classdef DataPlotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        AvailabledatasetincurrentdataseriesLabel  matlab.ui.control.Label
        DataseriesLabel  matlab.ui.control.Label
        UITable          matlab.ui.control.Table
        UITableSubData   matlab.ui.control.Table
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function displaySubData(app)
            
        end
        
        function displayData(app)
            t=app.UITable.Data; 

        data=app.Data.data; 

        for i=1:numel(data)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, option)
         
            app.Data.data=data; 
            app.Data.option=option;

              t=app.UITable;
              t.Data=[];
            
           %t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};

            t.ColumnWidth={100, 'auto', 'auto', 'auto'};
            t.ColumnEditable=[true false false false false];

              t=app.UITableSubData;
              t.Data=[];
            
           %t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};

            t.ColumnWidth={100, 'auto', 'auto', 'auto', true};
            t.ColumnEditable=[true false true true true];


            displayData(app)
            displaySubData(app)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITableSubData = uitable(app.UIFigure);
            app.UITableSubData.ColumnName = {'Plot'; 'Name'; 'Color'; 'Width'; 'Separate'};
            app.UITableSubData.RowName = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Parent'; 'Class'; 'Type'};
            app.UITable.RowName = {};
            app.DataseriesLabel = uilabel(app.UIFigure);
            app.DataseriesLabel.Text = 'Dataseries:';
            app.AvailabledatasetincurrentdataseriesLabel = uilabel(app.UIFigure);
            app.AvailabledatasetincurrentdataseriesLabel.Text = 'Available dataset in current dataseries:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataPlotGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end