classdef ProcessorImporterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DescriptionEditFieldLabel       matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditField_2Label     matlab.ui.control.Label
        ChooseamonglocallyavailableprocessorsDropDown  matlab.ui.control.DropDown
        ChooseamonglocallyavailableprocessorsDropDownLabel  matlab.ui.control.Label
        EnternewprocessornameEditField  matlab.ui.control.EditField
        EnternewprocessornameEditFieldLabel  matlab.ui.control.Label
        SetprocessorpathLabel           matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        ProceedButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        callingApp % Description
        Data % Description
        inputProject % Description
    end
    
    properties (Access = public)

 % Description
    end

    methods (Access = private)
        
     
function updateSelectedProcessorMeta(app)
    % Guard: field not created yet
    if ~isprop(app,'DescriptionEditField') || isempty(app.DescriptionEditField) || ~isvalid(app.DescriptionEditField)
        return;
    end
    if ~isprop(app,'ChooseamonglocallyavailableprocessorsDropDown') || ...
       isempty(app.ChooseamonglocallyavailableprocessorsDropDown) || ...
       ~isvalid(app.ChooseamonglocallyavailableprocessorsDropDown)
        return;
    end

    if ~isfield(app.Data,'pkgList') || isempty(app.Data.pkgList)
        app.DescriptionEditField.Value = '';
        app.ChooseamonglocallyavailableprocessorsDropDown.Tooltip = '';
        return;
    end

    name = app.ChooseamonglocallyavailableprocessorsDropDown.Value;
    pkg = app.Data.pkgList;

    if istable(pkg)
        pix = find(strcmp(pkg{:,2}, name), 1);
        if isempty(pix), return; end
        descr = pkg{pix,3};
        cat = pkg{pix,4};
    else
        pix = find(strcmp(pkg(:,2), name), 1);
        if isempty(pix), return; end
        descr = pkg{pix,3};
        cat = pkg{pix,4};
    end

    if iscell(descr), descr = descr{1}; end
    if iscell(cat),   cat   = cat{1}; end

    app.DescriptionEditField.Value = char(string(descr));
    app.ChooseamonglocallyavailableprocessorsDropDown.Tooltip = ...
        ['Category: ' char(string(cat))];
end


     function f = normalizeProcessFun(app, f) %#ok<INUSD>
            if isempty(f), return; end
            if isa(f,'function_handle')
                f = func2str(f);
            end
            f = char(string(f));
            if contains(f,'.process')
                return;
            end
            if contains(f,'.')
                return;
            end
            if ~isempty(which([f '.process']))
                f = [f '.process'];
            end
        end

        function procList = loadProcessPackages(app)
            procList = {};
            try
                procList = listProcessPackages();
            catch
            end
        end

        function setProcessorDropdown(app)
           procList = loadProcessPackages(app);
app.Data.pkgList = procList;

if isempty(procList)
    app.ChooseamonglocallyavailableprocessorsDropDown.Items = {};
    app.ChooseamonglocallyavailableprocessorsDropDown.Value = '';
    app.DescriptionEditField.Value = '';
    app.ProceedButton.Enable = 'off';
    return;
end

if istable(procList)
    names = procList{:,2};
else
    names = procList(:,2);
end

if isstring(names)
    names = cellstr(names);
end

app.ChooseamonglocallyavailableprocessorsDropDown.Items = names;
app.ChooseamonglocallyavailableprocessorsDropDown.Value = names{1};

updateSelectedProcessorMeta(app);

        end

        function param = buildDefaultParam(app, procFun) %#ok<INUSD>
            param = struct();
            setparamFun = '';

            if contains(procFun,'.process')
                setparamFun = strrep(procFun,'.process','.setparam');
            elseif ~isempty(procFun)
                setparamFun = [procFun '.setparam'];
            end

            if ~isempty(setparamFun) && ~isempty(which(setparamFun))
                try
                    ctx = struct();
                    ctx.channels = {};
                    param = feval(setparamFun, ctx);
                catch
                    try
                        param = feval(setparamFun);
                    catch
                        param = struct();
                    end
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, shallowObj)
             app.callingApp = callingApp;
            app.inputProject.shallowObj = shallowObj;

            % Set UI state
            if isempty(shallowObj)
                app.UIFigure.Name = 'Creating a new independent processor';
                app.SetprocessorpathLabel.Enable = 'on';
                app.Button.Enable = 'on';
                app.ProceedButton.Enable = 'off';
            else
                app.UIFigure.Name = 'Creating a project-dependent processor';
                app.SetprocessorpathLabel.Enable = 'off';
                app.Button.Enable = 'off';
                app.ProceedButton.Enable = 'on';
            end

            setProcessorDropdown(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if ~isempty(app.callingApp)
                uiresume(app.callingApp.DetecDivUIFigure);
            end
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             app.inputProject.path = [];
            [filename,path] = uiputfile('*.mat','Select processor folder', fullfile(pwd,'no_need_to_set_filename_here'));
            if isequal(filename,0)
                disp('User selected Cancel');
                return;
            end

            app.inputProject.path = path;
            app.SetprocessorpathLabel.Text = path;
            app.ProceedButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           if ~isempty(app.callingApp)
                uiresume(app.callingApp.DetecDivUIFigure);
            end
            delete(app)

        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)


              d = uiprogressdlg(app.UIFigure,'Title','Please Wait...', ...
                'Message','Creating new processor...');
            d.Value=0.1;

            filename = strtrim(app.EnternewprocessornameEditField.Value);
            if isempty(filename)
                uialert(app.UIFigure,'Please enter a processor name.','Warning','Icon','warning');
                close(d); return;
            end

            % Create processor (project-bound or standalone)
            if isempty(app.inputProject.shallowObj)
                path = app.inputProject.path;
                if isempty(path)
                    uialert(app.UIFigure,'Please select a path for the processor.','Warning','Icon','warning');
                    close(d); return;
                end
                procObj = processNew('path', path, 'filename', filename);
                assignin('base', filename, procObj);
            else
                app.inputProject.shallowObj.addProcessor('name', filename);
                procObj = app.inputProject.shallowObj.processing.processor(end);
            end

            % Set process type
            procName = app.ChooseamonglocallyavailableprocessorsDropDown.Value;
            procObj.processFun = normalizeProcessFun(app, procName);

            % Init default params
            procObj.processArg = buildDefaultParam(app, procObj.processFun);

            d.Value=0.66;
            d.Message='Saving processor.... Please wait...';
            processSave(procObj);

            d.Message='Done !';
            d.Value=1;
            pause(0.5);
            close(d);

            if ~isempty(app.callingApp)
                uiresume(app.callingApp.DetecDivUIFigure);
            end

            % Open new GUI (locked to this processor)
            processDataGUI(app.inputProject.shallowObj, procObj);

            delete(app)
            

        end

        % Value changed function: 
        % ChooseamonglocallyavailableprocessorsDropDown
        function ChooseamonglocallyavailableprocessorsDropDownValueChanged(app, event)
             updateSelectedProcessorMeta(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '...';
            app.SetprocessorpathLabel = uilabel(app.UIFigure);
            app.SetprocessorpathLabel.Enable = 'off';
            app.SetprocessorpathLabel.Tooltip = {'A path is required only when setting up an independent classifier'};
            app.SetprocessorpathLabel.Text = 'Set processor path:';
            app.EnternewprocessornameEditFieldLabel = uilabel(app.UIFigure);
            app.EnternewprocessornameEditFieldLabel.Text = 'Enter new processor name:';
            app.EnternewprocessornameEditField = uieditfield(app.UIFigure, 'text');
            app.EnternewprocessornameEditField.Value = 'my_process';
            app.ChooseamonglocallyavailableprocessorsDropDownLabel = uilabel(app.UIFigure);
            app.ChooseamonglocallyavailableprocessorsDropDownLabel.Text = 'Choose among locally available processors:';
            app.ChooseamonglocallyavailableprocessorsDropDown = uidropdown(app.UIFigure);
            app.ChooseamonglocallyavailableprocessorsDropDown.Items = {'Processor1', ''};
            app.ChooseamonglocallyavailableprocessorsDropDown.Editable = 'on';
            app.ChooseamonglocallyavailableprocessorsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseamonglocallyavailableprocessorsDropDownValueChanged, true);
            app.ChooseamonglocallyavailableprocessorsDropDown.Value = 'Processor1';
            app.DescriptionEditField_2Label = uilabel(app.UIFigure);
            app.DescriptionEditField_2Label.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'DescriptionEditField';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessorImporterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end