classdef ROIAdjustGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetButton            matlab.ui.control.Button
        ChannelMappingTable_2  matlab.ui.control.Table
        CancelButton           matlab.ui.control.Button
        ProceedButton          matlab.ui.control.Button
        heightEditField        matlab.ui.control.NumericEditField
        heightEditFieldLabel   matlab.ui.control.Label
        widthEditField         matlab.ui.control.NumericEditField
        widthEditFieldLabel    matlab.ui.control.Label
        minYEditField          matlab.ui.control.NumericEditField
        minYEditFieldLabel     matlab.ui.control.Label
        minXEditField          matlab.ui.control.NumericEditField
        minXEditFieldLabel     matlab.ui.control.Label
        ChannelMappingTable    matlab.ui.control.Table
        UITable                matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data struct = struct()      % structure style roiImporterGUI.Data
        ChannelKeep   cell
        DataKeep      cell
        Poly                     % handle du drawpolygon
        IsUpdatingFields logical = false  % pour éviter les boucles callback
    end
    
    methods (Access = private)
        
           function tf = hasValidROIs(app,obj)
            tf = false;
            if ~isprop(obj,'roi') || isempty(obj.roi)
                return;
            end
            r = obj.roi(1);
            if ~isa(r,'roi') || isempty(r.id)
                return;
            end
            tf = true;
           end

         function displayROIs(app, option)

            s         = app.Data.st;
            mainApp   = app.Data.mainApp;
            cc        = 1;
            displaystr2 = {};
            varstr      = {};
            target      = {};

            % --- Parcours des projets (shallow) ---
            for i = 1:numel(s.Project)
                proj = s.Project{i};
                shallowObj = evalin('base', proj); %#ok<NASGU>

                % FOV
                if ~isempty(s.Projectpos)
                    for k = 1:numel(s.Projectpos{i})
                        tmp = evalin('base', sprintf('%s.fov(%d)', proj, k));
                        if hasValidROIs(app,tmp)
                            varstr{cc}      = sprintf('%s.fov(%d)', proj, k);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectpos{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc}      = tmp;
                            cc = cc + 1;
                        end
                    end
                end

                % Classifications rattachées au projet
                if ~isempty(s.Projectclassi)
                    for k = 1:numel(s.Projectclassi{i})
                        val = k;
                        if nargin == 3 && ~isempty(option)
                            mp = evalin('base', proj);
                            for l = 1:numel(mp.processing.classification)
                                if strcmp(mp.processing.classification(l).strid, option)
                                    val = l;
                                    break
                                end
                            end
                        end
                        tmp = evalin('base', sprintf('%s.processing.classification(%d)', proj, val));
                        if hasValidROIs(app,tmp)
                            varstr{cc}      = sprintf('%s.processing.classification(%d)', proj, val);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectclassi{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc}      = tmp;
                            cc = cc + 1;
                        end
                    end
                end
            end

            % --- Parcours des classi standalone (comme roiImporterGUI) ---
            for i = 1:numel(s.Classifier)
                clas = s.Classifier{i};
                if isempty(clas)
                    continue;
                end
                if isstring(clas)
                    clas = clas(1);
                    clas = char(clas);
                end
                if ~ischar(clas)
                    warning('ROIAdjustGUI:ClassifierType',...
                        'Entrée st.Classifier{%d} non textuelle, ignorée.', i);
                    continue;
                end

                try
                    tmp = evalin('base', clas);
                catch
                    warning('ROIAdjustGUI:EvalinFailed',...
                        'Impossible de trouver la variable "%s" dans le workspace.', clas);
                    continue;
                end

                if hasValidROIs(app,tmp)
                    varstr{cc}      = clas;
                    displaystr2{cc,1} = '';
                    displaystr2{cc,2} = clas;
                    displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                    target{cc}      = tmp;
                    cc = cc + 1;
                end
            end

            app.Data.varstr      = varstr;
            app.Data.displaystr2 = displaystr2;
            app.Data.target      = target;

           nRows = size(displaystr2,1);

t = app.UITable;
t.Data = [];

t.ColumnWidth    = {50, 250, 200, 'auto'};
t.ColumnEditable = [true false false true];

d = cell(nRows,4);
for i = 1:nRows
    d{i,1} = false;

    % Optionnel : pré-sélectionner la première si option fourni
    if exist('option','var') && ~isempty(option) && i==1
        d{i,1} = true;
    end

    d{i,2} = displaystr2{i,1};
    d{i,3} = displaystr2{i,2};
    d{i,4} = displaystr2{i,3};
end

t.Data = d;


            % Si on a au moins une ligne, on la sélectionne par défaut
            if nRows > 0
                app.UITable.Selection = [1 1];
                UITableSelectionChanged(app, struct());
            end
        end


        %--------------------------------------------------------------
        % Mise à jour des tables Channels & Dataseries pour la ligne sélectionnée
        %--------------------------------------------------------------

        function updateChannelAndDataTables(app)
    sel = app.UITable.Selection;
    if isempty(sel)
        app.ChannelMappingTable.Data   = {};
        app.ChannelMappingTable_2.Data = {};
        return;
    end
    row = sel(1,1);

    tgt  = app.Data.target{row};
    if isempty(tgt), return; end
    rois = tgt.roi(:);

    if isempty(rois)
        app.ChannelMappingTable.Data   = {};
        app.ChannelMappingTable_2.Data = {};
        return;
    end

    % -----------------------
    % 🔹 CHANNELS
    % -----------------------
    allChannels = {};
    for r = 1:numel(rois)
        ch = rois(r).display.channel;
        if ischar(ch), ch = {ch}; end
        allChannels = [allChannels, ch]; %#ok<AGROW>
    end
    chNames = unique(allChannels,'stable');
    nCh = numel(chNames);

    % récupère état précédent si existant
    oldCh = {};
    if numel(app.ChannelKeep) >= row
        oldCh = app.ChannelKeep{row};
    end

    dataCh = cell(nCh,2);
    for i = 1:nCh
        chName = chNames{i};

        % valeur par défaut = keep
        keep = true;
        if ~isempty(oldCh) && isfield(oldCh,'name')
            idx = find(strcmp({oldCh.name}, chName),1);
            if ~isempty(idx)
                keep = oldCh(idx).keep;
            end
        end

        dataCh{i,1} = keep;
        dataCh{i,2} = chName;
    end

    app.ChannelMappingTable.Data = dataCh;
    app.ChannelMappingTable.ColumnEditable = [true false];


    % -----------------------
    % 🔹 DATASERIES
    % -----------------------
    allDS = {};
    for r = 1:numel(rois)
        d = rois(r).data;
        if isempty(d), continue; end
        for k = 1:numel(d)
            if isprop(d(k),'groupid') && ~isempty(d(k).groupid)
                allDS{end+1} = char(d(k).groupid); %#ok<AGROW>
            end
        end
    end
    dsNames = unique(allDS,'stable');
    nDS = numel(dsNames);

    oldDS = {};
    if numel(app.DataKeep) >= row
        oldDS = app.DataKeep{row};
    end

    dataDS = cell(nDS,2);
    for i = 1:nDS
        name = dsNames{i};
        keep = true;

        if ~isempty(oldDS) && isfield(oldDS,'name')
            idx = find(strcmp({oldDS.name}, name),1);
            if ~isempty(idx)
                keep = oldDS(idx).keep;
            end
        end

        dataDS{i,1} = keep;
        dataDS{i,2} = name;
    end

    app.ChannelMappingTable_2.Data = dataDS;
    app.ChannelMappingTable_2.ColumnEditable = [true false];
end


        %--------------------------------------------------------------
        % Aperçu ROI et polygon
        %--------------------------------------------------------------
        function updatePolygonPreview(app)
    sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);
    if numel(app.Data.target) < row || isempty(app.Data.target{row})
        return;
    end

    tgt  = app.Data.target{row};
    rois = tgt.roi(:);
    if isempty(rois), return; end

    % ROIs sélectionnées (colonne 4)
    raw = app.UITable.Data{row,4};
    roisIdx = parseROIString(app, raw, numel(rois));
    if isempty(roisIdx)
        roisIdx = 1;
    end
    refIdx = roisIdx(1);

    r = rois(refIdx);

    % --- Image de la ROI : frame 1, channel 1 ---
    im = getROIFramePreview(app, r);
    [hImg, wImg, ~] = size(im);

    cla(app.UIAxes);
    imagesc(app.UIAxes, im);
    colormap(app.UIAxes, gray);
    axis(app.UIAxes,'image');
    app.UIAxes.YDir = 'reverse';
    hold(app.UIAxes,'on');
    title(app.UIAxes, sprintf('ROI preview (idx %d)', refIdx));

    % On travaille dans les coordonnées du crop, origine (1,1)
    x = 1;
    y = 1;
    w = wImg;
    h = hImg;

    polyPos = [x      y; ...
               x+w-1  y; ...
               x+w-1  y+h-1; ...
               x      y+h-1];

    if ~isempty(app.Poly) && isvalid(app.Poly)
        delete(app.Poly);
    end

    app.Poly = drawpolygon(app.UIAxes,'Position',polyPos,'FaceAlpha',0.1);

    addlistener(app.Poly,'MovingROI',@(src,evt) app.syncFieldsFromPolygon());
    addlistener(app.Poly,'ROIMoved', @(src,evt) app.syncFieldsFromPolygon());

    app.IsUpdatingFields = true;
    app.minXEditField.Value   = x;
    app.minYEditField.Value   = y;
    app.widthEditField.Value  = w;
    app.heightEditField.Value = h;
    app.IsUpdatingFields      = false;
end


        function syncFieldsFromPolygon(app)
            if isempty(app.Poly) || ~isvalid(app.Poly)
                return;
            end
            pos = app.Poly.Position;
            minX = min(pos(:,1));
            maxX = max(pos(:,1));
            minY = min(pos(:,2));
            maxY = max(pos(:,2));
            w = maxX - minX;
            h = maxY - minY;

            app.IsUpdatingFields = true;
            app.minXEditField.Value   = minX;
            app.minYEditField.Value   = minY;
            app.widthEditField.Value  = w;
            app.heightEditField.Value = h;
            app.IsUpdatingFields      = false;
        end

        function syncPolygonFromFields(app, whichField)
            if app.IsUpdatingFields, return; end
            if isempty(app.Poly) || ~isvalid(app.Poly)
                return;
            end

            x  = app.minXEditField.Value;
            y  = app.minYEditField.Value;
            w  = max(1, app.widthEditField.Value);
            h  = max(1, app.heightEditField.Value);

            % Si on modifie width/height, on garde le centre de la forme
            if any(strcmp(whichField, {'width','height'}))
                pos = app.Poly.Position;
                minX = min(pos(:,1)); maxX = max(pos(:,1));
                minY = min(pos(:,2)); maxY = max(pos(:,2));
                cx = (minX + maxX)/2;
                cy = (minY + maxY)/2;
                x  = cx - w/2;
                y  = cy - h/2;
                app.minXEditField.Value = x;
                app.minYEditField.Value = y;
            end

            polyPos = [x y; x+w y; x+w y+h; x y+h];

            app.IsUpdatingFields = true;
            app.Poly.Position = polyPos;
            app.IsUpdatingFields = false;
        end

        function roisIdx = parseROIString(app,str, nMax)
            % interprète le contenu de la colonne "Select ROIs"
            if isnumeric(str)
                roisIdx = str;
                roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
                return;
            end
            s = strtrim(char(str));
            if isempty(s)
                roisIdx = 1:nMax;
                return;
            end
            s = regexprep(s,'[,;]',' ');
            s = regexprep(s,'\s+',' ');
            try
                roisIdx = str2num(s); %#ok<ST2NM>
            catch
                roisIdx = [];
            end
            roisIdx = unique(roisIdx);
            roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
        end


        function im = getROIFramePreview(~, r)
    % Assure que l'image de la ROI est chargée
    if isempty(r.image)
        try
            r.load();      % ta méthode existante, sans argument
        catch ME
            warning('ROIAdjustGUI:getROIFramePreview',...
                'Impossible de charger l''image pour ROI "%s": %s', r.id, ME.message);
        end
    end

    if ~isempty(r.image)
        % r.image : H x W x C x T
        im = r.image(:,:,1,1);      % channel 1, frame 1
    else
        % fallback si vraiment rien
        im = zeros(64,64,'uint8');
    end

    % On s'assure d’avoir 2D pour imagesc
    if ndims(im) > 2
        im = im(:,:,1);
    end
end

     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Démarrage standalone :
    %   ROIAdjustGUI()

    app.Data.mainApp = [];              % pas de calling app à ce stade
    app.UIFigure.Name = 'Batch ROI adjust';

    % Récupère les projets / classi / etc. dans le workspace
    s = gatherVariablesFromWorkspace;
    app.Data.st = s;

    app.Data.target      = {};  % objets (classi, shallow.fov, etc.)
    app.Data.varstr      = {};  % expressions dans base workspace
    app.Data.displaystr2 = {};  % [Project, Classifier/FOV, ROIs string]

    % Remplit la UITable avec les objets détectés
    displayROIs(app, []);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
                 indices = event.Indices;
            % Si on modifie la colonne ROIs, on force "Select" à true
            if indices(1,2) == 4
                pix = indices(1,1);
                app.UITable.Data{pix,1} = true;
            end
            app.UITable.Selection = indices;
            UITableSelectionChanged(app, event);
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
           updateChannelAndDataTables(app);
        end

        % Cell edit callback: ChannelMappingTable
        function ChannelMappingTableCellEdit(app, event)
           data = app.ChannelMappingTable.Data;
            sel  = app.UITable.Selection;
            if isempty(sel) || isempty(data)
                return;
            end
            row = sel(1,1);
            nCh = size(data,1);
            map(1,nCh) = struct('keep',true,'sourceName','','type','','destName','');
            for i = 1:nCh
                map(i).keep       = logical(data{i,1});
                map(i).sourceName = data{i,2};
                map(i).type       = data{i,3};
                map(i).destName   = data{i,4};
            end
            app.ChannelMap{row} = map;
        end

        % Selection changed function: ChannelMappingTable
        function ChannelMappingTableSelectionChanged(app, event)
            
        end

        % Cell edit callback: ChannelMappingTable_2
        function ChannelMappingTable_2CellEdit(app, event)
             data = app.ChannelMappingTable_2.Data;
            sel  = app.UITable.Selection;
            if isempty(sel) || isempty(data)
                return;
            end
            row = sel(1,1);
            nDS = size(data,1);
            map(1,nDS) = struct('keep',true,'sourceName','','type','','destName','');
            for i = 1:nDS
                map(i).keep       = logical(data{i,1});
                map(i).sourceName = data{i,2};
                map(i).type       = data{i,3};
                map(i).destName   = data{i,4};
            end
            app.DataMap{row} = map;
        end

        % Selection changed function: ChannelMappingTable_2
        function ChannelMappingTable_2SelectionChanged(app, event)
         
        end

        % Value changed function: minXEditField
        function minXEditFieldValueChanged(app, event)
            syncPolygonFromFields(app, 'minX');
        end

        % Value changed function: minYEditField
        function minYEditFieldValueChanged(app, event)
          syncPolygonFromFields(app, 'minX');
        end

        % Value changed function: widthEditField
        function widthEditFieldValueChanged(app, event)
        syncPolygonFromFields(app, 'minX');
        end

        % Value changed function: heightEditField
        function heightEditFieldValueChanged(app, event)
      syncPolygonFromFields(app, 'minX');
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
           
    sel = find(cell2mat(app.UITable.Data(:,1)));   % lignes cochées
    if isempty(sel)
        uialert(app.UIFigure,'No ROI source selected','Error'); 
        return;
    end

    newW = app.widthEditField.Value;
    newH = app.heightEditField.Value;

    for idx = sel'
        tgt  = app.Data.target{idx};
        rois = tgt.roi(:);

        % channels to keep
        tCh = app.ChannelMappingTable.Data;
        keepCh = tCh(:,1);
        namesCh = tCh(:,2);

        % dataseries to keep
        tDS = app.ChannelMappingTable_2.Data;
        keepDS = tDS(:,1);
        namesDS = tDS(:,2);

        for r = 1:numel(rois)
            rr = rois(r);

            % Resize centré
            rr.adjustROISize([0 0 newW newH]);

            % Remove channels not kept
            ch = rr.display.channel;
            mask = keepCh(strcmp(ch, namesCh));   % true/false per channel
            rr.display.channel = ch(mask);
            rr.image = rr.image(:,:,mask,:);

            % Remove dataseries not kept
            toRemove = [];
            for k = 1:numel(rr.data)
                g = rr.data(k).groupid;
                idxDS = strcmp(namesDS, g);
                if ~keepDS(idxDS)
                    toRemove(end+1) = k; %#ok<AGROW>
                end
            end
            rr.data(toRemove) = [];
        end
    end

    uialert(app.UIFigure,'ROIs adjusted successfully','Success');


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
                     delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
              sel = app.UITable.Selection;
            if isempty(sel)
                return;
            end
            row = sel(1,1);
            if numel(app.Data.target) < row || isempty(app.Data.target{row})
                return;
            end
            tgt  = app.Data.target{row};
            rois = tgt.roi(:);
            if isempty(rois), return; end

            raw = app.UITable.Data{row,4};
            idx = parseROIString(app,raw, numel(rois));
            if isempty(idx)
                idx = 1:numel(rois);
            end

            % centre = celui de la première ROI
            r0 = rois(idx(1));
            val0 = r0.value(:)';
            cx = val0(1) + val0(3)/2;
            cy = val0(2) + val0(4)/2;

            % largeur / hauteur max
            widths  = []; heights = [];
            for k = idx(:)'
                rv = rois(k).value(:)';
                widths(end+1)  = rv(3); %#ok<AGROW>
                heights(end+1) = rv(4); %#ok<AGROW>
            end
            w = max(widths);
            h = max(heights);

            x = cx - w/2;
            y = cy - h/2;

            app.IsUpdatingFields = true;
            app.minXEditField.Value   = x;
            app.minYEditField.Value   = y;
            app.widthEditField.Value  = w;
            app.heightEditField.Value = h;
            app.IsUpdatingFields      = false;

            syncPolygonFromFields(app, 'width');  % met aussi à jour le polygon
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'Project'; 'Classifier or Position (FOV)'; 'Select ROIs'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.ChannelMappingTable = uitable(app.UIFigure);
            app.ChannelMappingTable.ColumnName = {'Keep channel'; 'Source Channel Name'; 'Type'; 'Destination name'};
            app.ChannelMappingTable.RowName = {};
            app.ChannelMappingTable.ColumnEditable = [true false false true true];
            app.ChannelMappingTable.CellEditCallback = createCallbackFcn(app, @ChannelMappingTableCellEdit, true);
            app.ChannelMappingTable.SelectionChangedFcn = createCallbackFcn(app, @ChannelMappingTableSelectionChanged, true);
            app.minXEditFieldLabel = uilabel(app.UIFigure);
            app.minXEditFieldLabel.Text = 'minX';
            app.minXEditField = uieditfield(app.UIFigure, 'numeric');
            app.minXEditField.ValueChangedFcn = createCallbackFcn(app, @minXEditFieldValueChanged, true);
            app.minYEditFieldLabel = uilabel(app.UIFigure);
            app.minYEditFieldLabel.Text = 'minY';
            app.minYEditField = uieditfield(app.UIFigure, 'numeric');
            app.minYEditField.ValueChangedFcn = createCallbackFcn(app, @minYEditFieldValueChanged, true);
            app.widthEditFieldLabel = uilabel(app.UIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.UIFigure, 'numeric');
            app.widthEditField.ValueChangedFcn = createCallbackFcn(app, @widthEditFieldValueChanged, true);
            app.heightEditFieldLabel = uilabel(app.UIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightEditField.ValueChangedFcn = createCallbackFcn(app, @heightEditFieldValueChanged, true);
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChannelMappingTable_2 = uitable(app.UIFigure);
            app.ChannelMappingTable_2.ColumnName = {'Keep data'; 'Source Dataseries Name'; 'Type'; 'Destination dataseries name'};
            app.ChannelMappingTable_2.RowName = {};
            app.ChannelMappingTable_2.ColumnEditable = [true false false true true];
            app.ChannelMappingTable_2.CellEditCallback = createCallbackFcn(app, @ChannelMappingTable_2CellEdit, true);
            app.ChannelMappingTable_2.SelectionChangedFcn = createCallbackFcn(app, @ChannelMappingTable_2SelectionChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIAdjustGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end