classdef ROIextracterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIidentifierUIFigure         matlab.ui.Figure
        ResetreferenceROIpatternforautomatedROIdetectionButton  matlab.ui.control.Button
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        test                          matlab.ui.control.Button
        SetframeEditField             matlab.ui.control.NumericEditField
        SetframeEditFieldLabel        matlab.ui.control.Label
        OptionsButtonGroup            matlab.ui.container.ButtonGroup
        KeepexisitingROIsButton       matlab.ui.control.RadioButton
        OverwritesexistingROIsButton  matlab.ui.control.RadioButton
        PatternLabel                  matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        IdentifyandcreateROIsbasedonpatternButton  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data % Description
        callingApp % Description
    end

    methods (Access = private)


        function  displayPattern(app,shallowObj)
            axes(app.UIAxes);

            imshow(shallowObj.processing.roi.pattern,[],'parent',app.UIAxes);
            app.UIAxes.XLabel.String='';
            app.UIAxes.YLabel.String='';
            app.UIAxes.Title.String='';
        end

        function [cc,i,k]=computePattern(app,shallowObj,frame)
            cc=0;
            for i=1:numel(shallowObj.fov)
                for k=1:numel(shallowObj.fov(i).roi)
                    if numel(shallowObj.fov(i).roi(k).proc)>0 % one pattern has been identifed
                        shallowObj.setPattern(i,k,frame);
                        cc=1;
                        break;
                    end
                end
            end
        end

        function updateTable(app,shallowObj)
            t=app.UITable;
            t.ColumnEditable=[true false false false false];
            t.ColumnWidth={50 50 'Auto' 150 150};
            t.ColumnName={'Select' 'Index' 'Position name' '# of existing ROis' 'Existing cropping area'};

            for k=1:numel(shallowObj.fov)
                if numel(numel(shallowObj.fov(k).roi)) & numel(shallowObj.fov(k).roi(1).id)==0
                    n=0;
                else
                    n=numel(shallowObj.fov(k).roi);
                end

                m=false;
                if numel(shallowObj.fov(k).crop)
                    m=true;
                end

                Data(k,:)={true num2str(k) shallowObj.fov(k).id n m};
            end

            % Data

            t.Data=Data;
        end


        function  findPattern(app,option)
        
shallowObj=  app.Data.shallowObj;

            if nargin==1 % first call, no option

            [cc,i,k]=computePattern(app,shallowObj,1);
            app.Data.detect=[cc,i,k];

            proceed=0;

            if cc~=0

                app.PatternLabel.Text=['A pattern was found on position ' shallowObj.fov(i).id ' based on ROI #' num2str(k) ' - ' shallowObj.fov(i).roi(k).id];
                proceed=1;
                app.test.Enable='on';

            else

%                 if isfield(shallowObj.processing.roi,'pattern')
% 
%                     if numel(shallowObj.processing.roi.pattern)
% 
%                         app.PatternLabel.Text=['A reference pattern is available'];
%                         proceed=1;
%                            app.test.Enable='on';
% 
%                     end
%                 end
            end

            else
            proceed=0;
            end

            answer="OK";
            while proceed==0 && answer=="OK"
                answer=uiconfirm(app.ROIidentifierUIFigure,'There is no reference ROI pattern; set a new ROI pattern?','Warning','Icon','warning');

                if answer=="OK"
                    shallowObj.fov(1).view(1,[],app.ROIidentifierUIFigure);
                    uiwait(app.ROIidentifierUIFigure);

                  
                       [cc,i,k]=computePattern(app,shallowObj,1);
                       app.Data.detect=[cc,i,k];


                       if cc~=0

                               app.PatternLabel.Text=['A pattern was found on position ' shallowObj.fov(i).id ' based on ROI #' num2str(k) ' - ' shallowObj.fov(i).roi(k).id];
                               proceed=1;
                                app.test.Enable='on';

                       end

                end


            end


            if proceed==1
            displayPattern(app,shallowObj);

            updateTable(app,shallowObj)
            else
                 app.PatternLabel.Text=['There is no refrence ROI pattern'];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, shallowObj)
     app.callingApp=callingApp;
     app.Data.shallowObj=shallowObj;
         findPattern(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)
        end

        % Close request function: ROIidentifierUIFigure
        function ROIidentifierUIFigureCloseRequest(app, event)
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)

        end

        % Value changed function: SetframeEditField
        function SetframeEditFieldValueChanged(app, event)
            value = app.SetframeEditField.Value;
            shallowObj=app.Data.shallowObj;

            [cc,i,k]=computePattern(app,shallowObj,value);
            displayPattern(app,shallowObj);
        end

        % Button pushed function: IdentifyandcreateROIsbasedonpatternButton
        function IdentifyandcreateROIsbasedonpatternButtonPushed(app, event)
            shallowObj=app.Data.shallowObj;

            if numel(app.UITable.Data)

                selectedfordetection=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
                frameid=app.SetframeEditField.Value;
                thr=app.ThresholdEditField.Value;
                option=app.KeepexisitingROIsButton.Value;
                % return;

                shallowObj.identifyROIs('FOV',selectedfordetection','Frames',frameid,'Threshold',thr,'Keep',option);

                uialert(app.ROIidentifierUIFigure,'ROI detection is complete !','Success','Icon','success');
                updateTable(app,shallowObj);
            else
                uialert(app.ROIidentifierUIFigure,'There is no ROI in the table!','Warning')
            end
        end

        % Button pushed function: test
        function testButtonPushed(app, event)
            detectionParam=app.Data.detect; % where the reference pattern was detected
            shallowObj=app.Data.shallowObj;

            frameid=app.SetframeEditField.Value;

            thr=app.ThresholdEditField.Value;

            shallowObj.identifyROIs('FOV',detectionParam(2),'Frames',frameid,'Test','Threshold',thr);

        end

        % Button pushed function: 
        % ResetreferenceROIpatternforautomatedROIdetectionButton
        function ResetreferenceROIpatternforautomatedROIdetectionButtonPushed(app, event)
    findPattern(app,'ok')
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIidentifierUIFigure = uifigure('Visible', 'off');
            app.ROIidentifierUIFigure.Name = 'ROI identifier';
            app.ROIidentifierUIFigure.CloseRequestFcn = createCallbackFcn(app, @ROIidentifierUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ROIidentifierUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.ROIidentifierUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'If one single cropping area exists, it will be used for all position; if none exists, no cropping will be done; '};
            app.IdentifyandcreateROIsbasedonpatternButton = uibutton(app.ROIidentifierUIFigure, 'push');
            app.IdentifyandcreateROIsbasedonpatternButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyandcreateROIsbasedonpatternButtonPushed, true);
            app.IdentifyandcreateROIsbasedonpatternButton.Text = 'Identify and create ROIs based on pattern';
            app.CloseButton = uibutton(app.ROIidentifierUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.PatternLabel = uilabel(app.ROIidentifierUIFigure);
            app.PatternLabel.Text = 'Pattern';
            app.OptionsButtonGroup = uibuttongroup(app.ROIidentifierUIFigure);
            app.OptionsButtonGroup.Title = 'Options';
            app.OverwritesexistingROIsButton = uiradiobutton(app.OptionsButtonGroup);
            app.OverwritesexistingROIsButton.Text = 'Overwrites existing ROIs';
            app.OverwritesexistingROIsButton.Value = true;
            app.KeepexisitingROIsButton = uiradiobutton(app.OptionsButtonGroup);
            app.KeepexisitingROIsButton.Text = 'Keep exisiting ROIs';
            app.SetframeEditFieldLabel = uilabel(app.ROIidentifierUIFigure);
            app.SetframeEditFieldLabel.Text = 'Set frame';
            app.SetframeEditField = uieditfield(app.ROIidentifierUIFigure, 'numeric');
            app.SetframeEditField.ValueChangedFcn = createCallbackFcn(app, @SetframeEditFieldValueChanged, true);
            app.SetframeEditField.Value = 1;
            app.test = uibutton(app.ROIidentifierUIFigure, 'push');
            app.test.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.test.Enable = 'off';
            app.test.Text = 'Test detection on corresponding ROI';
            app.ThresholdEditFieldLabel = uilabel(app.ROIidentifierUIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ROIidentifierUIFigure, 'numeric');
            app.ThresholdEditField.Value = 0.5;
            app.ResetreferenceROIpatternforautomatedROIdetectionButton = uibutton(app.ROIidentifierUIFigure, 'push');
            app.ResetreferenceROIpatternforautomatedROIdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetreferenceROIpatternforautomatedROIdetectionButtonPushed, true);
            app.ResetreferenceROIpatternforautomatedROIdetectionButton.Text = 'Reset reference ROI pattern for automated ROI detection...';
            % Show the figure after all components are created
            app.ROIidentifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIextracterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIidentifierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIidentifierUIFigure)
        end
    end
end