classdef annotationBoundsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RulesPanel                      matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        OffsettheboundbyEditField       matlab.ui.control.NumericEditField
        OffsettheboundbyEditFieldLabel  matlab.ui.control.Label
        occurenceoftheselectedeventtype0ifyouwantthelasteventLabel  matlab.ui.control.Label
        SettheboundtotheEditField       matlab.ui.control.NumericEditField
        SettheboundtotheEditFieldLabel  matlab.ui.control.Label
        ValueListBox                    matlab.ui.control.ListBox
        ValueListBoxLabel               matlab.ui.control.Label
        DatasetListBox                  matlab.ui.control.ListBox
        DatasetListBoxLabel             matlab.ui.control.Label
        DataseriesListBox               matlab.ui.control.ListBox
        DataseriesListBoxLabel          matlab.ui.control.Label
        ChooseButtonGroup               matlab.ui.container.ButtonGroup
        ValueEditField                  matlab.ui.control.NumericEditField
        ValueEditFieldLabel             matlab.ui.control.Label
        BoundsbasedonrulesButton        matlab.ui.control.RadioButton
        ManuallydefineboundsButton      matlab.ui.control.RadioButton
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        ChoosewhichboundtosetListBox    matlab.ui.control.ListBox
        ChoosewhichboundtosetListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function  display(app)
            'ok'
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classif)


            listData={};


            for i=1:numel(classif.roi)
                listData=[listData [classif.roi(i).data.groupid]];
            end

            listData=unique(listData)
            listData

            % HERE : fix the list 

            app.Data.listData=listData;

                  display(app)
        end

        % Selection changed function: ChooseButtonGroup
        function ChooseButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseButtonGroup.SelectedObject
            
            if selectedButton.Text=="Bounds based on rules"
                    app.RulesPanel.Visible="on";
            else
                    app.RulesPanel.Visible="off";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosewhichboundtosetListBoxLabel = uilabel(app.UIFigure);
            app.ChoosewhichboundtosetListBoxLabel.Text = 'Choose which bound to set: ';
            app.ChoosewhichboundtosetListBox = uilistbox(app.UIFigure);
            app.ChoosewhichboundtosetListBox.Items = {'Min bound', 'Max bound'};
            app.ChoosewhichboundtosetListBox.Value = 'Min bound';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.ChooseButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseButtonGroupSelectionChanged, true);
            app.ChooseButtonGroup.Title = 'Choose';
            app.ManuallydefineboundsButton = uiradiobutton(app.ChooseButtonGroup);
            app.ManuallydefineboundsButton.Text = 'Manually define bounds ';
            app.ManuallydefineboundsButton.Value = true;
            app.BoundsbasedonrulesButton = uiradiobutton(app.ChooseButtonGroup);
            app.BoundsbasedonrulesButton.Text = 'Bounds based on rules';
            app.ValueEditFieldLabel = uilabel(app.ChooseButtonGroup);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.ValueEditField = uieditfield(app.ChooseButtonGroup, 'numeric');
            app.RulesPanel = uipanel(app.UIFigure);
            app.RulesPanel.Title = 'Rules';
            app.RulesPanel.Visible = 'off';
            app.DataseriesListBoxLabel = uilabel(app.RulesPanel);
            app.DataseriesListBoxLabel.Text = 'Dataseries';
            app.DataseriesListBox = uilistbox(app.RulesPanel);
            app.DatasetListBoxLabel = uilabel(app.RulesPanel);
            app.DatasetListBoxLabel.Text = 'Dataset';
            app.DatasetListBox = uilistbox(app.RulesPanel);
            app.ValueListBoxLabel = uilabel(app.RulesPanel);
            app.ValueListBoxLabel.Text = 'Value';
            app.ValueListBox = uilistbox(app.RulesPanel);
            app.SettheboundtotheEditFieldLabel = uilabel(app.RulesPanel);
            app.SettheboundtotheEditFieldLabel.Text = 'Set the bound to the ';
            app.SettheboundtotheEditField = uieditfield(app.RulesPanel, 'numeric');
            app.occurenceoftheselectedeventtype0ifyouwantthelasteventLabel = uilabel(app.RulesPanel);
            app.occurenceoftheselectedeventtype0ifyouwantthelasteventLabel.Text = 'th occurence of the selected event/value; type 0 if you want to seelct the last event';
            app.OffsettheboundbyEditFieldLabel = uilabel(app.RulesPanel);
            app.OffsettheboundbyEditFieldLabel.Text = 'Offset the bound by ';
            app.OffsettheboundbyEditField = uieditfield(app.RulesPanel, 'numeric');
            app.DropDown = uidropdown(app.RulesPanel);
            app.DropDown.Items = {'Occurence', 'Frame'};
            app.DropDown.Value = 'Occurence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotationBoundsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end