classdef classiRepository < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        classiRepositoryUIFigure  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        SetrepositorypathMenu     matlab.ui.container.Menu
        Clone                     matlab.ui.control.Button
        SelectedclassifierPanel   matlab.ui.container.Panel
        DownloadButton            matlab.ui.control.Button
        Open                      matlab.ui.control.Button
        Display                   matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        CancelButton              matlab.ui.control.Button
    end


    properties (Access = private)
        Data % Description
        CallingApp % Description
    end
    
    properties (Access = public)
    
    end

    methods (Access = public)

        function displayClassi(app)

            % load local classifiers
            pth=userpath;

            if ~exist(fullfile(pth,'Detecdiv'))
                mkdir(fullfile(pth,'Detecdiv'));
            end

            fle= fullfile(pth,'Detecdiv/repositoryPath.mat');

            if exist(fle)
                load(fle);
            else
                repopth=fullfile(pth, 'ClassiRepository');

                if ~exist(repopth)
                    mkdir(repopth)
                end

                save(fle,'repopth')
            end


            app.Data.repopth=repopth;

            liste=dir(repopth);

            liste=liste([liste.isdir]);
            liste=liste(~contains({liste.name},{'..','.'}));
            %   liste=list(isfolder(liste

            t=app.UITable;

            %     t.ColumnName={'Se','Select for test',' ROI index','ROI Id'};
            t.ColumnWidth={100, 'auto', 50, 100 };
            t.ColumnEditable=[false false false false];
            d=t.Data;


            cc=1;
            for i=1:numel(liste)

                % test is classi has the right architecture

                pth=fullfile(liste(i).folder, liste(i).name);
                clas=fullfile(pth,[liste(i).name '_classification.mat']);

                app.Data.path{i}=clas;

                if exist(clas)

                    load(clas)
                    descri=classiObj.description{2};
                    nroi=numel(classiObj.roi);
                    if numel(classiObj.roi(1).id)==0
                        nroi=0;
                    end

                    clear classiObj;

                    d{i,1}=liste(i).name;
                    d{i,2}=descri;
                    d{i,3}=num2str(nroi);
                    d{i,4}='local';
                    cc=cc+1;
                end

            end

            % load remote classifiers

            fle=which('shallowLoad.m');
            [pth,  ~, ~]=fileparts(fle);

            di=fullfile(pth,'/supportfunction/paper/remoteClassifiers.mat');

            if exist(di)
                load(di);

                for i=1:size(remoteclassi,1)

                    d{cc,1}=remoteclassi{i,1};

                    class(remoteclassi{i,1})

                    d{cc,2}=remoteclassi{i,2};
                    d{cc,3}=num2str(remoteclassi{i,3});
                    d{cc,4}=' remote';
                    app.Data.path{cc}=remoteclassi{i,4};
                    cc=cc+1;
                end
            end

            app.UITable.Data=d;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
           
            if nargin==2
                app.CallingApp=callingApp;
            else
                app.CallingApp=[];
            end
            
            app.DownloadButton.Enable='off';

app.Open.Enable='off';
app.Display.Enable='off';

if nargin==2
    app.Clone.Visible='on';
end

            displayClassi(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           if numel(app.CallingApp)~=0
                    uiresume(app.CallingApp.ClassifierUIFigure);
           end
            delete(app)
        end

        % Menu selected function: SetrepositorypathMenu
        function SetrepositorypathMenuSelected(app, event)


            repopth=uigetdir(app.Data.repopth,'Input the location of the Classi Repostiory');

            app.Data.repopth=repopth;

            pth=userpath;
            fle= fullfile(pth,'Detecdiv/repositoryPath.mat');

            save(fle,'repopth');

            disp(['User selected : ' repopth ' for the classi repository']);


        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
            if numel(selection)==0
                return
            end

            d=app.UITable.Data; 
           
            typ=d{selection(1,1),4};
            if strcmp(typ,'local')
app.DownloadButton.Enable='off';

app.Open.Enable='on';
app.Display.Enable='on';

            else
app.DownloadButton.Enable='on';

app.Open.Enable='off';
app.Display.Enable='off';
            end
        end

        % Button pushed function: Display
        function DisplayButtonPushed(app, event)
                        selection = app.UITable.Selection;
            
            if numel(selection)==0
                return
            end

        %    d=app.UITable.Data;
pth=app.Data.path{selection(1,1)};

[fld fle]=fileparts(pth);

sam=fullfile(fld,'sampleImage.png');

if exist(sam)
    im=imread(sam);
    h=figure('Position',[100 100 800 600]);
    imshow(im);
    set(h,'Position',[100 100 800 600]);
else
     
            classiObj=classiLoad(app.Data.path{selection(1,1)});

     %     classiObj
            [ k, himg]=classiObj.displayFormattedTrainingSet('Display','Nimages',9);

            clear classiObj;
end
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
             selection = app.UITable.Selection;
            classiObj=classiLoad(app.Data.path{selection(1,1)});
            classifierGUI(classiObj);
        end

        % Button pushed function: Clone
        function CloneButtonPushed(app, event)
              selection = app.UITable.Selection;

              if numel(selection)
            app.CallingApp.ClassifierUIFigure.UserData=app.Data.path{selection(1,1)};
              else
            app.CallingApp.ClassifierUIFigure.UserData=[];
              end

            uiresume(app.CallingApp.ClassifierUIFigure);
             delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.classiRepositoryUIFigure = uifigure('Visible', 'off');
            app.classiRepositoryUIFigure.Name = 'Classifier Repository';
            app.FileMenu = uimenu(app.classiRepositoryUIFigure);
            app.FileMenu.Text = 'File';
            app.SetrepositorypathMenu = uimenu(app.FileMenu);
            app.SetrepositorypathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetrepositorypathMenuSelected, true);
            app.SetrepositorypathMenu.Text = 'Set repository path...';
            app.CancelButton = uibutton(app.classiRepositoryUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UITable = uitable(app.classiRepositoryUIFigure);
            app.UITable.ColumnName = {'Name'; 'Description'; 'ROIs'; 'Status'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.SelectedclassifierPanel = uipanel(app.classiRepositoryUIFigure);
            app.SelectedclassifierPanel.Title = 'Selected classifier';
            app.Display = uibutton(app.SelectedclassifierPanel, 'push');
            app.Display.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.Display.Text = 'Display sample images';
            app.Open = uibutton(app.SelectedclassifierPanel, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Tooltip = {'To clone this classifier, select ''New Classifier'' in the main detecdiv window and select ''Duplicate from repository'''};
            app.Open.Text = 'Check classifier properties...';
            app.DownloadButton = uibutton(app.SelectedclassifierPanel, 'push');
            app.DownloadButton.Enable = 'off';
            app.DownloadButton.Text = 'Download';
            app.Clone = uibutton(app.classiRepositoryUIFigure, 'push');
            app.Clone.ButtonPushedFcn = createCallbackFcn(app, @CloneButtonPushed, true);
            app.Clone.Visible = 'off';
            app.Clone.Text = 'Clone selected classifier';
            % Show the figure after all components are created
            app.classiRepositoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classiRepository(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.classiRepositoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.classiRepositoryUIFigure)
        end
    end
end