classdef classifierBKGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
        RestoreselectedversioncloseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data=[]; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj)
            app.Data.classiObj=classiObj;
            
            [dat,lastIndex]=version(classiObj);
            
            st=cell(size(dat,1),1);
            st(:)={false};
            
            dat=[st,dat];
            
            t=app.UITable;
            t.Data=dat;
            t.ColumnWidth={50, 250, 150, 70, 250, 150};
            t.ColumnEditable=[true false false false false false];
           %  selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            selectedfortraining=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
            
            di=setxor(selectedfortraining,indices(1));
            
            app.UITable.Data(di,1)={false};
            
        end

        % Button pushed function: RestoreselectedversioncloseButton
        function RestoreselectedversioncloseButtonPushed(app, event)
            selectedfortraining=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
            
            if numel(selectedfortraining)==0
                uialert(app.UIFigure,'Select an item first!','Attention');
                return
            end
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                    'Message','Restoring a previous classifier...');
            d.Value=0.1;
            
            
            app.Data.classiObj.restore(selectedfortraining)
            
             d.Value=0.8;
             pause(0.3);
             close(d);
             delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RestoreselectedversioncloseButton = uibutton(app.UIFigure, 'push');
            app.RestoreselectedversioncloseButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreselectedversioncloseButtonPushed, true);
            app.RestoreselectedversioncloseButton.Text = 'Restore selected version & close';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'Classification name'; 'Date'; '# ROIs'; 'Classifier Name'; 'Date'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierBKGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end