classdef classifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveclassifierparametersMenu    matlab.ui.container.Menu
        BackupcurrentclassifierversionMenu  matlab.ui.container.Menu
        RestorepreviousclassifierMenu   matlab.ui.container.Menu
        ClassifierMenu                  matlab.ui.container.Menu
        FormattrainingsetMenu           matlab.ui.container.Menu
        TrainClassifierMenu             matlab.ui.container.Menu
        ValidateclassifierusingtestsetMenu  matlab.ui.container.Menu
        DisplaystatisticsMenu           matlab.ui.container.Menu
        LoadclassifierMenu              matlab.ui.container.Menu
        CheckstatusMenu                 matlab.ui.container.Menu
        ClassifierisloadedinworksapcememoryLabel  matlab.ui.control.Label
        ParametersaresavedLabel         matlab.ui.control.Label
        StatusLoad                      matlab.ui.control.Lamp
        StatusSaved                     matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        ClassifierparametersTab         matlab.ui.container.Tab
        DeSelectButton                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        ChannelListBoxSel               matlab.ui.control.ListBox
        SelectedchannelsasclassificationinputLabel  matlab.ui.control.Label
        ChannelListBox                  matlab.ui.control.ListBox
        AvailablechannelstakenfromtrainingdatasetROIsLabel  matlab.ui.control.Label
        PostprocessingparametersPanel   matlab.ui.container.Panel
        SizepixelEditField              matlab.ui.control.NumericEditField
        SizepixelEditFieldLabel         matlab.ui.control.Label
        FilteroutsmallobjectsCheckBox   matlab.ui.control.CheckBox
        ThresholdingButtonGroup         matlab.ui.container.ButtonGroup
        ValueEditField                  matlab.ui.control.EditField
        ValueEditFieldLabel             matlab.ui.control.Label
        MaximumprobabilityButton        matlab.ui.control.RadioButton
        AdaptivethresholdOtsumethodButton  matlab.ui.control.RadioButton
        FixedthresholdButton            matlab.ui.control.RadioButton
        WatershedCheckBox               matlab.ui.control.CheckBox
        PostprocessingDropDown          matlab.ui.control.DropDown
        PostprocessingDropDownLabel     matlab.ui.control.Label
        ClassiDetailsLabel              matlab.ui.control.Label
        PostprocessingcustomfunctionhandleEditField  matlab.ui.control.EditField
        PostprocessingcustomfunctionhandleEditFieldLabel  matlab.ui.control.Label
        SpaceseparatedclassnamesEditField  matlab.ui.control.EditField
        SpaceseparatedclassnamesLabel   matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        TrainingprocedureparametersTab  matlab.ui.container.Tab
        DatasetsROIsTab                 matlab.ui.container.Tab
        ShuffleROIsfractionEditField    matlab.ui.control.EditField
        ShuffleROIsfractionEditFieldLabel  matlab.ui.control.Label
        SelectROIsEditField             matlab.ui.control.EditField
        SelectROIsEditFieldLabel        matlab.ui.control.Label
        removeselectedROIButton         matlab.ui.control.Button
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        AnnotateselectedROIButton       matlab.ui.control.Button
        ImportROIsButton                matlab.ui.control.Button
        UITableData                     matlab.ui.control.Table
    end


    properties (Access = private)
        callingApp % Description
    end

    properties (Access = public)
        Data % Description % must be public to shar info with other apps
    end

    methods (Access = private)

        function  displayClassi(app) % displays the data from classi as they are

            % classi type
            typelist=app.Data.classlist{:,2};
            classiObj=app.Data.classiObj;

            if numel(classiObj.description)==0 % new classi
                pix=1;
                classlist=app.Data.classlist;

                app.Data.classiObj.description={classlist{pix,2} ' - ' classlist{pix,3}};
                app.Data.classiObj.category=classlist{pix,4};
                app.Data.classiObj.classifyFun=classlist{pix,6}{1};
                app.Data.classiObj.trainingFun=classlist{pix,5}{1};
            end

            if numel(find(matches(app.TypeDropDown.Items,app.Data.classiObj.description{1})))
                app.TypeDropDown.Value=app.Data.classiObj.description{1};
            else
                app.TypeDropDown.Items=[app.TypeDropDown.Items app.Data.classiObj.description{1}];
                app.TypeDropDown.Value=app.Data.classiObj.description{1};
            end

            % classi description
            % if numel(classiObj.description)
            app.ClassiDetailsLabel.Text=classiObj.description{3};
            % end


            app.PostprocessingparametersPanel.Enable='off';
            app.PostprocessingDropDown.Enable='off';
            app.PostprocessingcustomfunctionhandleEditField.Enable='off';


            % channels

            app.ChannelListBoxSel.Items={};
            app.ChannelListBoxSel.Enable='off';

            cha={};

            
            switch classiObj.category{1}

                case 'Timeseries' % timeseries

                    if numel(classiObj.roi(1).results)
                    fi=fieldnames(classiObj.roi(1).results);
                    cc=1;
                     for i=1:numel(fi)
                         tmp=classiObj.roi(1).results.(fi{i});       
                         fi2=fieldnames(tmp);  

                         for j=1:numel(fi2)
                             
                             cha{cc}=[fi{i} '.' fi2{j}];
                             
                             cc=cc+1;
                         end
                     end
                    end
                                   
                    
                  if numel(cha)
                        app.ChannelListBox.Items=cha;
                        app.ChannelListBox.Enable='on';
                    else
                        app.ChannelListBox.Items={};
                        app.ChannelListBox.Enable='off';
                  end
                  
                  if numel(classiObj.channelName)~=0 % in case previou system is used
                        channelName=classiObj.channelName;
                        app.ChannelListBoxSel.Items=channelName;
                        app.ChannelListBoxSel.Enable='on';
      
                  end


                otherwise % image classification 
                    
                    for i=1:numel(classiObj.roi)
                        if numel(classiObj.roi(i).id)
                            tmp=classiObj.roi(i).display.channel(:);
                            if size(tmp,1)>1
                                tmp=tmp';
                            end

                            cha=unique([cha tmp],'stable');

                        end
                    end

                    if numel(cha)
                        app.ChannelListBox.Items=cha;
                        app.ChannelListBox.Enable='on';
                    else
                        app.ChannelListBox.Items={};
                        app.ChannelListBox.Enable='off';
                    end

                    if numel(classiObj.channelName)==0 % in case previou system is used
                        if numel(cha) & numel(classiObj.channel)
                            if numel(cha)>= classiObj.channel(1) && classiObj.channel(1)>0
                                channelName=cha(classiObj.channel(1));
                                classiObj.channelName=channelName;
                                app.ChannelListBoxSel.Items=channelName;
                                app.ChannelListBoxSel.Enable='on';
                            end
                        else
                            channelName='';
                        end
                    else
                        channelName=classiObj.channelName;

                        if ischar(channelName) % fix previous classification format
                            channelName={channelName};
                            classiObj.channelName=channelName;
                        end

                        app.ChannelListBoxSel.Items=channelName;
                        app.ChannelListBoxSel.Enable='on';
                    end
            end

            % classes
            %   aaa=classiObj.description{1}
            if strcmp(classiObj.description{1},'Image Regression') || strcmp(classiObj.description{1},'LSTM Regression') % image regression : no classes needed
                classiObj.classes={};
                %   app.SpaceseparatedclassnamesEditField
                app.SpaceseparatedclassnamesEditField.Enable='off';

            else
                app.SpaceseparatedclassnamesEditField.Enable='on';
            end

            if numel(classiObj.classes)
                str='';
                for i=1:numel(classiObj.classes)
                    str=[str classiObj.classes{i} ' '];
                end
                app.SpaceseparatedclassnamesEditField.Value=str;
            else
                app.SpaceseparatedclassnamesEditField.Value='';
            end

            % postprocessing functions
            outputstr='';
            % outut functions


            if strcmp(classiObj.category{1},'Pixel')
                %   aa=classiObj.outputFun
                %   class(aa)
                app.PostprocessingDropDown.Enable='on';
                if numel(classiObj.outputType)
                    outputstr=classiObj.outputType;
                else
                    outputstr='proba';
                end
                %  outputstr

                switch outputstr
                    case 'proba'
                        outputstr='plain output / probabilities for each class';
                    case 'segmentation'
                        outputstr='plain output / semantic segmentation';
                    case 'postprocessing'
                        if ischar(classiObj.outputFun)
                            if strcmp(classiObj.outputFun,'post')
                                outputstr='postprocessing';
                            else
                                outputstr='custom function (see below)';
                            end
                        end
                end

                app.PostprocessingDropDown.Value=outputstr;


                if strcmp(outputstr,'custom function (see below)')
                    app.PostprocessingcustomfunctionhandleEditField.Enable='on';

                    if  numel(classiObj.outputFun)
                        if ischar(classiObj.outputFun)
                            app.PostprocessingcustomfunctionhandleEditField.Value=classiObj.outputFun;
                        end
                    end
                else
                    app.PostprocessingcustomfunctionhandleEditField.Enable='off';
                    app.PostprocessingcustomfunctionhandleEditField.Value='';
                end

                outputArg=classiObj.outputArg;
                app.WatershedCheckBox.Value=false;
                app.FilteroutsmallobjectsCheckBox.Value=false;
                app.SizepixelEditField.Value=10;
                app.FixedthresholdButton.Value=false;
                app.ValueEditField.Value=num2str(0.9);
                app.AdaptivethresholdOtsumethodButton.Value=false;
                app.MaximumprobabilityButton.Value=false;

                if  strcmp(outputstr,'postprocessing')

                    app.PostprocessingcustomfunctionhandleEditField.Value='post';
                    app.PostprocessingparametersPanel.Enable='on';
                    app.PostprocessingcustomfunctionhandleEditField.Value='post';

                    % display postprocessing parameters


                    for j=1:numel(outputArg)

                        if strcmp(outputArg{j},'watershed')
                            app.WatershedCheckBox.Value=true;
                        end

                        if strcmp(outputArg{j},'sizethreshold')
                            app.FilteroutsmallobjectsCheckBox.Value=true;
                            app.SizepixelEditField.Value=str2double(outputArg{j+1});
                        end
                        if strcmp(outputArg{j},'threshold')
                            app.FixedthresholdButton.Value=true;

                            app.ValueEditField.Value=num2str(outputArg{j+1});
                        end
                        if strcmp(outputArg{j},'adaptivethreshold')
                            app.AdaptivethresholdOtsumethodButton.Value=true;

                        end
                        if strcmp(outputArg{j},'maxproba')
                            app.MaximumprobabilityButton.Value=true;
                        end
                    end


                end
            else
                app.PostprocessingDropDown.Enable='off';
            end


            % HERE
        end

        function displayData(app)
            rois=app.Data.classiObj.roi;

            trainingrois=app.Data.classiObj.trainingset;

            % formatting roi table to be displayed :
            % columns :  selected for training , select for test, roi index, roi id ,
            % isannotated, isvalidated

            t=app.UITableData;

            Data={};

            if numel(rois)==1
                if numel(rois.id)==0
                    Data={};
                    t.Data={};
                    return;
                    % there is no roi available
                end
            end

            for i=1:numel(rois)

                istrainee=false;
                istest=true;
                isannotated=false;
                isvalidated=false;
                %  pix=find(trainingrois==i);
                if numel(find(trainingrois==i))>0

                    istrainee=true;
                    istest=false;
                end


                category=app.Data.classiObj.category{1};
                if strcmp(category,'Pixel') % pixel classification
                    % contains(rois(i).display.channel==app.Data.classiObj.strid)

                    t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};
                    t.ColumnWidth={150, 150, 100, 'auto' };
                    t.ColumnEditable=[true true false false];
                    Data(i,:)={istrainee, istest, i, rois(i).id};

                else % all other classification

                    if isfield(rois(i).train,app.Data.classiObj.strid)
                        if numel(rois(i).train.(app.Data.classiObj.strid).id)
                            if sum(rois(i).train.(app.Data.classiObj.strid).id)>0
                            isannotated=true;
                            end
                        end
                    end
                    if isfield(rois(i).results,app.Data.classiObj.strid)

if isfield(rois(i).results.(app.Data.classiObj.strid),'prob')
                        if numel(rois(i).results.(app.Data.classiObj.strid).prob)
                             if sum(rois(i).results.(app.Data.classiObj.strid).prob(:))
                            isvalidated=true;
                             end
                        end
end
                    end


                    t.ColumnName={'Select for training','Select for testing',' ROI index','ROI Id','is annotated','is validated'};
                    t.ColumnWidth={150, 150, 100, 'auto', 100, 100 };
                    t.ColumnEditable=[true true false false false false];
                    Data(i,:)={istrainee, istest, i, rois(i).id, isannotated, isvalidated};
                end

            end


            t.Data=Data;


        end

        function  checkStatus(app,saving)
            % checks whether classi parameters are saved and classifier is
            % in memory

            classiObj=app.Data.classiObj;

            [~, check]=classiObj.loadClassifier('check');

            if check
                app.StatusLoad.Color=[0 1 0];

            end
            %   end

            % checks whether paramas have changed
            if nargin==2

                if saving==false
                    app.StatusSaved.Color=[1 0 0];
                else
                    app.StatusSaved.Color=[0 1 0];
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj)
            % HERE
            % app.callingApp=callingApp;
            % displayNodes(callingApp)
            %  gatherVarsFromWorkspace(callingApp)

            if nargin==1
                classiObj=classi;
                classiObj.path='';
                classiObj.strid='No input classi was provided';
            end

            app.UIFigure.Name=[classiObj.strid];
            app.Data.classiObj=classiObj;

            str=which('shallowNew.m');
            [pth file ext]=fileparts(str);
            str=[pth '/classification/classlist.mat'];
            load(str);
            app.Data.classlist=classlist;
            app.TypeDropDown.Items=classlist{:,2};
            app.Data.selected=[];

            if nargin>1
                displayClassi(app);
            end

            if numel(classiObj.trainingParam)==0
                classiObj.trainClassifier('setparam');
            end
            
            if ~isfield(classiObj.trainingParam,'transfer_learning')
                [t,lastIndex]=classiObj.version;
                if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                end
                str=['ImageNet', str', 'ImageNet'];
                classiObj.trainingParam.transfer_learning=str;
                classiObj.trainingParam.tip{end+1}='Select version of the classifier to be used';
                checkStatus(app,false);
            else
                [t,lastIndex]=classiObj.version;
                   if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                   end
                str=['ImageNet', str', classiObj.trainingParam.transfer_learning{end}];
                classiObj.trainingParam.transfer_learning=str;
                checkStatus(app,true);
            end

            trainingParam=classiObj.trainingParam;
            tip=trainingParam.tip;
            trainingParam=rmfield(trainingParam,'tip');
            app.TrainingprocedureparametersTab.UserData=[];
            delete(app.TrainingprocedureparametersTab.Children);
            struct2GUI(trainingParam,[10 510],'Handle',app.TrainingprocedureparametersTab,'Flag',app.StatusSaved,'Tip',tip);
            app.TrainingprocedureparametersTab.UserData.tip=tip;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function
        function SaveclassifierparametersButtonPushed(app, event)
            disp('Saving classifiers')

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier and trainingset...');
            d.Value=0.33;


            if numel(app.UITableData.Data)
                selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
                app.Data.classiObj.trainingParam.rois=find(selectedfortraining');
            else
                app.Data.classiObj.trainingParam.rois =1:numel(app.Data.classiObj.roi);
            end

            classiSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';


            d.Value=1;
            close(d)

        end

        % Button down function: TrainingprocedureparametersTab
        function TrainingprocedureparametersTabButtonDown(app, event)
            %displayTraining(app)

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            pix=find(matches(app.TypeDropDown.Items,value));

            test=uiconfirm(app.UIFigure,'Training parameters will be erased if you change the type of classifer ! Proceed?','Warning','Icon','Warning');

            if strcmp(test,'Cancel')
                return;
            end


            app.Data.classiObj.typeid=pix;

            classlist=app.Data.classlist;

            app.Data.classiObj.description={classlist{pix,2} ' - ' classlist{pix,3}};
            app.Data.classiObj.category=classlist{pix,4};
            app.Data.classiObj.classifyFun=classlist{pix,6}{1};
            app.Data.classiObj.trainingFun=classlist{pix,5}{1};

            app.Data.classiObj.trainingParam=[];
            
            % reset parameters
            app.Data.classiObj.trainClassifier('setparam');
            classiObj=app.Data.classiObj;
            
            % set parameter menu:
              if ~isfield(classiObj.trainingParam,'transfer_learning')
                [t,lastIndex]=classiObj.version;
                 if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                 end
                str=['ImageNet', str', 'ImageNet'];
                classiObj.trainingParam.transfer_learning=str;
                classiObj.trainingParam.tip{end+1}='Select version of the classifier to be used';
                checkStatus(app,false);
            else
                [t,lastIndex]=classiObj.version;
                 if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                 end
                str=['ImageNet', str', classiObj.trainingParam.transfer_learning{end}];
                classiObj.trainingParam.transfer_learning=str;
                checkStatus(app,true);
              end
            
            trainingParam=classiObj.trainingParam;
            tip=trainingParam.tip;
            trainingParam=rmfield(trainingParam,'tip');
            app.TrainingprocedureparametersTab.UserData=[];
            delete(app.TrainingprocedureparametersTab.Children);
            struct2GUI(trainingParam,[10 510],'Handle',app.TrainingprocedureparametersTab,'Flag',app.StatusSaved,'Tip',tip);
            app.TrainingprocedureparametersTab.UserData.tip=tip;
            
            trainingParam=app.Data.classiObj.trainingParam;
            tip=trainingParam.tip;
            trainingParam=rmfield(trainingParam,'tip');
            app.TrainingprocedureparametersTab.UserData=[];
            delete(app.TrainingprocedureparametersTab.Children);
            struct2GUI(trainingParam,[10 510],'Handle',app.TrainingprocedureparametersTab,'Flag',app.StatusSaved,'Tip',tip);
            app.TrainingprocedureparametersTab.UserData.tip=tip;
            
            % aa=classlist{pix,3}
            displayClassi(app);
            
        end

        % Callback function
        function ChannelnameusedasinputEditFieldValueChanged(app, event)
            value = app.ChannelnameusedasinputEditField.Value;
            app.Data.classiObj.channel=str2num(value);
            checkStatus(app,false)
        end

        % Value changed function: SpaceseparatedclassnamesEditField
        function SpaceseparatedclassnamesEditFieldValueChanged(app, event)
            value = app.SpaceseparatedclassnamesEditField.Value;
            classes = textscan(value,'%s','Delimiter',' ');
            app.Data.classiObj.classes=classes{1};
            app.Data.classiObj.colormap=shallowColormap(numel(classes{1}));

            for i=1:numel(app.Data.classiObj.roi)
                app.Data.classiObj.roi(i).classes= app.Data.classiObj.classes;
            end
            
            checkStatus(app,false)
        end

        % Callback function
        function PostprocessingfunctionhandleEditFieldValueChanged(app, event)
            value =PostprocessingfunctionhandleEditFieldValueChanged.Value;
            app.Data.classiObj.outputFun=str2func(value);
            checkStatus(app,false)
        end

        % Value changed function: PostprocessingDropDown
        function PostprocessingDropDownValueChanged(app, event)
            value = app.PostprocessingDropDown.Value;
            pix=find(contains(app.PostprocessingDropDown.Items,value));

            switch pix
                case 1
                    app.Data.classiObj.outputType='proba';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                case 2
                    app.Data.classiObj.outputType='segmentation';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                case 3
                    app.Data.classiObj.outputType='postprocessing';
                    app.Data.classiObj.outputFun='post';
                    app.Data.classiObj.outputArg={'threshold','0.9'};
                case 4
                    app.Data.classiObj.outputType='postprocessing';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                    app.PostprocessingcustomfunctionhandleEditField.Value='';
            end

            checkStatus(app,false)
            displayClassi(app);
        end

        % Value changed function: 
        % PostprocessingcustomfunctionhandleEditField
        function PostprocessingcustomfunctionhandleEditFieldValueChanged2(app, event)
            value = app.PostprocessingcustomfunctionhandleEditField.Value;
            app.Data.classiObj.outputFun=value;
            checkStatus(app,false)
        end

        % Button down function: DatasetsROIsTab
        function DatasetsROIsTabButtonDown(app, event)
            displayData(app);
        end

        % Cell edit callback: UITableData
        function UITableDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)==2
                app.UITableData.Data{indices(1),1}=~app.UITableData.Data{indices(1),1};
            end
            if indices(2)==1
                app.UITableData.Data{indices(1),2}=~app.UITableData.Data{indices(1),2};
            end

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');


            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: AnnotateselectedROIButton
        function AnnotateselectedROIButtonPushed(app, event)
            sel=app.Data.selected;
            if numel(sel)==0
                uialert(app.UIFigure,'First select an ROI!','Error')
                return;
            end
            disp(['Launching ROI #' num2str(sel) '.... Please wait...']);
            app.Data.classiObj.userTraining('Roi',sel);
            checkStatus(app,false)
        end

        % Cell selection callback: UITableData
        function UITableDataCellSelection(app, event)
            indices = event.Indices;
            if numel(indices)
            app.Data.selected=indices(1);
            end

        end

        % Button pushed function: ImportROIsButton
        function ImportROIsButtonPushed(app, event)

            roiImporterGUI(app)
            uiwait(app.UIFigure);

            displayData(app);
            displayClassi(app); % uodates the channel in particular in the classi tab
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            
            n=size(app.UITableData.Data,1);
            t=cell(1,n);
            t(:)={true};
  
            app.UITableData.Data(:,1)=t';
            
            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            n=size(app.UITableData.Data,1);
            t=cell(1,n);
            t(:)={false};
  
            app.UITableData.Data(:,1)=t';

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: removeselectedROIButton
        function removeselectedROIButtonPushed(app, event)

            sel=app.Data.selected;
            if numel(sel)
                if numel(app.Data.classiObj.roi)==1
                    app.Data.classiObj.roi=roi;
                else

                    pix=setxor(1:numel(app.Data.classiObj.roi),sel,'stable');
                    pix = intersect(pix,  1:numel(app.Data.classiObj.roi));
                    app.Data.classiObj.roi=app.Data.classiObj.roi(pix);
                end
            end

            checkStatus(app,false)
            displayData(app);

        end

        % Menu selected function: DisplaystatisticsMenu
        function DisplaystatisticsMenuSelected(app, event)
            classiObj=app.Data.classiObj;
            aa=app.UITableData.Data;

            if numel(aa)==0
                uialert(app.UIFigure,'First select the testset in the dataset tab or make sure you have ROIs!','Error');
                return;
            end


            % gather the test set.

            selectedfortest=find(cellfun(@(x) x==0,app.UITableData.Data(:,1)));
            classiObj.stats('Confusion','Classes','Rois',selectedfortest','Force');
        end

        % Menu selected function: ValidateclassifierusingtestsetMenu
        function ValidateclassifierusingtestsetMenuSelected(app, event)

            classiObj=app.Data.classiObj;

%             if ~exist(fullfile(classiObj.path,'trainingParam.mat'))
%                 uialert(app.UIFigure,'Error','Training parameters were not saved !')
%                 return;
%             end

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Checking number of frames');
            d.Value=0.1;

            if numel(classiObj.roi(1).image)==0
                classiObj.roi(1).load;
            end

            nframes=size(classiObj.roi(1).image,4);

            prompt = {'Process only ROIs/Frames in which groundtruth is available (yes -> 1; no -> 0):','Frames to be processed: (write -1 if variable frame numbers)','Parallel computing (yes -> 1; no -> 0):','Use additional CNN only classification (yes -> 1; no -> 0) - for LSTM classification'};
            dlgtitle = 'Input classifier validation parameters';

            dims = [1 100];

            definput = {'1',['1:' num2str(nframes)],'0','0'};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            if numel(answer)==0
                return;
            end

            % here bug suspected
            if numel(app.UITableData.Data)==0
                displayData(app);
            end

            selectedfortesting=cellfun(@(x) x==0,app.UITableData.Data(:,1));

            roiobj=classiObj.roi(selectedfortesting);

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Checking network classifier performance using groundtruth data // test dataset; Please wait...');
            d.Value=0.2;

            arg={};
            cc=1;
            if strcmp(answer{1},'1')
                arg{cc}='RoiWithGT';
                cc=cc+1;
            end

            if strcmp(answer{3},'1')
                arg{cc}='Parallel';
                cc=cc+1;
            end

            arg{cc}='Frames';
            cc=cc+1;
            arg{cc}=answer{2};
            cc=cc+1;

            if strcmp(answer{4},'1')
                arg{cc}='ClassifierCNN';
                cc=cc+1;
            end

            arg{cc}='Progress';
            cc=cc+1;
            arg{cc}=d;
            cc=cc+1;

            classiObj.validateTrainingData(roiobj,arg{:});

            d.Value=1;

            pause(1);
            close(d)
        end

        % Menu selected function: FormattrainingsetMenu
        function FormattrainingsetMenuSelected(app, event)
         classiObj=  app.Data.classiObj;
                nrois=classiObj.trainingset; 
            if numel(nrois)==0
                  uialert(app.UIFigure,'You must select at least one ROI in the Dataset panel  !','Error')
                 return;
            end


            prompt = {'Frames to be processed'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Input data formatting parameters (type 0 if all frames should be processed)';
            
            dims = [1 100];
             
            definput = {'0'};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if numel(answer)==0
                return;
            end
            
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Exporting trainingset to files; Please wait...');
            d.Value=0.33;
        
            if str2num(answer{1})==0
            output=app.Data.classiObj.formatDataForTraining;
            else
            output=app.Data.classiObj.formatDataForTraining('Frames',str2num(answer{1}));
            end

            d.Value=0.66;

            if numel(output)>0
                d.Message=[num2str(output) ' files/images were exported; The classifier is reday to be trained!'];
            else
                d.Message=['No file was exported; Check your trainingset!'];
                pause(1)
            end

            pause(2);
            close(d)
        end

        % Menu selected function: TrainClassifierMenu
        function TrainClassifierMenuSelected(app, event)
            classiObj=app.Data.classiObj;

            if app.StatusSaved.Color==[1 0 0]

                uialert(app.UIFigure,'Error','Training parameters were not saved !')
                 return;
            end

            nrois=classiObj.trainingset; 
            if numel(nrois)==0
                  uialert(app.UIFigure,'You must select at least one ROI in the Dataset panel  !','Error')
                 return;
            end

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Training network; Please wait...');
            d.Value=0.5;

            [~, check]=classiObj.loadClassifier('check');
            if check==1
                evalin('base',['clear ' classiObj.strid]);
            end

            checkStatus(app);


            classiObj.trainClassifier;

            d.Value=1;

            pause(1);
            close(d)
        end

        % Menu selected function: SaveclassifierparametersMenu
        function SaveclassifierparametersMenuSelected(app, event)
            disp('Saving classifiers')

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier and trainingset...');
            d.Value=0.33;

            if numel(app.UITableData.Data)
                selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
                app.Data.classiObj.trainingset=find(selectedfortraining');
            else
                app.Data.classiObj.trainingset =1:numel(app.Data.classiObj.roi);
            end

            app.Data.classiObj.trainingParam=app.TrainingprocedureparametersTab.UserData;

            classiSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';

            %a=app.Data.classiObj.path


            %             if isfolder(app.Data.classiObj.path)
            %                 warning off all;
            %                 mkdir([app.Data.classiObj.path '/TrainingValidation'])
            %
            %                 warning on all;
            %
            %                 trainingParam=app.Data.trainingParam;
            %               %  trainingParam.MiniBatchSize=trainingParam.MinBatchSize;
            %
            %               %  trainingParam=rmfield(trainingParam,'MinBatchSize');
            %
            %                 save([app.Data.classiObj.path '/trainingParam.mat'],'trainingParam');
            %                 save([app.Data.classiObj.path '/TrainingValidation' '/trainingParam.mat'],'trainingParam');
            %
            %                 d.Value=1;
            %                 d.Message='Classifier is saved !';
            %             else
            %                 d.Value=1;
            %                 d.Message='Could not find/access the folder to save parameters; Check your connection? Quitting!';
            %                 pause(1);
            %             end
            pause(0.5);
            close(d)

            checkStatus(app,true)
        end

        % Callback function
        function ChannelsavailableintrainingsetDropDownValueChanged(app, event)
            value = app.ChannelsavailableintrainingsetDropDown.Value;

            app.ChannelnameusedasinputEditField.Value=value;
            app.Data.classiObj.channelName=value;
            app.Data.classiObj.channel(1)= find(matches(app.ChannelsavailableintrainingsetDropDown.Items,value));
            checkStatus(app,false)
        end

        % Callback function
        function ChannelsavailableintrainingsetDropDown_2ValueChanged(app, event)
            value = app.ChannelsavailableintrainingsetDropDown_2.Value;
            app.AdditionalchannelforclassificationobjectsorregressionEditField.Value=value;
            app.Data.classiObj.channelName2=value;
            app.Data.classiObj.channel(2)= find(matches(app.ChannelsavailableintrainingsetDropDown_2.Items,value));
            checkStatus(app,false)
        end

        % Value changed function: WatershedCheckBox
        function WatershedCheckBoxValueChanged(app, event)
            value = app.WatershedCheckBox.Value;
            str= app.Data.classiObj.outputArg;

            if value==false
                str=setxor(str,'watershed','stable') ;
            else
                str=[str 'watershed'];
            end


            app.Data.classiObj.outputArg=str;
            checkStatus(app,false);
            displayClassi(app);
        end

        % Value changed function: FilteroutsmallobjectsCheckBox
        function FilteroutsmallobjectsCheckBoxValueChanged(app, event)
            value = app.FilteroutsmallobjectsCheckBox.Value;
            str= app.Data.classiObj.outputArg;

            if value==false
                pix=find(matches(str,'sizethreshold'));
                if numel(pix)
                    val=str(pix+1);
                    str=setxor(str,val{1},'stable');
                end
                str=setxor(str,'sizethreshold','stable');
                %       str
            else
                str=[str 'sizethreshold' num2str(app.SizepixelEditField.Value)];
            end

            app.Data.classiObj.outputArg=str;
            checkStatus(app,false);
            displayClassi(app);
        end

        % Value changed function: SizepixelEditField
        function SizepixelEditFieldValueChanged(app, event)
            value = app.SizepixelEditField.Value;
            str= app.Data.classiObj.outputArg;

            pix=find(matches(str,'sizethreshold'));
            if numel(pix)
                val=str(pix+1);
                str{pix+1}=num2str(value);
                app.Data.classiObj.outputArg=str;
                checkStatus(app,false);
                displayClassi(app);
            end

        end

        % Selection changed function: ThresholdingButtonGroup
        function ThresholdingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThresholdingButtonGroup.SelectedObject;

            str= app.Data.classiObj.outputArg;

            switch selectedButton.Text
                case 'Maximum probability'

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)==0
                        str=[str 'maxproba'];
                    end

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)
                        str=setxor(str,'adaptivethreshold','stable');
                    end

                    pix=find(matches(str,'threshold'));
                    if numel(pix)
                        val=str(pix+1);
                        str=setxor(str,val{1},'stable');
                        str=setxor(str,'threshold','stable');
                    end

                case 'Adaptive threshold (Otsu method)'

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)==0
                        str=[str 'adaptivethreshold'];
                    end

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)
                        str=setxor(str,'maxproba','stable');
                    end

                    pix=find(matches(str,'threshold'));

                    if numel(pix)
                        val=str(pix+1);
                        str=setxor(str,val{1},'stable');
                        str=setxor(str,'threshold','stable');
                    end


                case 'Fixed threshold'

                    pix=find(matches(str,'threshold'));

                    if numel(pix)==0
                        str=[str 'threshold' num2str(app.ValueEditField.Value)];
                    end

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)
                        str=setxor(str,'maxproba','stable');
                    end

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)
                        str=setxor(str,'adaptivethreshold','stable');
                    end

            end

            app.Data.classiObj.outputArg=str;
            checkStatus(app,false)
            displayClassi(app);

        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            value = app.ValueEditField.Value;

            str= app.Data.classiObj.outputArg;

            pix=find(matches(str,'threshold'));
            if numel(pix)
                val=str(pix+1);
                str{pix+1}=num2str(value);
                app.Data.classiObj.outputArg=str;
                checkStatus(app,false)
                displayClassi(app);
            end

        end

        % Menu selected function: LoadclassifierMenu
        function LoadclassifierMenuSelected(app, event)

            classiObj=app.Data.classiObj;
            classifier=classiObj.loadClassifier;
            %assignin('base',classiObj.strid,classifier);
            checkStatus(app)
        end

        % Menu selected function: CheckstatusMenu
        function CheckstatusMenuSelected(app, event)
            checkStatus(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            classiObj=app.Data.classiObj;
            value=app.ChannelListBox.Value;

            if numel(app.ChannelListBoxSel.Items)==0 | matches(app.ChannelListBoxSel.Items,value)==false
                app.ChannelListBoxSel.Items=[app.ChannelListBoxSel.Items value];
            end

            classiObj.channelName= app.ChannelListBoxSel.Items;
            app.Data.classiObj=classiObj;
            displayClassi(app);

        end

        % Button pushed function: DeSelectButton
        function DeSelectButtonPushed(app, event)
            classiObj=app.Data.classiObj;
            value=app.ChannelListBoxSel.Value;


            app.ChannelListBoxSel.Items=setxor(app.ChannelListBoxSel.Items,value);

            classiObj.channelName= app.ChannelListBoxSel.Items;
            app.Data.classiObj=classiObj;
            displayClassi(app);
        end

        % Menu selected function: BackupcurrentclassifierversionMenu
        function BackupcurrentclassifierversionMenuSelected(app, event)
           disp('Backuping current version of the classifier')

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier model and network');
            d.Value=0.33;

            if numel(app.UITableData.Data)
                selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
                app.Data.classiObj.trainingset=find(selectedfortraining');
            else
                app.Data.classiObj.trainingset =1:numel(app.Data.classiObj.roi);
            end

            app.Data.classiObj.trainingParam=app.TrainingprocedureparametersTab.UserData;

            app.Data.classiObj.bk; % backs up classification and classifier
            
            d.Value=0.67;
            d.Message='Saving training parameters...';

            %a=app.Data.classiObj.path


            pause(0.5);
            close(d)

            checkStatus(app,true)
            
            startupFcn(app,app.Data.classiObj);
            
        end

        % Menu selected function: RestorepreviousclassifierMenu
        function RestorepreviousclassifierMenuSelected(app, event)
            classifierBKGUI(app.Data.classiObj)
        end

        % Value changed function: SelectROIsEditField
        function SelectROIsEditFieldValueChanged(app, event)
            value = app.SelectROIsEditField.Value;
            
            value=str2num(value);
            
            t=size(app.UITableData.Data,1);
            value=intersect(value,1:t);
            
            if numel(value)==0
                return
            end
            
            dt=cell(1,t);
            dt(:)={false};
            dt(value)={true}; 
          
            app.UITableData.Data(:,1)=dt';

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
            
        end

        % Value changed function: ShuffleROIsfractionEditField
        function ShuffleROIsfractionEditFieldValueChanged(app, event)
            value = app.ShuffleROIsfractionEditField.Value;
            
            value=str2num(value);
            
            t=size(app.UITableData.Data,1);
            id=randperm(t);
            
            value=min(value,1);
            value=max(value,0);
            
            dt=cell(1,t);
            dt(:)={false};
            
            dt(id(1:round(value*t)))={true};
  
            app.UITableData.Data(:,1)=dt';

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveclassifierparametersMenu = uimenu(app.FileMenu);
            app.SaveclassifierparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveclassifierparametersMenuSelected, true);
            app.SaveclassifierparametersMenu.Text = 'Save classifier parameters';
            app.BackupcurrentclassifierversionMenu = uimenu(app.FileMenu);
            app.BackupcurrentclassifierversionMenu.MenuSelectedFcn = createCallbackFcn(app, @BackupcurrentclassifierversionMenuSelected, true);
            app.BackupcurrentclassifierversionMenu.Separator = 'on';
            app.BackupcurrentclassifierversionMenu.Text = 'Back-up current classifier version';
            app.RestorepreviousclassifierMenu = uimenu(app.FileMenu);
            app.RestorepreviousclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @RestorepreviousclassifierMenuSelected, true);
            app.RestorepreviousclassifierMenu.Text = 'Restore previous classifier...';
            app.ClassifierMenu = uimenu(app.UIFigure);
            app.ClassifierMenu.Text = 'Classifier';
            app.FormattrainingsetMenu = uimenu(app.ClassifierMenu);
            app.FormattrainingsetMenu.MenuSelectedFcn = createCallbackFcn(app, @FormattrainingsetMenuSelected, true);
            app.FormattrainingsetMenu.Text = 'Format training set...';
            app.TrainClassifierMenu = uimenu(app.ClassifierMenu);
            app.TrainClassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainClassifierMenuSelected, true);
            app.TrainClassifierMenu.Text = 'Train Classifier...';
            app.ValidateclassifierusingtestsetMenu = uimenu(app.ClassifierMenu);
            app.ValidateclassifierusingtestsetMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidateclassifierusingtestsetMenuSelected, true);
            app.ValidateclassifierusingtestsetMenu.Text = 'Validate classifier using test set...';
            app.DisplaystatisticsMenu = uimenu(app.ClassifierMenu);
            app.DisplaystatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaystatisticsMenuSelected, true);
            app.DisplaystatisticsMenu.Text = 'Display statistics...';
            app.LoadclassifierMenu = uimenu(app.ClassifierMenu);
            app.LoadclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadclassifierMenuSelected, true);
            app.LoadclassifierMenu.Text = 'Load classifier';
            app.CheckstatusMenu = uimenu(app.ClassifierMenu);
            app.CheckstatusMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckstatusMenuSelected, true);
            app.CheckstatusMenu.Separator = 'on';
            app.CheckstatusMenu.Text = 'Check status...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClassifierparametersTab = uitab(app.TabGroup);
            app.ClassifierparametersTab.Title = 'Classifier parameters';
            app.TypeLabel = uilabel(app.ClassifierparametersTab);
            app.TypeLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ClassifierparametersTab);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.SpaceseparatedclassnamesLabel = uilabel(app.ClassifierparametersTab);
            app.SpaceseparatedclassnamesLabel.Text = 'Space-separated class names: ';
            app.SpaceseparatedclassnamesEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.SpaceseparatedclassnamesEditField.ValueChangedFcn = createCallbackFcn(app, @SpaceseparatedclassnamesEditFieldValueChanged, true);
            app.SpaceseparatedclassnamesEditField.Tooltip = {'For classification, class definition is mandaztory; If emtpy, the classifier will output a regression'};
            app.PostprocessingcustomfunctionhandleEditFieldLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingcustomfunctionhandleEditFieldLabel.Text = 'Post-processing custom function handle:';
            app.PostprocessingcustomfunctionhandleEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.PostprocessingcustomfunctionhandleEditField.ValueChangedFcn = createCallbackFcn(app, @PostprocessingcustomfunctionhandleEditFieldValueChanged2, true);
            app.ClassiDetailsLabel = uilabel(app.ClassifierparametersTab);
            app.ClassiDetailsLabel.Text = 'ClassiDetails';
            app.PostprocessingDropDownLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingDropDownLabel.Text = 'Post-processing';
            app.PostprocessingDropDown = uidropdown(app.ClassifierparametersTab);
            app.PostprocessingDropDown.Items = {'plain output / probabilities for each class', 'plain output / semantic segmentation', 'postprocessing', 'custom function (see below)'};
            app.PostprocessingDropDown.ValueChangedFcn = createCallbackFcn(app, @PostprocessingDropDownValueChanged, true);
            app.PostprocessingDropDown.Value = 'plain output / probabilities for each class';
            app.PostprocessingparametersPanel = uipanel(app.ClassifierparametersTab);
            app.PostprocessingparametersPanel.Title = 'Post-processing parameters';
            app.WatershedCheckBox = uicheckbox(app.PostprocessingparametersPanel);
            app.WatershedCheckBox.ValueChangedFcn = createCallbackFcn(app, @WatershedCheckBoxValueChanged, true);
            app.WatershedCheckBox.Tooltip = {'Applies a watershed segmentation on the output'};
            app.WatershedCheckBox.Text = 'Watershed';
            app.ThresholdingButtonGroup = uibuttongroup(app.PostprocessingparametersPanel);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingButtonGroupSelectionChanged, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.FixedthresholdButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.FixedthresholdButton.Text = 'Fixed threshold';
            app.FixedthresholdButton.Value = true;
            app.AdaptivethresholdOtsumethodButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.AdaptivethresholdOtsumethodButton.Text = 'Adaptive threshold (Otsu method)';
            app.MaximumprobabilityButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.MaximumprobabilityButton.Text = 'Maximum probability';
            app.ValueEditFieldLabel = uilabel(app.ThresholdingButtonGroup);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.ValueEditField = uieditfield(app.ThresholdingButtonGroup, 'text');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.ValueEditField.Value = '0.9';
            app.FilteroutsmallobjectsCheckBox = uicheckbox(app.PostprocessingparametersPanel);
            app.FilteroutsmallobjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteroutsmallobjectsCheckBoxValueChanged, true);
            app.FilteroutsmallobjectsCheckBox.Tooltip = {'Applies a watershed segmentation on the output'};
            app.FilteroutsmallobjectsCheckBox.Text = 'Filter out small objects';
            app.SizepixelEditFieldLabel = uilabel(app.PostprocessingparametersPanel);
            app.SizepixelEditFieldLabel.Text = 'Size (pixel):';
            app.SizepixelEditField = uieditfield(app.PostprocessingparametersPanel, 'numeric');
            app.SizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @SizepixelEditFieldValueChanged, true);
            app.AvailablechannelstakenfromtrainingdatasetROIsLabel = uilabel(app.ClassifierparametersTab);
            app.AvailablechannelstakenfromtrainingdatasetROIsLabel.Text = 'Available channels (taken from training dataset (ROIs):';
            app.ChannelListBox = uilistbox(app.ClassifierparametersTab);
            app.ChannelListBox.Items = {'Item 1', 'Item 2', '', ''};
            app.ChannelListBox.Tooltip = {'This is only activated after ROIs have been imported to this classifier (see tab #3 in this GUI).'};
            app.ChannelListBox.Value = 'Item 2';
            app.SelectedchannelsasclassificationinputLabel = uilabel(app.ClassifierparametersTab);
            app.SelectedchannelsasclassificationinputLabel.Text = 'Selected channels as classification input:';
            app.ChannelListBoxSel = uilistbox(app.ClassifierparametersTab);
            app.ChannelListBoxSel.Items = {'Item 1', '', '', ''};
            app.SelectButton = uibutton(app.ClassifierparametersTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = '>';
            app.DeSelectButton = uibutton(app.ClassifierparametersTab, 'push');
            app.DeSelectButton.ButtonPushedFcn = createCallbackFcn(app, @DeSelectButtonPushed, true);
            app.DeSelectButton.Text = '<';
            app.TrainingprocedureparametersTab = uitab(app.TabGroup);
            app.TrainingprocedureparametersTab.Title = 'Training procedure parameters';
            app.TrainingprocedureparametersTab.ButtonDownFcn = createCallbackFcn(app, @TrainingprocedureparametersTabButtonDown, true);
            app.DatasetsROIsTab = uitab(app.TabGroup);
            app.DatasetsROIsTab.Title = 'Datasets (ROIs)';
            app.DatasetsROIsTab.ButtonDownFcn = createCallbackFcn(app, @DatasetsROIsTabButtonDown, true);
            app.DatasetsROIsTab.Tag = 'list';
            app.UITableData = uitable(app.DatasetsROIsTab);
            app.UITableData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData.RowName = {};
            app.UITableData.CellEditCallback = createCallbackFcn(app, @UITableDataCellEdit, true);
            app.UITableData.CellSelectionCallback = createCallbackFcn(app, @UITableDataCellSelection, true);
            app.ImportROIsButton = uibutton(app.DatasetsROIsTab, 'push');
            app.ImportROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportROIsButtonPushed, true);
            app.ImportROIsButton.Text = 'Import ROIs...';
            app.AnnotateselectedROIButton = uibutton(app.DatasetsROIsTab, 'push');
            app.AnnotateselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateselectedROIButtonPushed, true);
            app.AnnotateselectedROIButton.Tooltip = {'Select a ROI in the ROI Index column first'};
            app.AnnotateselectedROIButton.Text = 'Annotate selected ROI...';
            app.SelectallButton = uibutton(app.DatasetsROIsTab, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.DatasetsROIsTab, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.removeselectedROIButton = uibutton(app.DatasetsROIsTab, 'push');
            app.removeselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedROIButtonPushed, true);
            app.removeselectedROIButton.Text = 'remove selected ROI';
            app.SelectROIsEditFieldLabel = uilabel(app.DatasetsROIsTab);
            app.SelectROIsEditFieldLabel.Text = 'Select ROIs #';
            app.SelectROIsEditField = uieditfield(app.DatasetsROIsTab, 'text');
            app.SelectROIsEditField.ValueChangedFcn = createCallbackFcn(app, @SelectROIsEditFieldValueChanged, true);
            app.ShuffleROIsfractionEditFieldLabel = uilabel(app.DatasetsROIsTab);
            app.ShuffleROIsfractionEditFieldLabel.Text = 'Shuffle ROIs fraction';
            app.ShuffleROIsfractionEditField = uieditfield(app.DatasetsROIsTab, 'text');
            app.ShuffleROIsfractionEditField.ValueChangedFcn = createCallbackFcn(app, @ShuffleROIsfractionEditFieldValueChanged, true);
            app.ShuffleROIsfractionEditField.Value = '1';
            app.StatusSaved = uilamp(app.UIFigure);
            app.StatusSaved.Tooltip = {'Classifier status'};
            app.StatusLoad = uilamp(app.UIFigure);
            app.StatusLoad.Tooltip = {'Classifier loaded in memory'};
            app.ParametersaresavedLabel = uilabel(app.UIFigure);
            app.ParametersaresavedLabel.Text = 'Parameters are saved:';
            app.ClassifierisloadedinworksapcememoryLabel = uilabel(app.UIFigure);
            app.ClassifierisloadedinworksapcememoryLabel.Text = 'Classifier is loaded in worksapce (memory):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end