classdef classifierImporterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifierUIFigure             matlab.ui.Figure
        TransferparametersPanel        matlab.ui.container.Panel
        ClassesnamesindestinationEditField  matlab.ui.control.EditField
        ClassesnamesindestinationEditFieldLabel  matlab.ui.control.Label
        ClassesnamesinsourceEditField  matlab.ui.control.EditField
        ClassesnamesinsourceEditFieldLabel  matlab.ui.control.Label
        PreserveannotationsCheckBox    matlab.ui.control.CheckBox
        EnterROIindicesEditField       matlab.ui.control.EditField
        EnterROIindicesEditFieldLabel  matlab.ui.control.Label
        TransferunformattedtrainingsetROIsCheckBox  matlab.ui.control.CheckBox
        TransferformattedgroundtruthimagesCheckBox  matlab.ui.control.CheckBox
        TransfertrainedclassifierfileCheckBox  matlab.ui.control.CheckBox
        TransfertrainingparametersCheckBox  matlab.ui.control.CheckBox
        ChooseamonglocallyavailableclassifiersDropDown  matlab.ui.control.DropDown
        ChooseamonglocallyavailableclassifiersDropDownLabel  matlab.ui.control.Label
        OuputclassificationpathnamePanel  matlab.ui.container.Panel
        EnternewclassifiernameEditField  matlab.ui.control.EditField
        EnternewclassifiernameEditFieldLabel  matlab.ui.control.Label
        SetclassifierpathLabel         matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        ProceedButton                  matlab.ui.control.Button
        ClassifiercreationmethodButtonGroup  matlab.ui.container.ButtonGroup
        DuplicatefromclassifierrepositoryButton  matlab.ui.control.RadioButton
        DuplicateexistingclassiferintheworkspaceButton  matlab.ui.control.RadioButton
        NewclassifierButton            matlab.ui.control.RadioButton
    end


    properties (Access = private)
        callingApp % Description
        inputProject % Description
    end
    
    properties (Access = public)
        Data % Description
    end

    methods (Access = private)
        
        function getAvailableClassi(app,option)
            
            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            cc=0;
            cd=0;
            app.Data={};

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});


                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];
                    end

                    st.Projectclassi{cc}=tmpclassi;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')
                    
                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};
                   % aa= st.Classifier{cd}
                end

            end

          %  st
            app.Data=st;
            app.Data.convert={};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, shallowObj)
            app.callingApp=callingApp;
           
            app.inputProject.shallowObj=shallowObj;
             app.TransferparametersPanel.Enable='off';
            if numel(app.inputProject.shallowObj)==0
                app.SetclassifierpathLabel.Enable='on';
                app.Button.Enable='on';
                app.ProceedButton.Enable='off';
                app.ClassifierUIFigure.Name='Creating a new independent classifier';
            else
                 app.ClassifierUIFigure.Name='Creating a project-dependent classifier';
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)
        end

        % Selection changed function: ClassifiercreationmethodButtonGroup
        function ClassifiercreationmethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClassifiercreationmethodButtonGroup.SelectedObject;

            if ~strcmp(selectedButton.Text,'New classifier')

                app.TransferparametersPanel.Enable='on';
                % list all avaiable classi in the workspace
                
                getAvailableClassi(app,'workspace')
                s=app.Data;
                
                cc=1;
                displaystr={};
                varstr={};
                availableROIs=[];
                
                for i=1:numel(s.Projectclassi)
                    
                    proj=s.Project{i};
                    
                    shallowObj=evalin('base',proj);
                    
                    
                    for k=1:numel(s.Projectclassi{i})
                        varstr{cc}=[proj '.processing.classification(' num2str(k) ')'];
                        
                        classiObj=evalin('base',varstr{cc});
                        rois=numel(classiObj.roi);
                        if rois==1 & numel(classiObj.roi(1).id)==0
                            rois=0;
                        end
                        
                        displaystr{cc}=[proj '  //  ' s.Projectclassi{i}{k} ' // ' num2str(rois) ' ROIs available'  ];
                        availableROIs(cc)=rois;
                        cc=cc+1;
                    end
                end
                for i=1:numel(s.Classifier)
                    
                    clas=s.Classifier{i};
                    varstr{cc}=clas;
                    
                    classiObj=evalin('base',varstr{cc});
                        rois=numel(classiObj.roi);
                        if rois==1 & numel(classiObj.roi(1).id)==0
                            rois=0;
                        end
                        
                    displaystr{cc}=[clas ' // ' num2str(rois) ' ROIs available'  ];
                    availableROIs(cc)=rois;
                   cc=cc+1;
                end
                
                app.ChooseamonglocallyavailableclassifiersDropDown.Items=displaystr;
                app.Data.varstr=varstr; % list of classifier variables in the workspace
                app.Data.displaystr=displaystr;
                app.Data.availableROIs=availableROIs;
                
                app.EnterROIindicesEditField.Value=['1:' num2str(app.Data.availableROIs(1))];
            else
                app.TransferparametersPanel.Enable='off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.inputProject.path=[];
           % filename=app.EnternewclassifiernameEditField.Value;

            [filename,path,rep] = uiputfile('*.mat','File Selection',fullfile(pwd,['no_need_to_set_filename_here']));
            if isequal(filename,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected ', fullfile(path, filename)]);
            end
            %         end

            app.inputProject.path=path;
            app.SetclassifierpathLabel.Text=path;
            app.ProceedButton.Enable='on';
        end

        % Close request function: ClassifierUIFigure
        function ClassifierUIFigureCloseRequest(app, event)
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)

        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

               d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
                    'Message','Creating new classifier...');
            d.Value=0.1;
            
            if numel(app.inputProject.shallowObj)==0 % independent classifier to be created
                    filename=app.EnternewclassifiernameEditField.Value;
                    path=app.inputProject.path;
                    classiObj=classiNew('path',path,'filename',filename); % creates and saves object
                    assignin('base',filename,classiObj);

                else % classifier is associated to project 
                    
                    filename=app.EnternewclassifiernameEditField.Value;
                    app.inputProject.shallowObj.addClassification('name',filename);
                    classiObj=app.inputProject.shallowObj.processing.classification(end);
            end


            if app.DuplicateexistingclassiferintheworkspaceButton.Value==1 % classifier transfered from exisiting one
                % gathering classi variable
                vars=app.Data.varstr;
                displaystr=app.Data.displaystr;
                selectedvarstr=app.ChooseamonglocallyavailableclassifiersDropDown.Value;
                ok=0;
                for i=1:numel(displaystr)
                    
                if strcmp(displaystr{i},selectedvarstr)
                    ok=1;
                    break
                end
                end
                if ok==0
                   uialert(app.DetecDivUIFigure,['Could not find item in the list!'] ,'Warning','Icon','warning');
                end
                selectedvar=evalin('base',vars{i});
                
                % gathering transfer options
                option=[];
                option(1)=app.TransfertrainingparametersCheckBox.Value;
                option(2)=app.TransfertrainedclassifierfileCheckBox.Value;
                option(3)=app.TransferformattedgroundtruthimagesCheckBox.Value;
                option(4)=app.TransferunformattedtrainingsetROIsCheckBox.Value;
                
                
                if numel(app.inputProject.shallowObj)==0
                    %classiObj
                else
                    classiObj=app.inputProject.shallowObj.processing.classification(end);  
                end
                
                rois=str2num(app.EnterROIindicesEditField.Value);
                
                d.Value=0.3;
                d.Message='Importing selected ROIs.... Please wait...';
             %   classiObj.importFromClassi(selectedvar,option,'rois',rois,'convert',app.Data.convert);

             option2=option;
             option2(4)=0; % roi iport should not be done  with importFromClassi 
                 classiObj.importFromClassi(selectedvar,option2);
                 app.Data.classiObj=classiObj; % this is required before calling roiImporterGUI; 

                 if option(4)==1
                 roiImporterGUI(app, selectedvar.strid) % pass the name of the 
                 uiwait(app.ClassifierUIFigure);
                 end
                %classiSave(classiObj);
        
            end

             d.Value=0.66;
              d.Message='Saving classifier.... Please wait...';
              
            classiSave(classiObj);
            
            d.Message='Done !';
            d.Value=1;
            pause(1);
            close(d);
            uiresume(app.callingApp.DetecDivUIFigure);
            
           %  if app.NewclassifierButton.Value==1 % new classifier from scratch
                 classifierGUI(classiObj) ; % laucnes classifier GUI 
          %   end
             
            delete(app)
        end

        % Value changed function: 
        % ChooseamonglocallyavailableclassifiersDropDown
        function ChooseamonglocallyavailableclassifiersDropDownValueChanged(app, event)
            value = app.ChooseamonglocallyavailableclassifiersDropDown.Value;
            pix=find(contains(app.ChooseamonglocallyavailableclassifiersDropDown.Items,value));
            app.EnterROIindicesEditField.Value=['1:' num2str(app.Data.availableROIs(pix))];
        end

        % Value changed function: 
        % TransferunformattedtrainingsetROIsCheckBox
        function TransferunformattedtrainingsetROIsCheckBoxValueChanged(app, event)
            value = app.TransferunformattedtrainingsetROIsCheckBox.Value;
            
            if value==1
            app.EnterROIindicesEditField.Enable='on';
            app.PreserveannotationsCheckBox.Enable='on';
            else
            app.EnterROIindicesEditField.Enable='off';    
            app.PreserveannotationsCheckBox.Enable='off';
            end
            
        end

        % Value changed function: PreserveannotationsCheckBox
        function PreserveannotationsCheckBoxValueChanged(app, event)
            value = app.PreserveannotationsCheckBox.Value;
         
 
                vars=app.Data.varstr;
                displaystr=app.Data.displaystr;
                selectedvarstr=app.ChooseamonglocallyavailableclassifiersDropDown.Value;
                ok=0;
                for i=1:numel(displaystr)
                    
                if strcmp(displaystr{i},selectedvarstr)
                    ok=1;
                    break
                end
                end
                if ok==0
                   uialert(app.DetecDivUIFigure,['Could not find item in the list!'] ,'Warning','Icon','warning');
                end
                tmp=evalin('base',vars{i});


            if value==1 % preserve training
            app.ClassesnamesindestinationEditField.Enable='on';
            app.ClassesnamesinsourceEditField.Enable='on';
            
            str=[];
            for i=1:numel(tmp.classes)
                str=[str ' ' tmp.classes{i}];
            end
            
            str2=str;
%             for i=1:numel(app.Data.mainApp.Data.classiObj.classes)
%                 str2=[str2 ' ' app.Data.mainApp.Data.classiObj.classes{i}];
%             end
            
            app.ClassesnamesinsourceEditField.Value=str;
            app.ClassesnamesindestinationEditField.Value=str2;
            app.Data.convert={str,str2};

            else
            app.ClassesnamesindestinationEditField.Enable='off';
            app.ClassesnamesinsourceEditField.Enable='off';
             app.Data.convert={};
            end
            
        end

        % Value changed function: ClassesnamesindestinationEditField
        function ClassesnamesindestinationEditFieldValueChanged(app, event)
            str2 = app.ClassesnamesindestinationEditField.Value;
            
              str=app.ClassesnamesinsourceEditField.Value;
            
            app.Data.convert={str,str2};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassifierUIFigure = uifigure('Visible', 'off');
            app.ClassifierUIFigure.Name = 'MATLAB App';
            app.ClassifierUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifierUIFigureCloseRequest, true);
            app.ClassifiercreationmethodButtonGroup = uibuttongroup(app.ClassifierUIFigure);
            app.ClassifiercreationmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassifiercreationmethodButtonGroupSelectionChanged, true);
            app.ClassifiercreationmethodButtonGroup.Title = 'Classifier creation method';
            app.NewclassifierButton = uiradiobutton(app.ClassifiercreationmethodButtonGroup);
            app.NewclassifierButton.Text = 'New classifier';
            app.NewclassifierButton.Value = true;
            app.DuplicateexistingclassiferintheworkspaceButton = uiradiobutton(app.ClassifiercreationmethodButtonGroup);
            app.DuplicateexistingclassiferintheworkspaceButton.Text = 'Duplicate existing classifer in the workspace';
            app.DuplicatefromclassifierrepositoryButton = uiradiobutton(app.ClassifiercreationmethodButtonGroup);
            app.DuplicatefromclassifierrepositoryButton.Enable = 'off';
            app.DuplicatefromclassifierrepositoryButton.Tooltip = {'Not yet enabled'};
            app.DuplicatefromclassifierrepositoryButton.Text = 'Duplicate from classifier repository';
            app.ProceedButton = uibutton(app.ClassifierUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CloseButton = uibutton(app.ClassifierUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OuputclassificationpathnamePanel = uipanel(app.ClassifierUIFigure);
            app.OuputclassificationpathnamePanel.Title = 'Ouput classification path & name';
            app.Button = uibutton(app.OuputclassificationpathnamePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '...';
            app.SetclassifierpathLabel = uilabel(app.OuputclassificationpathnamePanel);
            app.SetclassifierpathLabel.Enable = 'off';
            app.SetclassifierpathLabel.Tooltip = {'A path is required only when setting up an independent classifier'};
            app.SetclassifierpathLabel.Text = 'Set classifier path:';
            app.EnternewclassifiernameEditFieldLabel = uilabel(app.OuputclassificationpathnamePanel);
            app.EnternewclassifiernameEditFieldLabel.Text = 'Enter new classifier name:';
            app.EnternewclassifiernameEditField = uieditfield(app.OuputclassificationpathnamePanel, 'text');
            app.EnternewclassifiernameEditField.Value = 'my_classi';
            app.TransferparametersPanel = uipanel(app.ClassifierUIFigure);
            app.TransferparametersPanel.Tooltip = {'Specify transfer parameters in case a classifier is transferred to the new classifier'};
            app.TransferparametersPanel.Title = 'Transfer parameters';
            app.ChooseamonglocallyavailableclassifiersDropDownLabel = uilabel(app.TransferparametersPanel);
            app.ChooseamonglocallyavailableclassifiersDropDownLabel.Text = 'Choose among locally available classifiers:';
            app.ChooseamonglocallyavailableclassifiersDropDown = uidropdown(app.TransferparametersPanel);
            app.ChooseamonglocallyavailableclassifiersDropDown.Items = {'Classifier1', ''};
            app.ChooseamonglocallyavailableclassifiersDropDown.Editable = 'on';
            app.ChooseamonglocallyavailableclassifiersDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseamonglocallyavailableclassifiersDropDownValueChanged, true);
            app.ChooseamonglocallyavailableclassifiersDropDown.Value = 'Classifier1';
            app.TransfertrainingparametersCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransfertrainingparametersCheckBox.Text = 'Transfer training parameters';
            app.TransfertrainedclassifierfileCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransfertrainedclassifierfileCheckBox.Text = 'Transfer trained classifier file';
            app.TransferformattedgroundtruthimagesCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransferformattedgroundtruthimagesCheckBox.Text = 'Transfer formatted groundtruth images';
            app.TransferunformattedtrainingsetROIsCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransferunformattedtrainingsetROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransferunformattedtrainingsetROIsCheckBoxValueChanged, true);
            app.TransferunformattedtrainingsetROIsCheckBox.Text = 'Transfer unformatted trainingset (ROIs)';
            app.EnterROIindicesEditFieldLabel = uilabel(app.TransferparametersPanel);
            app.EnterROIindicesEditFieldLabel.Enable = 'off';
            app.EnterROIindicesEditFieldLabel.Text = 'Enter ROI indices:';
            app.EnterROIindicesEditField = uieditfield(app.TransferparametersPanel, 'text');
            app.EnterROIindicesEditField.Enable = 'off';
            app.PreserveannotationsCheckBox = uicheckbox(app.TransferparametersPanel);
            app.PreserveannotationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreserveannotationsCheckBoxValueChanged, true);
            app.PreserveannotationsCheckBox.Enable = 'off';
            app.PreserveannotationsCheckBox.Text = 'Preserve annotations';
            app.ClassesnamesinsourceEditFieldLabel = uilabel(app.TransferparametersPanel);
            app.ClassesnamesinsourceEditFieldLabel.Enable = 'off';
            app.ClassesnamesinsourceEditFieldLabel.Text = 'Classes names in source:';
            app.ClassesnamesinsourceEditField = uieditfield(app.TransferparametersPanel, 'text');
            app.ClassesnamesinsourceEditField.Editable = 'off';
            app.ClassesnamesinsourceEditField.Enable = 'off';
            app.ClassesnamesindestinationEditFieldLabel = uilabel(app.TransferparametersPanel);
            app.ClassesnamesindestinationEditFieldLabel.Enable = 'off';
            app.ClassesnamesindestinationEditFieldLabel.Text = 'Classes names in destination:';
            app.ClassesnamesindestinationEditField = uieditfield(app.TransferparametersPanel, 'text');
            app.ClassesnamesindestinationEditField.ValueChangedFcn = createCallbackFcn(app, @ClassesnamesindestinationEditFieldValueChanged, true);
            app.ClassesnamesindestinationEditField.Enable = 'off';
            % Show the figure after all components are created
            app.ClassifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierImporterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifierUIFigure)
        end
    end
end