classdef detecdiv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DetecDivUIFigure             matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        NewprojectMenu               matlab.ui.container.Menu
        OpenprojectMenu              matlab.ui.container.Menu
        SaveselectedprojectMenu      matlab.ui.container.Menu
        Closeproject                 matlab.ui.container.Menu
        NewprojectindependentclassiiferMenu  matlab.ui.container.Menu
        OpenprojectindependentclassifierMenu  matlab.ui.container.Menu
        SaveprojectindependentclassifierMenu  matlab.ui.container.Menu
        CloseprojectindependentclassifierMenu  matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        ProjectsPanel                matlab.ui.container.Panel
        ExtractROIdatafromrawdataButton  matlab.ui.control.Button
        AutomatedROIdetectionButton  matlab.ui.control.Button
        CheckrawdatapathButton       matlab.ui.control.Button
        UpdaterawidatapathButton     matlab.ui.control.Button
        ProjectInformationLabel      matlab.ui.control.Label
        AddclassifierButton          matlab.ui.control.Button
        AddrawdataButton             matlab.ui.control.Button
        Tree                         matlab.ui.container.Tree
        ProjectsNode                 matlab.ui.container.TreeNode
        IndependentClassifiersNode   matlab.ui.container.TreeNode
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = public)

        function displayNodes(app)
            cc=1;
            %h1=[];
            %hproj=[];
            %hclassi=[];


            t=app.ProjectsNode.Children;
            t.delete;
            t=app.IndependentClassifiersNode.Children;
            t.delete;

            [pth fle ext]= fileparts(which('detecdiv.mlapp'));

            for i=1:numel(app.Data.Project)
                h1(i)=uitreenode(app.ProjectsNode,'Text',app.Data.Project{i},'Tag','Project','UserData',i,'Icon',fullfile(pth,'detecDiv_logo.png'));


                for k=1:numel(app.Data.Projectclassi{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open classifier...');
                    m.MenuSelectedFcn={@contextMenuClassiFcn,[i,k],'Projectclassi'};

                    uitreenode(h1(i),'Text',app.Data.Projectclassi{i}{k},'Tag','Projectclassi','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));
                end

                for k=1:numel(app.Data.Projectpos{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open position...');
                    m.MenuSelectedFcn={@contextMenuPositionFcn,[i,k],'Projectpos'};
                    uitreenode(h1(i),'Text',app.Data.Projectpos{i}{k},'Tag','Projectpos','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'data.png'));
                end
            end

            for i=1:numel(app.Data.Classifier)
                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Open classifier...');
                m.MenuSelectedFcn={@contextMenuClassiFcn,i,'Classifier'};
                uitreenode(app.IndependentClassifiersNode,'Text',app.Data.Classifier{i},'Tag','Classifier','UserData',i,'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));
            end

            expand(app.ProjectsNode);
            expand(app.IndependentClassifiersNode);

            function contextMenuClassiFcn(src,event,arg,str)

              %  str,arg
                cc=arg(1);
                if strcmp(str,'Classifier')
                     cc=arg(1);
                     clas=app.Data.Classifier{cc};
                     clas=evalin('base',clas);
                end
                if strcmp(str,'Projectclassi')
                proj=app.Data.Project{cc(1)};
                pos=arg(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
                end

                classifierGUI(clas)
                %    i,k
            end
            function contextMenuPositionFcn(src,event,arg,str)

                cc=arg(1);
                pos=arg(2);
                
                proj=app.Data.Project{cc(1)};
                shallowObj=evalin('base',proj);
                
                  d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                'Message','Loading raw data from source files; Please wait...');
                  d.Value=0.33;
            
                shallowObj.fov(pos).view
                
                d.Value=0.9;
                d.Message='Data loaded...';
                pause(1);
                close(d);
                
            end

        end


        function gatherVarsFromWorkspace(app)
            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            cc=0;
            cd=0;
            app.Data={};

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});


                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];
                    end

                    st.Projectclassi{cc}=tmpclassi;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')
                    
                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};
                   % aa= st.Classifier{cd}
                end

            end

          %  st
            app.Data=st;
        end


        function check = checkImagePath(app,proj)

            check=ones(1,numel(proj.fov));

            d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                'Message','Checking raw data path in the project...');
            d.Value=0.0;
            for i=1:numel(proj.fov)
                 d.Value=i./numel(proj.fov);
                if numel(proj.fov(i).srcpath{1})>0
                    im=readImage(proj.fov(i),1,1);

                    if numel(im)==0 & numel(proj.fov(1).srcpath{1})>0
                       check(i)=0;
                    end
                end
            end
            if any(check)==0
                d.Message='Some path need to be update; Please check your internet connection, or use the update check button!';
                pause(2);
            else
                d.Message='The paths are OK!';
                pause(1);
            end
       
            close(d);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gatherVarsFromWorkspace(app)
            displayNodes(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;

            app.AddrawdataButton.Visible='off';
            app.AddclassifierButton.Visible='off';
            app.CheckrawdatapathButton.Visible='off';
            app.UpdaterawidatapathButton.Visible='off'; 
            app.AutomatedROIdetectionButton.Visible='off';
            app.ExtractROIdatafromrawdataButton.Visible='off';
            
            % HERE 
            
            if numel(selectedNodes)==0
                 return
            end

            if numel(selectedNodes.Tag)==0 & strcmp(selectedNodes.Text,'Projects')
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end
            if numel(selectedNodes.Tag)==0 & strcmp(selectedNodes.Text,'Independent Classifiers')
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end

            if strcmp(selectedNodes.Tag,'Project')

                app.ProjectsPanel.Title='Project';
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};
                shallowObj=evalin('base',proj);

                t='';
                t=[t 'Project path: ' newline newline];
                t=[t shallowObj.io.path shallowObj.io.file '.mat' newline newline];
                t=[t 'Number of positions: ' num2str(numel(shallowObj.fov)) newline newline];

                t=[t 'Number of classifiers in project: ' num2str(numel(shallowObj.processing.classification)) newline newline];
                app.ProjectInformationLabel.Text=t;

                app.AddrawdataButton.Visible='on';
                app.AddclassifierButton.Visible='on';
                app.CheckrawdatapathButton.Visible='on';
                app.UpdaterawidatapathButton.Visible='on'; 
                app.AutomatedROIdetectionButton.Visible='on';
                app.ExtractROIdatafromrawdataButton.Visible='on';
            end

            if strcmp(selectedNodes.Tag,'Projectpos')
                app.ProjectsPanel.Title='Position';
                cc=app.Tree.SelectedNodes.UserData;

                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                position=shallowObj.fov(pos);

                t='';
                t=[t 'Source files path: ' newline newline];

                for i=1%:numel(position.srcpath)
                    t=[t position.srcpath{i} newline newline];
                end

                t=[t 'Source sample filename: ' newline newline];
                t=[t position.srclist{1}(1).name newline newline];

                t=[t num2str(numel(position.channel)) ' channels: '];

                for i=1:numel(position.channel)
                    t=[t position.channel{i} ' '];
                end

                t=[t newline newline];
                if numel(position.frames)
                    fr=position.frames;
                else
                    fr=numel(position.srclist{1});
                end
                t=[t num2str(fr) ' frames' newline newline];
                
                 n=numel(position.roi);
                if n==1 & numel(position.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as available datasets' newline newline];
                
                if n==0
                     t=[t 'You must first define one or several ROIs by opening the position GUI!' newline newline];
                end
                app.ProjectInformationLabel.Text=t;

            end
            if strcmp(selectedNodes.Tag,'Projectclassi')
                app.ProjectsPanel.Title='Classifier';
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);

                t='';
                t=[t 'Classification path: ' newline newline];
                t=[t clas.path newline newline];
               if numel(clas.description)
                t=[t 'Description: '];
 
                t=[t clas.description{1} ' - '];
                end
                
                if numel(clas.category)
                t=[t clas.category{1} newline];
                end
                
                if numel(clas.classes)
                t=[t 'Classes: ' newline newline];

                for i=1:numel(clas.classes)
                    t=[t clas.classes{i} newline];
                end
                end
                
                t=[t  newline];
                
                n=numel(clas.roi);
                if n==1 & numel(clas.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as training/test sets' newline newline];
                
                 if n==0
                     t=[t 'You must first import ROIs !' newline newline];
                end
                
                app.ProjectInformationLabel.Text=t;
            end
            if strcmp(selectedNodes.Tag,'Classifier')
                app.ProjectsPanel.Title='Classifier';
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{cc};
                
                clas=evalin('base',proj);
               

                t='';
                t=[t 'Classification path: ' newline newline];
                t=[t clas.path newline newline];
                
                if numel(clas.description)
                t=[t 'Description: '];
 
                t=[t clas.description{1} ' - '];
                end
                
                if numel(clas.category)
                t=[t clas.category{1} newline newline];
                end
                
                if numel(clas.classes)
                t=[t 'Classes: ' newline newline];

                for i=1:numel(clas.classes)
                    t=[t clas.classes{i} newline];
                end
                end
                 t=[t newline];
                  n=numel(clas.roi);
                if n==1 & numel(clas.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as training/test sets' newline newline];
                
                 if n==0
                     t=[t 'You must first import ROIs !' newline newline];
                end
                  
                app.ProjectInformationLabel.Text=t;
            end
   
        end

        % Callback function
        function ContextMenuOpening(app, event)

        end

        % Callback function
        function OpenMenuSelected(app, event)

        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            proj=shallowNew;
            if numel(proj)==0
                return;
            end
            name=proj.io.file;
            assignin('base',name,proj);
            gatherVarsFromWorkspace(app);
            displayNodes(app)
        end

        % Callback function
        function DetecDivUIFigureWindowButtonDown(app, event)

        end

        % Callback function
        function DetecDivUIFigureButtonDown(app, event)

        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)
             d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Loading selected project...');
            d.Value=0.33;
                
            [proj msg]=shallowLoad;
            if numel(proj)==0
                if numel(msg)
                    uialert(app.DetecDivUIFigure,msg,'Warning','Icon','warning');
                end
                return;
            end
            
            d.Value=0.66;
            pause(0.2);
            name=proj.io.file;
            assignin('base',name,proj);
            gatherVarsFromWorkspace(app);
            displayNodes(app)
            check = checkImagePath(app,proj);
            
            if any(check)==0
                msg=['Those positions have an incorrect base path. ' newline 'You must update the path if you plan to build new region of interest / process the raw data !'];
                uialert(app.DetecDivUIFigure,msg,'Warning','Icon','warning');
            end


        end

        % Menu selected function: Closeproject
        function CloseprojectMenuSelected(app, event)

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                evalin('base',['clear ' proj]);
                gatherVarsFromWorkspace(app);
                displayNodes(app)
            end
        end

        % Menu selected function: SaveselectedprojectMenu
        function SaveselectedprojectMenuSelected(app, event)
            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                shallowObj=evalin('base',proj);

                if numel(shallowObj)==0
                    uialert(app.DetecDivUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                end

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                shallowSave(shallowObj,[],d);
                close(d);
            else
               uialert(app.DetecDivUIFigure,'No project was selected in the window!','Error','Icon','warning'); 
            end

        end

        % Button pushed function: AddclassifierButton
        function AddclassifierButtonPushed(app, event)
            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                shallowObj=evalin('base',proj);
            
            classifierImporterGUI(app,shallowObj)
            uiwait(app.DetecDivUIFigure)
            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);
            end
        end

        % Button pushed function: AddrawdataButton
        function AddrawdataButtonPushed(app, event)
            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            addDataGUI(shallowObj,app);
            
            uiwait(app.DetecDivUIFigure);

            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);

            % app.Tree.SelectedNodes=store;

        end

        % Button pushed function: UpdaterawidatapathButton
        function UpdaterawidatapathButtonPushed(app, event)
             i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            shallowObj.setSrcPath;
            check = checkImagePath(app,shallowObj);
            
        end

        % Button pushed function: CheckrawdatapathButton
        function CheckrawdatapathButtonPushed(app, event)
            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            check = checkImagePath(app,shallowObj);
        end

        % Menu selected function: 
        % NewprojectindependentclassiiferMenu
        function NewprojectindependentclassiiferMenuSelected(app, event)
            %classifierGUI([],app)
            classifierImporterGUI(app,[])
            uiwait(app.DetecDivUIFigure)
            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: 
        % OpenprojectindependentclassifierMenu
        function OpenprojectindependentclassifierMenuSelected(app, event)
    
             d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Loading selected classifier...');
            d.Value=0.33;
                
            [classiObj msg]=classiLoad;
            
            if numel(classiObj)==0
                if numel(msg)
                    uialert(app.DetecDivUIFigure,msg,'Warning','Icon','warning');
                end
                return;
            end
            
            d.Value=0.66;
            pause(0.2);
            
            name=classiObj.strid;
            assignin('base',name,classiObj);
            gatherVarsFromWorkspace(app);
            displayNodes(app)
            close(d)
        end

        % Menu selected function: 
        % CloseprojectindependentclassifierMenu
        function CloseprojectindependentclassifierMenuSelected(app, event)
            if strcmp(app.Tree.SelectedNodes.Tag,'Classifier')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{i};

                evalin('base',['clear ' proj]);
                gatherVarsFromWorkspace(app);
                displayNodes(app)
            end
        end

        % Menu selected function: 
        % SaveprojectindependentclassifierMenu
        function SaveprojectindependentclassifierMenuSelected(app, event)
             if strcmp(app.Tree.SelectedNodes.Tag,'Classifier')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{i};

                classiObj=evalin('base',proj);

                if numel(classiObj)==0
                    uialert(app.DetecDivUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                    return;
                end

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                classiSave(classiObj);
                d.Value=0.9;
                close(d);
            end

        end

        % Button pushed function: AutomatedROIdetectionButton
        function AutomatedROIdetectionButtonPushed(app, event)
             i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            ROIextracterGUI(shallowObj);
        end

        % Button pushed function: ExtractROIdatafromrawdataButton
        function ExtractROIdatafromrawdataButtonPushed(app, event)
            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
           
            if numel(shallowObj.fov)==1 & numel(shallowObj.fov(1).srcpath)==0
                uialert(app.DetecDivUIFigure,'There is not position avaialable for ROI extraction','Error');
                return;
            end
            
            if numel(shallowObj.fov(1).frames)==0
                nframes=numel(shallowObj.fov(1).srclist{1});
            else
                nframes=shallowObj.fov(1).frames;
            end
            
            npos=numel(shallowObj.fov);
            
            if numel(shallowObj.fov(1).interval)==0
                inte=ones(1,numel(shallowObj.fov(1).channel));
            end
            
            prompt = {'Max frames to process:','Positions to process:','Max number of frames in memory','Interval between frames for each channel:'};
            dlgtitle = 'Input ROI extraction parameters';
            
            dims = [1 100];
            
            definput = {['1:' num2str(nframes)],['1:' num2str(npos)], '20', num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if numel(answer)==0
                return;
            end
            
          %  answer
            
           shallowObj.saveCroppedImages('frames',str2num(answer{1}),'fov',str2num(answer{2}),'cut',str2num(answer{3}), 'channelint', str2num(answer{4}));
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DetecDivUIFigure = uifigure('Visible', 'off');
            app.DetecDivUIFigure.Name = 'DetecDiv';
            app.DetecDivUIFigure.Icon = 'detecDiv_logo.png';
            app.FileMenu = uimenu(app.DetecDivUIFigure);
            app.FileMenu.Text = 'File';
            app.NewprojectMenu = uimenu(app.FileMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project...';
            app.OpenprojectMenu = uimenu(app.FileMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project...';
            app.SaveselectedprojectMenu = uimenu(app.FileMenu);
            app.SaveselectedprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveselectedprojectMenuSelected, true);
            app.SaveselectedprojectMenu.Text = 'Save selected project';
            app.Closeproject = uimenu(app.FileMenu);
            app.Closeproject.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectMenuSelected, true);
            app.Closeproject.Text = 'Close selected project';
            app.NewprojectindependentclassiiferMenu = uimenu(app.FileMenu);
            app.NewprojectindependentclassiiferMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectindependentclassiiferMenuSelected, true);
            app.NewprojectindependentclassiiferMenu.Separator = 'on';
            app.NewprojectindependentclassiiferMenu.Text = 'New project-independent classiifer...';
            app.OpenprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.OpenprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectindependentclassifierMenuSelected, true);
            app.OpenprojectindependentclassifierMenu.Text = 'Open project-independent classifier...';
            app.SaveprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.SaveprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectindependentclassifierMenuSelected, true);
            app.SaveprojectindependentclassifierMenu.Text = 'Save project-independent classifier';
            app.CloseprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.CloseprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectindependentclassifierMenuSelected, true);
            app.CloseprojectindependentclassifierMenu.Text = 'Close project-independent classifier';
            app.HelpMenu = uimenu(app.DetecDivUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Tree = uitree(app.DetecDivUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'Use left-click to scroll projects and classifiers; Use right-click to open positions and classifiers'};
            app.ProjectsNode = uitreenode(app.Tree);
            app.ProjectsNode.Text = 'Projects';
            app.IndependentClassifiersNode = uitreenode(app.Tree);
            app.IndependentClassifiersNode.Text = 'Independent Classifiers';
            app.ProjectsPanel = uipanel(app.DetecDivUIFigure);
            app.ProjectsPanel.Title = 'Projects';
            app.AddrawdataButton = uibutton(app.ProjectsPanel, 'push');
            app.AddrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @AddrawdataButtonPushed, true);
            app.AddrawdataButton.Visible = 'off';
            app.AddrawdataButton.Text = 'Add raw data...';
            app.AddclassifierButton = uibutton(app.ProjectsPanel, 'push');
            app.AddclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @AddclassifierButtonPushed, true);
            app.AddclassifierButton.Visible = 'off';
            app.AddclassifierButton.Text = 'Add classifier...';
            app.ProjectInformationLabel = uilabel(app.ProjectsPanel);
            app.ProjectInformationLabel.Text = 'Project Information';
            app.UpdaterawidatapathButton = uibutton(app.ProjectsPanel, 'push');
            app.UpdaterawidatapathButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterawidatapathButtonPushed, true);
            app.UpdaterawidatapathButton.Visible = 'off';
            app.UpdaterawidatapathButton.Text = 'Update raw idata path...';
            app.CheckrawdatapathButton = uibutton(app.ProjectsPanel, 'push');
            app.CheckrawdatapathButton.ButtonPushedFcn = createCallbackFcn(app, @CheckrawdatapathButtonPushed, true);
            app.CheckrawdatapathButton.Visible = 'off';
            app.CheckrawdatapathButton.Text = 'Check raw data path';
            app.AutomatedROIdetectionButton = uibutton(app.ProjectsPanel, 'push');
            app.AutomatedROIdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AutomatedROIdetectionButtonPushed, true);
            app.AutomatedROIdetectionButton.Visible = 'off';
            app.AutomatedROIdetectionButton.Tooltip = {'This allows you to use a pattern to identify multiple ROIs automatically. The pattern must be defined in one of the positions'};
            app.AutomatedROIdetectionButton.Text = 'Automated ROI detection...';
            app.ExtractROIdatafromrawdataButton = uibutton(app.ProjectsPanel, 'push');
            app.ExtractROIdatafromrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractROIdatafromrawdataButtonPushed, true);
            app.ExtractROIdatafromrawdataButton.Visible = 'off';
            app.ExtractROIdatafromrawdataButton.Tooltip = {'This allows you to use a pattern to identify multiple ROIs automatically. The pattern must be defined in one of the positions'};
            app.ExtractROIdatafromrawdataButton.Text = 'Extract ROI data from raw data';
            % Show the figure after all components are created
            app.DetecDivUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detecdiv

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DetecDivUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DetecDivUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DetecDivUIFigure)
        end
    end
end