classdef detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DivisioncountingandRLSanalysisUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoaddatagroupsMenu         matlab.ui.container.Menu
        SavedatagroupsasMenu       matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        SelectdatadivisionextractionparametersTab  matlab.ui.container.Tab
        DataselectionPanel         matlab.ui.container.Panel
        DivisionextractionparametersPanel  matlab.ui.container.Panel
        DatagroupsPanel            matlab.ui.container.Panel
        Lamp                       matlab.ui.control.Lamp
        SaveselectedgroupButton    matlab.ui.control.Button
        AssignselectedgroupparamtoallButton  matlab.ui.control.Button
        RenamegroupEditField       matlab.ui.control.EditField
        RenamegroupEditFieldLabel  matlab.ui.control.Label
        DeleteselectedgroupButton  matlab.ui.control.Button
        NewgroupButton             matlab.ui.control.Button
        DatagroupsListBox          matlab.ui.control.ListBox
        DivisioncountingTab        matlab.ui.container.Tab
        PlotDivExportButton        matlab.ui.control.Button
        UIDivTable                 matlab.ui.control.Table
        divParametersPanel         matlab.ui.container.Panel
        RLSAnalysisTab             matlab.ui.container.Tab
        PlotRLSExportButton        matlab.ui.control.Button
        UIRLSTable                 matlab.ui.control.Table
        RLSParametersPanel         matlab.ui.container.Panel
        Tab                        matlab.ui.container.Tab
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = private)
        function  gatherVarsFromWorkspace(app)

            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            cc=0;
            cd=0;
            app.Data.st=[];

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});

                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];
                    end

                    st.Projectclassi{cc}=tmpclassi;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')

                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};
                    % aa= st.Classifier{cd}
                end

            end

            %  st
            app.Data.st=st;
            %    app.Data.convert={};
        end

        function divparam= newDivPlot(app)
            tip={'Select to export plot to pdf',...
                'Select to export data to CSV file ',...
                'Select to export data to Matlab workspapce ',...
                'Set filename to be written in the current directory',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Set the bin size (minutes)',...
                'Set the max bin for the histogram (minutes)'};

            divparam=struct('ExportPlot',false,...
                'ExportData2CSV',false,...
                'ExportData2Workspace',false,...
                'Filename','myDivfile',...
                'FrameInterval',5,...
                'maxBirth',100,...
                'binSize',5,...
                'maxBin',200,...
                'tip',{tip});
            %       'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...

        end

        function param = newGroup(app)
            tip={'Select the classifier used to score divisions',...
                'Choose the type of division classification (bud or div) ',...
                'Perform postprocessing or not',...
                'Detect errors or not',...
                'Lifespan arrest threshold( frames)',...
                'Number of consecutive dead frames before death is defined as the end of the RLS',...
                'Number of consecutive clog frames before clog is defined as the end of the RLS',...
                'Number of frames before traps is declared empty',...
                '?',...
                'Frames array used for analysis (set -1 if all frames should be considered)'};
            varlist=app.Data.st;
            classilist={};
            cc=1;


            for i=1:numel(varlist.Projectclassi)
                for j=1:numel(varlist.Projectclassi{i})
                    classilist{cc}=varlist.Projectclassi{i}{j};
                    %  tmp=varlist.Projectclassi{i}{j}
                    cc=cc+1;
                end
            end

            for i=1:numel(varlist.Classifier)
                classilist{cc}=varlist.Classifier{i};
                cc=cc+1;
            end

            classilist{end+1}=classilist{1};

            param=struct('classifierName',{classilist},...
                'classiftype',{{'bud','div','bud'}},...
                'postProcessing',true,...
                'errorDetection',true,...
                'ArrestThreshold',175,...
                'DeathThreshold',3,...
                'ClogThreshold',1,...
                'EmptyThresholdDiscard',500,...
                'EmptyThresholdNext',100,...
                'Frames',[-1],...
                'tip',{tip});
            %   'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...
        end

        function divparam = newRLSPlot(app)
            tip={'Select to export plot to pdf',...
                'Select to export data to CSV file ',...
                'Select to export data to Matlab workspapce ',...
                'Set filename to be written in the current directory',...
                'Plot 95% confidence interval for survival',...
                'Plot Hazard rate',...
                'If plot hazard rate, which binning to use (higher binning decreases noise but reduces temporal resolution)',...
                'If plot hazard rate, stop displaying the curve when less than this number of cells are still alive (avoid interpretations based on very few cells). !!For the moment, only works if bootstrap is ticked!!',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Check to bootstrap data (error estimate)',...
                'Number of boostrap steps',...
                'Align trajectories from birth, SEP, or death'};

            divparam=struct('ExportPlot',false,...
                'ExportData2CSV',false,...
                'ExportData2Workspace',false,...
                'Filename','myRLSfile',...
                'ConfidenceInterval',true,...
                'HazardRate',false,...
                'Binning_HazardRate',4,...
                'MinNumberCell_HazardRate',10,...
                'FrameInterval',5,...
                'maxBirth',100,...
                'Bootstrap',false,...
                'Bootstrap_Steps',200,...
                'Align_Traj',{{'birth','SEP','death','birth'}},...
                'tip',{tip});
            %       'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...
        end
    end

    methods (Access = public)
        function display2(app,datapicker)

            d = uiprogressdlg(app.DivisioncountingandRLSanalysisUIFigure,'Title','Please Wait...',...
                'Message','Loading data group...');
            d.Value=0.33;

            groups = app.Data.groups;
            items= {groups.Name};

            if numel(app.DatagroupsListBox.Value)
                aa=app.DatagroupsListBox.Value;
                pix=find(matches(items,aa));

                if numel(pix)==0
                    pix=1;
                else
                    pix=pix(1);
                end
            else
                pix=1;
            end

            app.DatagroupsListBox.Items=items;
            app.DatagroupsListBox.Value=items{pix};

            %                    app.Data.selectedgroup=pix;
            %                    pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            param=app.Data.groups(pix).Param;

            %here
            tip=param.tip;
            param=rmfield(param,'tip');
            app.DivisionextractionparametersPanel.UserData=[];
            delete(app.DivisionextractionparametersPanel.Children);
            struct2GUI(param,[10 330],'Handle',app.DivisionextractionparametersPanel,'Tip',tip,'Flag',app.Lamp);
            app.DivisionextractionparametersPanel.UserData.tip=tip;
            app.RenamegroupEditField.Value=aa;

            if nargin==2 % creates datapicker
                if numel(app.Data.groups(pix).Data.nodeid)
                    nodes=app.Data.groups(pix).Data;
                    nodes=app.Data.groups(pix).Data.nodeid;
                else
                    nodes=[];
                end
                %  no=app.Data.groups(pix).Data.filepath{1}


                if isa('datatree','var')
                    if ishandle(datatree)
                        delete(datatree)
                    end
                end
                d.Value=0.5;
                d.Message='Refreshing data picker...';

                [datatree,~]=datapickerGUI2('Handle',app.DataselectionPanel,'Flag',app.Lamp,'Position',[12 12 240 350],'Input',nodes);
                app.Data.tree=datatree;
                %  tmp=app.Data.tree.UserData
                drawnow


            end
            d.Value=0.9;
            d.Message='Data loaded...';
            close(d)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data=[];

            gatherVarsFromWorkspace(app)

            param = newGroup(app);
            app.Data.groups=struct('Name','group01','Data',[],'Param',param);
            app.Data.groups.Data.nodeid=[];
            app.Data.groups.Data.roiobj=[];

            display2(app,'datapicker')
        end

        % Value changed function: DatagroupsListBox
        function DatagroupsListBoxValueChanged(app, event)
            tmp=app.DatagroupsListBox.Value;
            app.Data.selectedgroup = tmp;
            display2(app,'refresh')
        end

        % Value changed function: RenamegroupEditField
        function RenamegroupEditFieldValueChanged(app, event)
            value = app.RenamegroupEditField.Value;

            if numel(app.DatagroupsListBox.Value)
                pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                pix=pix(1);
                app.Data.groups(pix).Name=value;
                app.DatagroupsListBox.Items{pix}=value;
                app.DatagroupsListBox.Value=value;
            end
        end

        % Button pushed function: NewgroupButton
        function NewgroupButtonPushed(app, event)
            pix=numel(app.DatagroupsListBox.Items);

            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            % take param value from selected position
            % here
            if numel(sel)
                param= app.Data.groups(sel).Param;
            else
                param = newGroup(app);

            end

            app.DatagroupsListBox.Items=[app.DatagroupsListBox.Items {['group' num2str(pix+1)]}];
            app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{end};

            %  aa= size(app.Data.groups)

            app.Data.groups(pix+1)=app.Data.groups(pix);

            app.Data.groups(pix+1).Name=app.DatagroupsListBox.Value;
            app.Data.groups(pix+1).Param=param;
            display2(app)


        end

        % Button pushed function: DeleteselectedgroupButton
        function DeleteselectedgroupButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                app.DatagroupsListBox.Items=app.DatagroupsListBox.Items(li);
                app.Data.groups= app.Data.groups(li);
                app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{1};
            end
            display2(app)
        end

        % Button pushed function: SaveselectedgroupButton
        function SaveselectedgroupButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                app.Data.groups(sel).Param=app.DivisionextractionparametersPanel.UserData;

                if numel(app.Data.tree.UserData)
                    app.Data.groups(sel).Data.nodeid= app.Data.tree.UserData.nodeid;
                    app.Data.groups(sel).Data.roiobj= app.Data.tree.UserData.roiobj;
                    app.Data.groups(sel).Data.filepath= app.Data.tree.UserData.filepath;
                    app.Data.groups(sel).Data.type= app.Data.tree.UserData.type;
                end
                app.Lamp.Color=[0 1 0];
            end
        end

        % Button pushed function: 
        % AssignselectedgroupparamtoallButton
        function AssignselectedgroupparamtoallButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                if numel(li)
                    for i=li
                        app.Data.groups(i).Param=app.Data.groups(sel).Param;
                        app.Data.groups(i).Data=app.Data.groups(sel).Data;
                    end
                end
            else
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select a data group !','Warning');
            end

        end

        % Menu selected function: LoaddatagroupsMenu
        function LoaddatagroupsMenuSelected(app, event)

            [file,path] = uigetfile('*.mat','Select a shallow project',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                load(filename);
                app.Data.groups=groups;

                % check if projects are loaded ; if not , load them ...
                for i=1:numel(groups)
                    %  tmp=groups(i).Data
                    for j=1:numel(groups(i).Data.filepath)
                        if numel(groups(i).Data.filepath{j})
                            if strcmp(groups(i).Data.type{j},'shallow')
                                sha=shallowLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=sha.io.file;
                                    assignin('base',name,sha);
                                end

                            end
                            if strcmp(groups(i).Data.type{j},'classi')

                                sha=classiLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=[sha.strid '_indep'];
                                    assignin('base',name,sha);
                                end
                            end


                        end
                    end
                end

                display2(app,'refresh');
            end

        end

        % Menu selected function: SavedatagroupsasMenu
        function SavedatagroupsasMenuSelected(app, event)
            [file,path] = uiputfile('*.mat','Choose a data group filename',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                groups=app.Data.groups;
                save(filename, 'groups');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if strcmp(selectedTab.Title,'Division counting')

                if ~isfield(app.Data,'divparam')
                    app.Data.divparam=   newDivPlot(app);
                    param=app.Data.divparam;
                    tip=param.tip;
                    param=rmfield(param,'tip');
                    struct2GUI(param,[10 330],'Handle',app.divParametersPanel,'Tip',tip);

                    t=app.UIDivTable;
                    % t.ColumnName={'Select for training','Select for testing',' ROI index','ROI Id','is annotated','is validated'};
                    t.ColumnWidth={60, 'auto', 100, 'auto', 'auto' };
                    t.ColumnEditable=[true false true false false];
                    %    Data(i,:)={};

                end
                %divparam=

            end

            if strcmp(selectedTab.Title,'RLS Analysis')
                if ~isfield(app.Data,'rlsparam')
                    app.Data.rlsparam=   newRLSPlot(app);
                    param=app.Data.rlsparam;
                    tip=param.tip;
                    param=rmfield(param,'tip');
                    struct2GUI(param,[10 330],'Handle',app.RLSParametersPanel,'Tip',tip);

                    t=app.UIRLSTable;
                    % t.ColumnName={'Select for training','Select for testing',' ROI index','ROI Id','is annotated','is validated'};
                    t.ColumnWidth={60, 'auto', 100, 'auto', 'auto' };
                    t.ColumnEditable=[true false true false false];
                    %    Data(i,:)={};

                end
            end

            if ~strcmp(selectedTab.Title,'Select data & division extraction parameters')

                d = uiprogressdlg(app.DivisioncountingandRLSanalysisUIFigure,'Title','Please Wait...',...
                    'Message','Computing divisions & RLS...');
                d.Value=0.33;

                groups=app.Data.groups;

                rlstot=[];

                cc=1;

                t=app.UIDivTable;
                tdata=t.Data;


                for i=1:numel(groups)
                    d.Message=['Processing data group ' num2str(i) '/' num2str(numel(groups)) '...'];
                    data=groups(i).Data;
                    param=groups(i).Param ;
                    name=groups(i).Name;

                    d.Value=0.33+0.66*(i-1)./double(numel(groups));

                    if numel(data.roiobj)==0
                        close(d);
                        uialert(app.DivisioncountingandRLSanalysisUIFigure,'No ROIs were selected in the data selection tab ! Quitting...','Warning');
                        return;
                    end

                    measureRLS3(data.roiobj,param);

                    tdata{i,1}=true;
                    tdata{i,2}=name;
                    tdata{i,3}=false;
                    tdata{i,4}=[];
                    tdata{i,5}=[];
                end

                t.Data=tdata;
                app.UIRLSTable.Data=tdata;
                close(d)
            end


        end

        % Button pushed function: PlotDivExportButton
        function PlotDivExportButtonPushed(app, event)
            divParam=   app.divParametersPanel.UserData;

            arg={};
            if divParam.ExportPlot
                arg=[arg 'ExportPlot' ];
            end
            if divParam.ExportData2CSV
                arg=[arg 'ExportData' ];
            end

            arg=[arg 'FrameInterval', divParam.FrameInterval];
            arg=[arg 'maxBirth', divParam.maxBirth];
            arg=[arg 'binSize', divParam.binSize];
            arg=[arg 'maxBin', divParam.maxBin];
            

            tdata=app.UIDivTable.Data;
            groups=app.Data.groups;

            cc=1;
            rlstot=[];

            grcc=[];
            groups(1).div=[];

            for i=1:numel(groups)
                if tdata{i,1}==true % to plot

                    data=groups(i).Data;
                    param=groups(i).Param ;
                    name=groups(i).Name;

                    classifstrid=param.classifierName{end};
                    classif=evalin('base',classifstrid);

                    gtpred=tdata{i,3};

                    if gtpred==true
                        %                 if param.GroundtruthVSPredictions
                        param.comment={'Groundtruth','Prediction'};
                        param.GroundtruthVSPredictions=true;
                    else
                        param.comment={name};
                        param.GroundtruthVSPredictions=false;
                    end
                    %
                    rls=createRLSfile(classif,{data.roiobj},param);
                    
                    %
                    if gtpred==false
                        for j=1:numel(rls)
                            rls(j).condition=cc;
                        end
                        cc=cc+1;
                    end
                    %
                    groups(i).div=rls;
                    %
                    if gtpred
                        %    if tdata{i,1}==true
                        arg2=[arg 'GT'  'Filename' [divParam.Filename '_' name '_predvsgt'] 'figName' name];
                        divt=plotDivtimes(rls,arg2{:});
                        tdata{i,4}=numel(divt{1,1});
                        tdata{i,5}=median(divt{1,1});

                        if divParam.ExportData2Workspace
                            assignin('base',[ 'divisions_' name '_predvsgt'],divt)
                        end
                        %     end
                    else
                        %      if tdata{i,1}==true % plot
                        rlstot=[rlstot; rls];
                        grcc=[grcc; i];
                        %     end
                    end
                end
            end


            %  assignin('base','rls',rlstot)
            %
            %           %  plotDivtimes(rlstot)

            if numel(rlstot)
                arg=[arg 'figName' 'pooled groups' 'Filename' divParam.Filename];
                divt=plotDivtimes(rlstot,arg{:});

                if divParam.ExportData2Workspace
                    assignin('base',[ 'divisions_pooled'],divt)
                end
                % grcc
                for i=1:numel(grcc)
                    % aa=divt{grcc(i),1}
                    tdata{grcc(i),4}=numel(divt{i,1});
                    tdata{grcc(i),5}=median(divt{i,1});
                end
            end
            %
            %             d.Value=0.99;
            %             close(d)
            %             end

            app.UIDivTable.Data=tdata;
        end

        % Button pushed function: PlotRLSExportButton
        function PlotRLSExportButtonPushed(app, event)
            divParam=   app.RLSParametersPanel.UserData;

            arg={};
            if divParam.ExportPlot
                arg=[arg 'ExportPlot' ];
            end
            if divParam.ExportData2CSV
                arg=[arg 'ExportData' ];
            end

            if divParam.Bootstrap
                arg=[arg 'Bootstrap' ];
            end
            
            
            if divParam.ConfidenceInterval
                arg=[arg 'ConfidenceInterval' ];
            end
            
            if divParam.HazardRate
                arg=[arg 'HazardRate' ];
            end
            
            if divParam.Binning_HazardRate
                arg=[arg 'Binning_HazardRate' divParam.Binning_HazardRate];
            end
            
            if divParam.MinNumberCell_HazardRate
                arg=[arg 'MinNumberCell_HazardRate' divParam.MinNumberCell_HazardRate];
            end

            if divParam.Bootstrap_Steps
                arg=[arg 'Bootstrap_Steps' divParam.Bootstrap_Steps ];
            end

            arg=[arg 'FrameInterval', divParam.FrameInterval];

            arg=[arg 'maxBirth', divParam.maxBirth];

            tdata=app.UIRLSTable.Data;
            groups=app.Data.groups;

            cc=1;
            rlstot=[];

            grcc=[];
            groups(1).div=[];

            for i=1:numel(groups)
                if tdata{i,1}==true % to plot

                    data=groups(i).Data;
                    param=groups(i).Param ;
                    name=groups(i).Name;

                    classifstrid=param.classifierName{end};
                    classif=evalin('base',classifstrid);

                    gtpred=tdata{i,3};

                    if gtpred==true
                        %                 if param.GroundtruthVSPredictions
                        param.comment={'Groundtruth','Prediction'};
                        param.GroundtruthVSPredictions=true;
                    else
                        param.comment={name};
                        param.GroundtruthVSPredictions=false;
                    end
                    %

                    rls=createRLSfile(classif,{data.roiobj},param);

                    %
                    if gtpred==false
                        for j=1:numel(rls)
                            rls(j).condition=cc;
                        end
                        cc=cc+1;
                    end
                    %
                    groups(i).rls=rls;
                    %
                    if gtpred
                        %    if tdata{i,1}==true
                        arg2=[arg 'GT'  'Filename' [divParam.Filename '_' name '_predvsgt'] 'figName' name];
                        rlst=plotRLS(rls,arg2{:});
                        tdata{i,4}=numel(rlst{1,1});
                        tdata{i,5}=median(rlst{1,1});

                        if divParam.ExportData2Workspace
                            assignin('base',[ 'RLS_' name '_predvsgt'],rlst)
                        end
                        %     end
                    else
                        %      if tdata{i,1}==true % plot
                        rlstot=[rlstot; rls];
                        grcc=[grcc; i];
                        %     end
                    end
                end
            end

            if numel(rlstot)
                arg=[arg 'figName' 'pooled groups' 'Filename' divParam.Filename];
                rlst=plotRLS(rlstot,arg{:});

                if divParam.ExportData2Workspace
                    assignin('base',[ 'RLS_pooled'],rlst)
                end
                % grcc
                for i=1:numel(grcc)
                    % aa=divt{grcc(i),1}
                    tdata{grcc(i),4}=numel(rlst{i,1});
                    tdata{grcc(i),5}=median(rlst{i,1});
                end
            end

            app.UIRLSTable.Data=tdata;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DivisioncountingandRLSanalysisUIFigure = uifigure('Visible', 'off');
            app.DivisioncountingandRLSanalysisUIFigure.Name = 'Division counting and & RLS analysis';
            app.FileMenu = uimenu(app.DivisioncountingandRLSanalysisUIFigure);
            app.FileMenu.Text = 'File...';
            app.LoaddatagroupsMenu = uimenu(app.FileMenu);
            app.LoaddatagroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddatagroupsMenuSelected, true);
            app.LoaddatagroupsMenu.Text = 'Load data groups...';
            app.SavedatagroupsasMenu = uimenu(app.FileMenu);
            app.SavedatagroupsasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedatagroupsasMenuSelected, true);
            app.SavedatagroupsasMenu.Text = 'Save data groups as...';
            app.TabGroup = uitabgroup(app.DivisioncountingandRLSanalysisUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SelectdatadivisionextractionparametersTab = uitab(app.TabGroup);
            app.SelectdatadivisionextractionparametersTab.Title = 'Select data & division extraction parameters';
            app.DatagroupsPanel = uipanel(app.SelectdatadivisionextractionparametersTab);
            app.DatagroupsPanel.Title = 'Data groups';
            app.DatagroupsListBox = uilistbox(app.DatagroupsPanel);
            app.DatagroupsListBox.Items = {'group01', 'group02'};
            app.DatagroupsListBox.ValueChangedFcn = createCallbackFcn(app, @DatagroupsListBoxValueChanged, true);
            app.DatagroupsListBox.Value = 'group01';
            app.NewgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.NewgroupButton.ButtonPushedFcn = createCallbackFcn(app, @NewgroupButtonPushed, true);
            app.NewgroupButton.Text = 'New group';
            app.DeleteselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.DeleteselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedgroupButtonPushed, true);
            app.DeleteselectedgroupButton.Text = 'Delete selected group';
            app.RenamegroupEditFieldLabel = uilabel(app.DatagroupsPanel);
            app.RenamegroupEditFieldLabel.Text = 'Rename group:';
            app.RenamegroupEditField = uieditfield(app.DatagroupsPanel, 'text');
            app.RenamegroupEditField.ValueChangedFcn = createCallbackFcn(app, @RenamegroupEditFieldValueChanged, true);
            app.AssignselectedgroupparamtoallButton = uibutton(app.DatagroupsPanel, 'push');
            app.AssignselectedgroupparamtoallButton.ButtonPushedFcn = createCallbackFcn(app, @AssignselectedgroupparamtoallButtonPushed, true);
            app.AssignselectedgroupparamtoallButton.Text = 'Assign selected group param to all';
            app.SaveselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.SaveselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectedgroupButtonPushed, true);
            app.SaveselectedgroupButton.Text = 'Save selected group';
            app.Lamp = uilamp(app.DatagroupsPanel);
            app.DivisionextractionparametersPanel = uipanel(app.SelectdatadivisionextractionparametersTab);
            app.DivisionextractionparametersPanel.Title = 'Division extraction parameters';
            app.DataselectionPanel = uipanel(app.SelectdatadivisionextractionparametersTab);
            app.DataselectionPanel.Title = 'Data selection';
            app.DivisioncountingTab = uitab(app.TabGroup);
            app.DivisioncountingTab.Title = 'Division counting';
            app.divParametersPanel = uipanel(app.DivisioncountingTab);
            app.divParametersPanel.Title = 'Plotting parameters';
            app.UIDivTable = uitable(app.DivisioncountingTab);
            app.UIDivTable.ColumnName = {'Plot'; 'Group name'; 'PredVsGT'; '# divisions'; 'Mean div time'};
            app.UIDivTable.RowName = {};
            app.PlotDivExportButton = uibutton(app.DivisioncountingTab, 'push');
            app.PlotDivExportButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDivExportButtonPushed, true);
            app.PlotDivExportButton.Text = 'Plot/Export';
            app.RLSAnalysisTab = uitab(app.TabGroup);
            app.RLSAnalysisTab.Title = 'RLS Analysis';
            app.RLSParametersPanel = uipanel(app.RLSAnalysisTab);
            app.RLSParametersPanel.Title = 'Plotting parameters';
            app.UIRLSTable = uitable(app.RLSAnalysisTab);
            app.UIRLSTable.ColumnName = {'Plot'; 'Group name'; 'PredVsGT'; '# lifespans'; 'Median RLS'};
            app.UIRLSTable.RowName = {};
            app.PlotRLSExportButton = uibutton(app.RLSAnalysisTab, 'push');
            app.PlotRLSExportButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRLSExportButtonPushed, true);
            app.PlotRLSExportButton.Text = 'Plot/Export';
            app.Tab = uitab(app.TabGroup);
            % Show the figure after all components are created
            app.DivisioncountingandRLSanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DivisioncountingandRLSanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DivisioncountingandRLSanalysisUIFigure)
        end
    end
end