classdef exportdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExportfiletypeDropDown        matlab.ui.control.DropDown
        ExportfiletypeDropDownLabel   matlab.ui.control.Label
        FileButton                    matlab.ui.control.Button
        ExportfilepathEditField       matlab.ui.control.EditField
        ExportfilepathEditFieldLabel  matlab.ui.control.Label
        CheckdatatoexportPanel        matlab.ui.container.Panel
        RefreshButton                 matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        ChooseProjectPositionROIstoexportPanel  matlab.ui.container.Panel
    end


    properties (Access = private)
        Data=[]; % Description
    end

    methods (Access = private)


        function displayFields(app)
            Tree = uitree(app.CheckdatatoexportPanel,'checkbox');
            Tree.CheckedNodesChangedFcn = {@checkChanged};
            Tree.Tooltip = {'Select data from projects loaded in memory'};
            Tree.Position = [10 10 550 340];
            app.Data.datatree=Tree;


            TrainNode = uitreenode(Tree);
            TrainNode.Text = 'Training';
            TrainNode.Tag='Training';

            ResultsNode = uitreenode(Tree);
            ResultsNode.Text = 'Results';
            ResultsNode.Tag='Results';


            %expand(IndependentClassifiersNode);

            %tmp= app.Data.tree.UserData.nodeid

            tmp= app.Data.tree.UserData.roiobj;
            app.Data.rois=tmp;
            %     tmp= app.Data.tree.UserData.filepath;
            %     tmp=app.Data.tree.UserData.type
            fields=gatherROIsfields(app,tmp);

            for i=1:numel(fields.Results{1})
                udata='ok';
                h1(i)=uitreenode(ResultsNode,'Text',fields.Results{1}{i},'Tag','Results','UserData',[]);
               
                 for j=1:numel(fields.Results{2}{1})
                     h2(i,j)=uitreenode(h1(i),'Text',fields.Results{2}{i}{j},'Tag','Results','UserData',['results.' fields.Results{1}{i} '.' fields.Results{2}{i}{j}]);
                 end
            end

            expand(ResultsNode);

            function checkChanged(src, event)
                tmp=app.Data.datatree.CheckedNodes(1).UserData
                %aa=event.CheckedNodes(2)
            end


        end

        function fields=gatherROIsfields(app,rois)

            fields=[];
            fields.Training={[] []};
            fields.Results={[] []};

            if numel(rois)==0
                return
            end

            tmpt=[];
            for i=1:numel(rois)
                if numel(rois(i).train)
                    tmpt=fieldnames(rois(i).train);
                end
            end

            tmpr={};
            cc=1;
            for i=1:numel(rois)
                if numel(rois(i).results)
                    tmp=fieldnames(rois(i).results);

                    tmpr=[tmpr tmp];
                    cc=cc+1;
                end
            end
            tmpr=unique(tmpr);

            subt={};

            for i=1:numel(tmpr)

                tt={};
                for j=1:numel(rois)

                    tmpr2=fieldnames(rois(i).results);
                    pix=matches(tmpr2,tmpr{i});
                    subtmpr=fieldnames(rois(i).results.(tmpr2{pix}));
                  

                    %   for k=1:numel(subtmpr)
                    tt=[tt subtmpr ];
                    %   end


                end

                subt{i}=unique(tt);
            end

            fields.Results={tmpr subt};
        end
    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [datatree,~]=datapickerGUI2('Handle',app.ChooseProjectPositionROIstoexportPanel,'Position',[10 10 280 550], 'Flag',app.Lamp);
            app.Data.tree=datatree;
            displayFields(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)


            displayFields(app)
            app.Lamp.Color=[0 1 0];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseProjectPositionROIstoexportPanel = uipanel(app.UIFigure);
            app.ChooseProjectPositionROIstoexportPanel.Title = 'Choose Project/Position/ROIs to export';
            app.Lamp = uilamp(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh ';
            app.CheckdatatoexportPanel = uipanel(app.UIFigure);
            app.CheckdatatoexportPanel.Title = 'Check data to export';
            app.ExportfilepathEditFieldLabel = uilabel(app.UIFigure);
            app.ExportfilepathEditFieldLabel.Text = 'Export file path:';
            app.ExportfilepathEditField = uieditfield(app.UIFigure, 'text');
            app.FileButton = uibutton(app.UIFigure, 'push');
            app.FileButton.Text = 'File...';
            app.ExportfiletypeDropDownLabel = uilabel(app.UIFigure);
            app.ExportfiletypeDropDownLabel.Text = 'Export file type';
            app.ExportfiletypeDropDown = uidropdown(app.UIFigure);
            app.ExportfiletypeDropDown.Items = {'XLS'};
            app.ExportfiletypeDropDown.Value = 'XLS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exportdata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end