classdef lifespan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LifespanizerUIFigure       matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewprojectMenu             matlab.ui.container.Menu
        OpenprojectMenu            matlab.ui.container.Menu
        SaveprojectMenu            matlab.ui.container.Menu
        CloseprojectMenu           matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        LoaddataTab                matlab.ui.container.Tab
        LoadAdddataButton          matlab.ui.control.Button
        PreprocessingTab           matlab.ui.container.Tab
        CombinemultiplezstackintoasinglechannelLabel  matlab.ui.control.Label
        UIChannelTable             matlab.ui.control.Table
        RunpreprocessingButton     matlab.ui.control.Button
        ClassifyTab                matlab.ui.container.Tab
        SelectRunclassifierButton  matlab.ui.control.Button
        ExportTab                  matlab.ui.container.Tab
        ExportPlotdataButton       matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        UIPositionTable            matlab.ui.control.Table
    end


    properties (Access = public)
        Data % Description
        Settings
        shallowObj % Description
    end

    methods (Access = public)

        function displayPositions(app)
            % Vérifier que l'objet shallow existe et contient des informations sur les FOV
            if isempty(app.shallowObj) || ~isprop(app.shallowObj, 'fov') || isempty(app.shallowObj.fov)
                uialert(app.LifespanizerUIFigure, 'No field-of-view data available in the project.', 'Error');
                return;
            end

            fovs = app.shallowObj.fov;
            nFOV = numel(fovs);
            % Préparer une cellule pour la table, avec 8 colonnes (selon la configuration de UIPositionTable)
            % Colonnes : {check, id, name, comment, #ROIs, extracted, classified, pp}
            tableData = cell(nFOV, 8);

            for i = 1:nFOV
                % Colonne 1 : Check (par défaut true)
                tableData{i, 1} = true;

                % Colonne 2 : id (si le champ 'id' existe, sinon utiliser l'indice)

                tableData{i, 2} = i;


                % Colonne 3 : name (si le champ 'name' existe, sinon 'FOV i')
                if isprop(fovs(i), 'id')
                    tableData{i, 3} = fovs(i).id;
                else
                    tableData{i, 3} = 'noname';
                end

                % Colonne 4 : comment (s'il existe, sinon vide)
                if isprop(fovs(i), 'comment')
                    tableData{i, 4} = fovs(i).comment;
                else
                    tableData{i, 4} = '';
                end

                extract=false;
                % Colonne 5 : #ROIs
                % On teste si le champ 'roi' existe et n'est pas vide
                if isprop(fovs(i), 'roi') && ~isempty(fovs(i).roi) && numel(fovs(i).roi(1).id)
                    % Calculer le nombre de ROI. Si roi est un array ou une structure, utiliser numel.
                    roiCount = numel(fovs(i).roi);
                    tableData{i, 5} = num2str(roiCount);

                    fovs(i).roi(1).load;
                    if numel(fovs(i).roi(1).image)~=0
                        extract=true;;
                    end

                else
                    tableData{i, 5} = '0';
                end

                % Colonne 6 : extracted (booléen indiquant si les ROI ont été extraites)

                tableData{i, 6} = extract;


                % Colonne 7 : classified (par défaut false)
                tableData{i, 7} = false;

                % Colonne 8 : pp (peut être vide)
                tableData{i, 8} = false;
                tableData{i, 9} = false;

                tableData{i, 10} = 'Open';
            end

            % Affecter le tableau à la table UIPositionTable de l'app
            app.UIPositionTable.Data = tableData;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAdddataButton
        function LoadAdddataButtonPushed(app, event)
            % Check if the 'projname' field exists in app.Settings

            if isfield(app.Settings, 'projname') && ~isempty(app.Settings.projname)
                projName = app.Settings.projname;
                % Check if the variable exists in the base workspace
                if evalin('base', sprintf('exist(''%s'', ''var'')', projName))
                    shallowObj = evalin('base', projName);
                    loadData(shallowObj);
                else
                    uialert(app.LifespanizerUIFigure, ...
                        'The specified project does not exist in the workspace.', ...
                        'Error');
                end
            else
                uialert(app.LifespanizerUIFigure, ...
                    'No open project is defined in app.Settings.', ...
                    'Error');
            end
        end

        % Button pushed function: RunpreprocessingButton
        function RunpreprocessingButtonPushed(app, event)
      %% Vérifier que les positions sélectionnées ont leurs ROI extraites
    tableData = app.UIPositionTable.Data;
    if isempty(tableData)
        uialert(app.LifespanizerUIFigure, 'No positions available.', 'Error');
        return;
    end

    nRows = size(tableData, 1);
    for r = 1:nRows
        % Vérifier pour chaque ligne cochée (colonne 1)
        if isequal(tableData{r, 1}, true)
            % Vérifier que la colonne "extracted" (colonne 6) est true
            if ~isequal(tableData{r, 6}, true)
                uialert(app.LifespanizerUIFigure, ...
                    sprintf('ROI not extracted for position %d. Please extract ROI first.', r), ...
                    'Error');
                return;
            end
        end
    end

    uialert(app.LifespanizerUIFigure, ...
        'All selected positions have extracted ROIs. Preprocessing can now proceed.', ...
        'Info');

    %% Préparation du processing
    processorName = 'Combine';  % Nom du processor à utiliser
    
    % Récupérer l'objet shallow depuis l'espace de travail à partir du nom de projet
    projName = app.Settings.projname;
    if evalin('base', sprintf('exist(''%s'', ''var'')', projName))
        shallowObj = evalin('base', projName);
    else
        uialert(app.LifespanizerUIFigure, 'Project not found in workspace.', 'Error');
        return;
    end

    % Ajouter le processor avec le nom défini (remplacez filename par processorName)
    shallowObj.addProcessor('name', processorName);
    processObj = shallowObj.processing.processor(end);
    
    % Charger la liste des processors
    procListFile = fullfile(fileparts(which('shallowNew.m')), 'processor', 'processlist.mat');
    if exist(procListFile, 'file')
        load(procListFile, 'processlist'); %#ok<LOAD>
    else
        uialert(app.LifespanizerUIFigure, 'Processor list not found.', 'Error');
        return;
    end

    % Choisir la fonction de processing souhaitée dans la processlist (ici indice 7,5)
    processObj.processFun = processlist{7,5}{1};
    processObj.processArg = {};

    % Initialiser les arguments du processor en appelant la fonction de processing sans input
    % Ici, j'utilise processObj.processFun directement au lieu de classiObj.processFun
    param = feval(processObj.processFun); 
    processObj.processArg = param;
    
    % Sauvegarder la configuration du processor
    processSave(processObj);

    %% Récupérer les positions sélectionnées dans la table
    data = app.UIPositionTable.Data;
    selpos = find(cellfun(@(x) x==1, data(:,1)));
    if isempty(selpos)
        uialert(app.LifespanizerUIFigure, 'No positions selected.', 'Error');
        return;
    end

    % Initialiser les variables pour frames et ROI
    frames = {};
    roiobj = [];
    cc = 1;

    % Pour chaque position sélectionnée, parcourir les FOV dans shallowObj
    for i = 1:numel(selpos)
        idx = selpos(i);
        % Pour chaque ROI dans le FOV correspondant (on suppose que shallowObj.fov(idx).roi est un array)
        for j = 1:numel(shallowObj.fov(idx).roi)
            % Si vous voulez forcer à traiter toutes les frames, mettre -1 sinon vous pouvez adapter
            frames{cc} = -1;  %#ok<AGROW>
            % Concaténer le ROI courant dans roiobj
            roiobj = [roiobj, shallowObj.fov(idx).roi(j)];  %#ok<AGROW>
            cc = cc + 1;
        end
    end

    %% Lancer le processing
    d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
        'Message', 'Starting data classification...');
    d.Value = 0.01;
    drawnow;

    % Construire les arguments pour le processing
    arg = {'Progress', d, 'Frames', frames};

    tic
    processData(processObj, roiobj, arg{:});
    toc

    % Vous pouvez fermer la progress dialog ou la mettre à jour après processing
    d.Value = 1;
    pause(0.2);
    close(d);

        end

        % Button pushed function: SelectRunclassifierButton
        function SelectRunclassifierButtonPushed(app, event)

        end

        % Button pushed function: ExportPlotdataButton
        function ExportPlotdataButtonPushed(app, event)

        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            proj=shallowNew;
            if numel(proj)==0
                return;
            end
            name=proj.io.file;
            assignin('base',name,proj);
            app.Settings.projname=name;


            app.shallowObj = proj;  % proj est l'objet shallow chargé
            displayPositions(app);

            app.Label.Text= fullfile( proj.io.path, [proj.io.file, '.mat']);

        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)
            % Display a progress dialog
            d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
                'Message', 'Loading selected project...');
            d.Value = 0.33;

            [proj, msg] = shallowLoad;

            % Si le projet est vide, on examine le message
            if isempty(proj)
                if contains(msg, 'Project is already in the workspace under the var name:')
                    % Extraction du nom de variable à partir du message
                    tokens = regexp(msg, 'Project is already in the workspace under the var name:\s*(.*?)\s*; Quitting\.\.\.', 'tokens');
                    if ~isempty(tokens)
                        projName = tokens{1}{1};  % Extraction du premier élément
                        % Vérifier que la variable existe dans l'espace de travail de base
                        if evalin('base', sprintf('exist(''%s'', ''var'')', projName))
                            proj = evalin('base', projName);
                            app.Settings.projname = projName;
                        else
                            uialert(app.LifespanizerUIFigure, ...
                                'The project variable extracted from the message does not exist in the base workspace.', ...
                                'Error');
                            close(d);
                            return;
                        end
                    else
                        uialert(app.LifespanizerUIFigure, ...
                            'Unable to extract the variable name from the message.', 'Error');
                        close(d);
                        return;
                    end
                elseif contains(msg, 'Wrong file name loaded')
                    uialert(app.LifespanizerUIFigure, 'Wrong file name loaded.', 'Error');
                    close(d);
                    return;
                else
                    uialert(app.LifespanizerUIFigure, sprintf('Unexpected error: %s', msg), 'Error');
                    close(d);
                    return;
                end
            end

            % Vérifier que le projet chargé est bien un objet de la classe shallow
            if ~isa(proj, 'shallow')
                uialert(app.LifespanizerUIFigure, ...
                    'Loaded project is not a valid shallow object.', 'Error');
                close(d);
                return;
            end

            d.Value = 0.66;
            pause(0.2);

            % Affichage du projet dans la console (pour débogage)
            %disp(proj);

            % Récupérer le nom du projet depuis l'objet et l'assigner dans l'espace de travail et dans app.Settings
            name = proj.io.file;
            assignin('base', name, proj);
            app.Settings.projname = name;


            app.shallowObj = proj;  % proj est l'objet shallow chargé
            displayPositions(app);

            app.Label.Text= fullfile( proj.io.path, [proj.io.file, '.mat']);

            close(d);

        end

        % Menu selected function: SaveprojectMenu
        function SaveprojectMenuSelected(app, event)
            if isfield(app.Settings,'projname')
                proj=app.Settings.projname;

                shallowObj=evalin('base',proj);

                if numel(shallowObj)==0
                    uialert(app.LifespanizerUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                end

                d = uiprogressdlg(app.LifespanizerUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                shallowSave(shallowObj,[],d);
                close(d);
            else
                uialert(app.LifespanizerUIFigure,'There is no open project!','Warning','Icon','warning');
            end
        end

        % Menu selected function: CloseprojectMenu
        function CloseprojectMenuSelected(app, event)

        end

        % Cell edit callback: UIPositionTable
        function UIPositionTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Selection changed function: UIPositionTable
        function UIPositionTableSelectionChanged(app, event)
            selection = app.UIPositionTable.Selection;
            if isempty(selection)
                return;
            end
            % Extraction des indices [row, column] de la première sélection
            selectedRow = selection(1);
            selectedCol = selection(2);

            % Supposons que la colonne "open" est la 9ème colonne
            openColumnIndex = 10;

            if selectedCol == openColumnIndex
                % Si la colonne "open" est sélectionnée, lancer la visualisation en mode 'preview'
                if ~isempty(app.shallowObj)

                    loadData(app.shallowObj, selectedRow);
                else
                    uialert(app.LifespanizerUIFigure, 'No shallow project loaded.', 'Error');
                end
            else
                % Autrement, mettre à jour la table des channels pour la position sélectionnée
                %   updateChannelTable(app, selectedRow);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LifespanizerUIFigure = uifigure('Visible', 'off');
            app.LifespanizerUIFigure.Name = 'Lifespanizer';
            app.FileMenu = uimenu(app.LifespanizerUIFigure);
            app.FileMenu.Text = 'File';
            app.NewprojectMenu = uimenu(app.FileMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project...';
            app.OpenprojectMenu = uimenu(app.FileMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectMenuSelected, true);
            app.SaveprojectMenu.Text = 'Save project';
            app.CloseprojectMenu = uimenu(app.FileMenu);
            app.CloseprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectMenuSelected, true);
            app.CloseprojectMenu.Text = 'Close project';
            app.UIPositionTable = uitable(app.LifespanizerUIFigure);
            app.UIPositionTable.ColumnName = {'check'; 'id'; 'name'; 'comment'; '#ROIs'; 'extracted'; 'preP'; 'classified'; 'postP'; 'preview'};
            app.UIPositionTable.RowName = {};
            app.UIPositionTable.ColumnEditable = [true false false true false false false false false true];
            app.UIPositionTable.CellEditCallback = createCallbackFcn(app, @UIPositionTableCellEdit, true);
            app.UIPositionTable.SelectionChangedFcn = createCallbackFcn(app, @UIPositionTableSelectionChanged, true);
            app.Label = uilabel(app.LifespanizerUIFigure);
            app.TabGroup = uitabgroup(app.LifespanizerUIFigure);
            app.LoaddataTab = uitab(app.TabGroup);
            app.LoaddataTab.Title = 'Load data';
            app.LoadAdddataButton = uibutton(app.LoaddataTab, 'push');
            app.LoadAdddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAdddataButtonPushed, true);
            app.LoadAdddataButton.Text = 'Load/Add data';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre processing';
            app.RunpreprocessingButton = uibutton(app.PreprocessingTab, 'push');
            app.RunpreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunpreprocessingButtonPushed, true);
            app.RunpreprocessingButton.Text = 'Run pre-processing';
            app.UIChannelTable = uitable(app.PreprocessingTab);
            app.UIChannelTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIChannelTable.RowName = {};
            app.CombinemultiplezstackintoasinglechannelLabel = uilabel(app.PreprocessingTab);
            app.CombinemultiplezstackintoasinglechannelLabel.Text = 'Combine multiple z-stack into a single channel';
            app.ClassifyTab = uitab(app.TabGroup);
            app.ClassifyTab.Title = 'Classify';
            app.SelectRunclassifierButton = uibutton(app.ClassifyTab, 'push');
            app.SelectRunclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRunclassifierButtonPushed, true);
            app.SelectRunclassifierButton.Text = 'Select/Run classifier';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportPlotdataButton = uibutton(app.ExportTab, 'push');
            app.ExportPlotdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotdataButtonPushed, true);
            app.ExportPlotdataButton.Text = 'Export/Plot data';
            % Show the figure after all components are created
            app.LifespanizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lifespan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LifespanizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LifespanizerUIFigure)
        end
    end
end