classdef loadData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadingdataUIFigure            matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImagesTab                      matlab.ui.container.Tab
        applychangestoallpositionsCheckBox  matlab.ui.control.CheckBox
        AdvancedmodeCheckBox           matlab.ui.control.CheckBox
        FramesSlider                   matlab.ui.control.RangeSlider
        FramesSliderLabel              matlab.ui.control.Label
        FramesField                    matlab.ui.control.EditField
        UIChannelTable                 matlab.ui.control.Table
        UIPositionTable                matlab.ui.control.Table
        SetButton                      matlab.ui.control.Button
        ImageFolderpathEditField       matlab.ui.control.EditField
        ImageFolderpathEditFieldLabel  matlab.ui.control.Label
        ProjectTab                     matlab.ui.container.Tab
        SetProjectButton               matlab.ui.control.Button
        OutputprojectfileEditField     matlab.ui.control.EditField
        OutputprojectfileEditFieldLabel  matlab.ui.control.Label
        InvertframesandpositionsCheckBox  matlab.ui.control.CheckBox
        ROIsTab                        matlab.ui.container.Tab
        ROIsButtonGroup                matlab.ui.container.ButtonGroup
        DimensionsEditField            matlab.ui.control.EditField
        DimensionsEditFieldLabel       matlab.ui.control.Label
        FindallButton                  matlab.ui.control.Button
        CreatROIButton                 matlab.ui.control.Button
        ROIDivide                      matlab.ui.control.NumericEditField
        CustomROIsButton               matlab.ui.control.RadioButton
        DivideframeintoButton          matlab.ui.control.RadioButton
        FullframeROIsButton            matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
        CancelButton                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        parsedData % Description
    end

    methods (Access = public)

 
function updatePositionTable(app)
    % Met à jour la table des positions et le slider à partir de app.parsedData.
    
    positions = app.parsedData.positions;  % Array de structures
    nPos = numel(positions);
    posTableData = cell(nPos, 3);  % Colonnes: {Select, File Pos Name, User Name}
    
    for i = 1:nPos
        % Extraire le nom du dossier à partir du champ folder (pour File Pos Name)
        if isfield(positions(i), 'folder')
            folderValue = positions(i).folder;
            if ischar(folderValue) && ~strcmp(folderValue, '')
                folderPath = folderValue;
            elseif isstring(folderValue) && folderValue ~= ""
                folderPath = char(folderValue);
            else
                folderPath = '';
            end
        else
            folderPath = '';
        end

        if ~isempty(folderPath)
            % Retirer le séparateur final s'il existe
            if folderPath(end)==filesep || folderPath(end)=='/'
                folderPath = folderPath(1:end-1);
            end
            % Extraire le nom du dossier (après le dernier séparateur)
            idx = find(folderPath==filesep | folderPath=='/', 1, 'last');
            if isempty(idx)
                filePosName = folderPath;
            else
                filePosName = folderPath(idx+1:end);
            end
        else
            filePosName = sprintf('Pos%d', i-1);
        end

        % Utiliser le champ 'selected' et 'userName' déjà stocké dans la structure
        posTableData{i, 1} = positions(i).selected;
        posTableData{i, 2} = filePosName;  
        posTableData{i, 3} = positions(i).userName;
    end
    
    app.UIPositionTable.Data = posTableData;
    
    % Mise à jour par défaut du slider des frames pour la première position
    % (ou pour une position par défaut, ici la première)
    posData = positions(1);
    app.FramesSlider.Limits = [posData.minFrame, posData.maxFrame];
    if isfield(posData, 'currentMinFrame') && ~isempty(posData.currentMinFrame)
        currentMin = posData.currentMinFrame;
    else
        currentMin = posData.minFrame;
    end
    if isfield(posData, 'currentMaxFrame') && ~isempty(posData.currentMaxFrame)
        currentMax = posData.currentMaxFrame;
    else
        currentMax = posData.maxFrame;
    end
    app.FramesSlider.Value = [currentMin, currentMax];
    app.FramesField.Value = [num2str(currentMin) ' ' num2str(currentMax)];
    
    % Mettre à jour la table des channels pour la première position par défaut
    updateChannelTable(app, 1);
end


function updateChannelTable(app, posIndex)
    % Met à jour la table des channels et le slider pour la position spécifiée.
    % Si posIndex n'est pas fourni, la première position est utilisée.
    
    if nargin < 2 || isempty(posIndex)
        posIndex = 1;
    end
    
    positions = app.parsedData.positions;
    posData = positions(posIndex);
    numChannels = posData.numChannels;
    
    % Préparer la table des channels avec 5 colonnes :
    % {Select, File Chan. Name, User Name, Frequency, Size}
    chanTableData = cell(numChannels, 5);
    for i = 1:numChannels
        chanIndex = i - 1;  % Numérotation à partir de 0
        chanTableData{i, 1} = posData.channelsSelected(i);            % Utilise le champ channelsSelected
        chanTableData{i, 2} = num2str(chanIndex);                       % File Chan. Name
        chanTableData{i, 3} = posData.userChanName{i};                  % User Name (champ userChanName)
        chanTableData{i, 4} = num2str(posData.channelFrequencies(i));   % Frequency
        chanTableData{i, 5} = posData.channelSizes{i};                  % Size
    end
    app.UIChannelTable.Data = chanTableData;
    
    % Mise à jour du slider des frames pour la position sélectionnée.
    if isfield(posData, 'minFrame')
        minFrame = posData.minFrame;
    else
        minFrame = min(posData.frames);
    end
    if isfield(posData, 'maxFrame')
        maxFrame = posData.maxFrame;
    else
        maxFrame = max(posData.frames);
    end
    
    if isfield(posData, 'currentMinFrame') && ~isempty(posData.currentMinFrame)
        currentMin = posData.currentMinFrame;
    else
        currentMin = minFrame;
    end
    if isfield(posData, 'currentMaxFrame') && ~isempty(posData.currentMaxFrame)
        currentMax = posData.currentMaxFrame;
    else
        currentMax = maxFrame;
    end
    
    currentMin=round(currentMin);
    currentMax=round(currentMax);

    app.FramesSlider.Limits = [minFrame, maxFrame];
    app.FramesSlider.Value = [currentMin, currentMax];
    app.FramesField.Value = [num2str(currentMin) ' ' num2str(currentMax)];
end


    end


    methods (Access = private)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
   choice = questdlg('Voulez-vous vraiment annuler et fermer l''application ?', ...
                      'Annuler', 'Oui', 'Non', 'Non');
    if strcmp(choice, 'Oui')
        delete(app);  % Ferme l'application
    end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)

       changingValue = event.Value;  % Vecteur 1x2 [min max]
    minSliderPos = changingValue(1);
    maxSliderPos = changingValue(2);
    
    % Mise à jour du champ de texte
    app.FramesField.Value = [num2str(round(minSliderPos)) ' ' num2str(round(maxSliderPos))];
    
    % Déterminer quelles positions mettre à jour
    if app.applychangestoallpositionsCheckBox.Value
        posIndices = 1:numel(app.parsedData.positions);
    else
        if ~isempty(app.UIPositionTable.Selection)
            posIndices = app.UIPositionTable.Selection(1);
        else
            posIndices = 1;
        end
    end
    
    % Mise à jour des champs currentMinFrame/currentMaxFrame pour chaque position concernée
    for posIndex = posIndices
        app.parsedData.positions(posIndex).currentMinFrame = minSliderPos;
        app.parsedData.positions(posIndex).currentMaxFrame = maxSliderPos;
    end
    
    % Actualiser l'affichage de la table des channels pour la position actuellement sélectionnée
    if ~isempty(app.UIPositionTable.Selection)
        currentPos = app.UIPositionTable.Selection(1);
    else
        currentPos = 1;
    end
    updateChannelTable(app, currentPos);

        end

        % Value changed function: FramesField
        function FramesFieldValueChanged(app, event)
        % Convertir la valeur du champ de texte en nombre
    value = str2num(app.FramesField.Value);  %#ok<ST2NM>
    lim = app.FramesSlider.Limits;
    newVal = [max(lim(1), value(1)) , min(lim(2), value(2))];
    
    % Mettre à jour le slider
    app.FramesSlider.Value = newVal;
    
    % Déterminer quelles positions mettre à jour
    if app.applychangestoallpositionsCheckBox.Value
        posIndices = 1:numel(app.parsedData.positions);
    else
        if ~isempty(app.UIPositionTable.Selection)
            posIndices = app.UIPositionTable.Selection(1);
        else
            posIndices = 1;
        end
    end
    
    % Mise à jour des champs currentMinFrame/currentMaxFrame pour chaque position concernée
    for posIndex = posIndices
        app.parsedData.positions(posIndex).currentMinFrame = newVal(1);
        app.parsedData.positions(posIndex).currentMaxFrame = newVal(2);
    end
    
    % Actualiser l'affichage de la table des channels pour la position actuellement sélectionnée
    if ~isempty(app.UIPositionTable.Selection)
        currentPos = app.UIPositionTable.Selection(1);
    else
        currentPos = 1;
    end
    updateChannelTable(app, currentPos);
    
        end

        % Value changed function: AdvancedmodeCheckBox
        function AdvancedmodeCheckBoxValueChanged(app, event)
            value = app.AdvancedmodeCheckBox.Value;
                value = app.AdvancedmodeCheckBox.Value;
    % if value
    %     app.ProjectTab.Visible = 'on';
    %     app.ROIsTab.Visible = 'on';
    % else
    %     app.ProjectTab.Visible = 'off';
    %     app.ROIsTab.Visible = 'off';
    % end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % Demander à l'utilisateur s'il souhaite sélectionner un dossier ou des fichiers
            choice = questdlg('Select folders or files ?', ...
                'Sélect.', 'Folder', 'Files', 'Files');
            switch choice
                case 'Folder'
                    files={};
                    folder = uigetdir;
                    if folder ~= 0  % Vérifier que l'utilisateur n'a pas annulé
                        % Mettre à jour le champ de texte avec le chemin du dossier sélectionné
                        app.ImageFolderpathEditField.Value = folder;
                    end

                case 'Files'
                    [files, folder] = uigetfile({'*.*','All fils'}, ...
                        'Select files', 'MultiSelect', 'on');
                    if isequal(files, 0)
                        % L'utilisateur a annulé la sélection
                        return;
                    else
                        % Afficher uniquement le chemin du dossier du premier fichier
                        app.ImageFolderpathEditField.Value = folder;
                    end
            end

            parsedData = loadData_parse(files, folder);

            app.parsedData = parsedData;

            app.updatePositionTable;
        end

        % Selection changed function: UIPositionTable
        function UIPositionTableSelectionChanged(app, event)
          selection = app.UIPositionTable.Selection;
    % La sélection renvoie un tableau d'indices [row, column]
    if ~isempty(selection)
        posIndex = selection(1);  % Utiliser la première ligne sélectionnée comme indice
        updateChannelTable(app, posIndex);
    end

       assignin('base','parsedData', app.parsedData);

        end

        % Cell edit callback: UIPositionTable
        function UIPositionTableCellEdit(app, event)
            indices = event.Indices;  % [row, column]
    newData = event.NewData;
    row = indices(1);
    col = indices(2);
    
    % Mettre à jour la structure de la position correspondante selon la colonne éditée
    switch col
        case 1  % Colonne Select (checkbox)
            app.parsedData.positions(row).selected = newData;
        case 2  % Colonne File Pos Name
            app.parsedData.positions(row).filePosName = newData;
        case 3  % Colonne User Name
            app.parsedData.positions(row).userName = newData;
    end
            
        end

        % Cell edit callback: UIChannelTable
        function UIChannelTableCellEdit(app, event)
      indices = event.Indices;  % [row, column]
    newData = event.NewData;
    channelRow = indices(1);  % index du channel (ligne)
    col = indices(2);         % index de la colonne éditée

    % Vérifier si la checkbox "apply changes to all positions" est activée
    if app.applychangestoallpositionsCheckBox.Value
        posIndices = 1:numel(app.parsedData.positions);
    else
        if ~isempty(app.UIPositionTable.Selection)
            posIndices = app.UIPositionTable.Selection(1);
        else
            posIndices = 1;
        end
    end

    for posIndex = posIndices
        posData = app.parsedData.positions(posIndex);
        switch col
            case 1  % Colonne "Select"
                posData.channelsSelected(channelRow) = newData;
            case 3  % Colonne "User Name"
                % Initialiser userChanName s'il n'existe pas ou est trop court
                if ~isfield(posData, 'userChanName') || numel(posData.userChanName) < posData.numChannels
                    posData.userChanName = repmat({['Channel ' num2str(channelRow-1)]}, 1, posData.numChannels);
                end
                posData.userChanName{channelRow} = newData;
            % On ne traite pas les autres colonnes
        end
        % Mise à jour incrémentale dans la structure globale
        app.parsedData.positions(posIndex) = posData;
    end

    % Mettre à jour l'affichage de la table des channels pour la position actuellement sélectionnée
    if ~isempty(app.UIPositionTable.Selection)
        currentPos = app.UIPositionTable.Selection(1);
    else
        currentPos = 1;
    end
    updateChannelTable(app, currentPos);

    assignin('base','parsedData', app.parsedData);
            
        end

        % Value changed function: applychangestoallpositionsCheckBox
        function applychangestoallpositionsCheckBoxValueChanged(app, event)
            value = app.applychangestoallpositionsCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadingdataUIFigure = uifigure('Visible', 'off');
            app.LoadingdataUIFigure.Name = 'Loading data....';
            app.LoadButton = uibutton(app.LoadingdataUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CancelButton = uibutton(app.LoadingdataUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TabGroup = uitabgroup(app.LoadingdataUIFigure);
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Simple';
            app.ImageFolderpathEditFieldLabel = uilabel(app.ImagesTab);
            app.ImageFolderpathEditFieldLabel.Text = 'Image/Folder path:';
            app.ImageFolderpathEditField = uieditfield(app.ImagesTab, 'text');
            app.SetButton = uibutton(app.ImagesTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set...';
            app.UIPositionTable = uitable(app.ImagesTab);
            app.UIPositionTable.ColumnName = {'Select'; 'File Pos Name'; 'User Name'};
            app.UIPositionTable.RowName = {};
            app.UIPositionTable.ColumnEditable = [true false true];
            app.UIPositionTable.CellEditCallback = createCallbackFcn(app, @UIPositionTableCellEdit, true);
            app.UIPositionTable.SelectionChangedFcn = createCallbackFcn(app, @UIPositionTableSelectionChanged, true);
            app.UIChannelTable = uitable(app.ImagesTab);
            app.UIChannelTable.ColumnName = {'Select'; 'File Chan. Name'; 'User Name'; 'Frequency'; 'Size'};
            app.UIChannelTable.RowName = {};
            app.UIChannelTable.ColumnEditable = [true false true];
            app.UIChannelTable.CellEditCallback = createCallbackFcn(app, @UIChannelTableCellEdit, true);
            app.FramesField = uieditfield(app.ImagesTab, 'text');
            app.FramesField.ValueChangedFcn = createCallbackFcn(app, @FramesFieldValueChanged, true);
            app.FramesSliderLabel = uilabel(app.ImagesTab);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.ImagesTab, 'range');
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.AdvancedmodeCheckBox = uicheckbox(app.ImagesTab);
            app.AdvancedmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedmodeCheckBoxValueChanged, true);
            app.AdvancedmodeCheckBox.Text = 'Advanced mode';
            app.applychangestoallpositionsCheckBox = uicheckbox(app.ImagesTab);
            app.applychangestoallpositionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @applychangestoallpositionsCheckBoxValueChanged, true);
            app.applychangestoallpositionsCheckBox.Text = 'apply changes to all positions';
            app.applychangestoallpositionsCheckBox.Value = true;
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.InvertframesandpositionsCheckBox = uicheckbox(app.ProjectTab);
            app.InvertframesandpositionsCheckBox.Text = 'Invert frames and positions';
            app.OutputprojectfileEditFieldLabel = uilabel(app.ProjectTab);
            app.OutputprojectfileEditFieldLabel.Text = 'Output project file:';
            app.OutputprojectfileEditField = uieditfield(app.ProjectTab, 'text');
            app.SetProjectButton = uibutton(app.ProjectTab, 'push');
            app.SetProjectButton.Text = 'Set...';
            app.ROIsTab = uitab(app.TabGroup);
            app.ROIsTab.Title = 'ROIs';
            app.UIAxes = uiaxes(app.ROIsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ROIsButtonGroup = uibuttongroup(app.ROIsTab);
            app.ROIsButtonGroup.Title = 'ROIs';
            app.FullframeROIsButton = uiradiobutton(app.ROIsButtonGroup);
            app.FullframeROIsButton.Text = 'Full frame ROIs';
            app.FullframeROIsButton.Value = true;
            app.DivideframeintoButton = uiradiobutton(app.ROIsButtonGroup);
            app.DivideframeintoButton.Text = 'Divide frame into ';
            app.CustomROIsButton = uiradiobutton(app.ROIsButtonGroup);
            app.CustomROIsButton.Text = 'Custom ROIs';
            app.ROIDivide = uieditfield(app.ROIsButtonGroup, 'numeric');
            app.ROIDivide.Value = 4;
            app.CreatROIButton = uibutton(app.ROIsButtonGroup, 'push');
            app.CreatROIButton.Text = 'Creat ROI';
            app.FindallButton = uibutton(app.ROIsButtonGroup, 'push');
            app.FindallButton.Text = 'Find all';
            app.DimensionsEditFieldLabel = uilabel(app.ROIsButtonGroup);
            app.DimensionsEditFieldLabel.Text = 'Dimensions:';
            app.DimensionsEditField = uieditfield(app.ROIsButtonGroup, 'text');
            % Show the figure after all components are created
            app.LoadingdataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadingdataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadingdataUIFigure)
        end
    end
end