classdef processGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveparametersButton      matlab.ui.control.Button
        ProcessorparametersPanel  matlab.ui.container.Panel
        ProcessordefintionPanel   matlab.ui.container.Panel
        TypeDropDown              matlab.ui.control.DropDown
        ProceesingmethodLabel     matlab.ui.control.Label
        PathLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function displayParam(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj)
             if nargin==1
                classiObj=processor;
                classiObj.path='';
                classiObj.strid='No input classi was provided';
            end

            app.UIFigure.Name=[classiObj.strid];
            app.Data.processObj=classiObj;

            app.PathLabel.Text=[ classiObj.path  ' ' classiObj.strid];
            
            str=which('shallowNew.m');
            [pth file ext]=fileparts(str);
            str=[pth '/processor/processlist.mat'];
            load(str);
            app.Data.processlist=processlist;
            app.TypeDropDown.Items=processlist{:,2};
            
            app.Data.selected=[];
            
            pstruct.toto=false;
            struct2GUI(pstruct,app.UIFigure)
            
            
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            disp('Saving processor')

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Saving current processor parameters...');
            d.Value=0.33;

            processSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';
            
            close(d)
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessordefintionPanel = uipanel(app.UIFigure);
            app.ProcessordefintionPanel.Title = 'Processor defintion';
            app.PathLabel = uilabel(app.ProcessordefintionPanel);
            app.PathLabel.Text = 'Path';
            app.ProceesingmethodLabel = uilabel(app.ProcessordefintionPanel);
            app.ProceesingmethodLabel.Text = 'Proceesing method:';
            app.TypeDropDown = uidropdown(app.ProcessordefintionPanel);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ProcessorparametersPanel = uipanel(app.UIFigure);
            app.ProcessorparametersPanel.Title = 'Processor parameters';
            app.SaveparametersButton = uibutton(app.UIFigure, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end