classdef roiImporterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        sourceChannelList_2             matlab.ui.control.ListBox
        ChannelsavailableinROIstobeimportedLabel  matlab.ui.control.Label
        sourceChannelList               matlab.ui.control.ListBox
        ClickselectchannelsusedasinputoftheclassifierLabel  matlab.ui.control.Label
        ClassesinthedestinationROIEditField  matlab.ui.control.EditField
        ClassesinthedestinationROIEditFieldLabel  matlab.ui.control.Label
        ClassesinthesourceROIEditField  matlab.ui.control.EditField
        ClassesinthesourceROIEditFieldLabel  matlab.ui.control.Label
        Preserveannotations             matlab.ui.control.CheckBox
        TypeROIindicestoimportEditField  matlab.ui.control.EditField
        TypeROIindicestoimportEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        ProceedButton                   matlab.ui.control.Button
        ChoosePositionClassiferDropDown  matlab.ui.control.DropDown
        ChoosePositionClassiferDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function gatherVarsFromWorkspace(app)
            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            cc=0;
            cd=0;
            app.Data.st=[];

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});
                
                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];
                    end

                    st.Projectclassi{cc}=tmpclassi;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')
                    
                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};
                   % aa= st.Classifier{cd}
                end

            end

          %  st
            app.Data.st=st;
            app.Data.convert={};
        end
        
        function  displayChannels(app)
            classiObj=app.Data.mainApp.Data.classiObj;
            
            target=app.Data.target;
            
             targetChannel=app.Data.targetChannel;
             
             if numel(classiObj.channelName)
             app.sourceChannelList.Items= classiObj.channelName;
             else
                app.sourceChannelList.Items={};  
             end
             
             if numel(app.sourceChannelList.Value)==0
                 if numel(app.sourceChannelList.Items)
             app.sourceChannelList.Value= app.sourceChannelList.Items{1};
                 else
                     app.sourceChannelList.Value={};
                 end
             end
             
            str=target.roi(1).display.channel;
            if numel(str)
                if ischar(str)
                    str={str};
                end
            app.sourceChannelList_2.Items=str;
            else
             app.sourceChannelList_2.Items={};    
            end
            
            selectedChannel=find(matches(app.sourceChannelList.Items,app.sourceChannelList.Value));
            
            
            val={};
            if numel(targetChannel)==0
             pix=find(matches(str,app.sourceChannelList.Value));
             if numel(pix)
                val=str{pix};
             else
                
             end
            else 

                %aa=app.Data.targetChannel
               % selectedChannel
             val=  app.Data.targetChannel{selectedChannel};
             
            end
             if numel(val)==0
              val=app.sourceChannelList_2.Items{1};
             end
             
if ischar(val)
    val={val};
end

             if numel(val)
             app.sourceChannelList_2.Value=val;
             if numel(selectedChannel)
             app.Data.targetChannel{selectedChannel}=app.sourceChannelList_2.Value;
             end
             end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.Data.mainApp=mainApp;
            app.UIFigure.Name=['Import from ' mainApp.Data.classiObj.strid];
             app.Data.convert={};
             app.Data.targetChannel=cell(1,numel(mainApp.Data.classiObj.channelName));
          %   aa= app.Data.targetChannel
            gatherVarsFromWorkspace(app)
            
            s=app.Data.st;
            cc=1;
            store=[];
            % list avaialable fovs and classi with ROIs
                for i=1:numel(s.Projectclassi)
                    
                    proj=s.Project{i};
                    
                    shallowObj=evalin('base',proj);

                    for k=1:numel(s.Projectpos{i})
                        
                        tmp= evalin('base',[proj '.fov(' num2str(k) ')']);
                        
                        if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                        varstr{cc}=[proj '.fov(' num2str(k) ')'];
                        displaystr{cc}=[proj '  //  ' s.Projectpos{i}{k} ' - ' num2str(numel(tmp.roi)) ' ROIs available'];
                        if numel(store)==0
                        store=numel(tmp.roi);
                        end
                        cc=cc+1;
                        end
                    end
                    for k=1:numel(s.Projectclassi{i})
                        
                        tmp= evalin('base',[proj '.processing.classification(' num2str(k) ')']);
                        
                        if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                        varstr{cc}=[proj '.processing.classification(' num2str(k) ')'];
                        displaystr{cc}=[proj '  //  ' s.Projectclassi{i}{k} ' - ' num2str(numel(tmp.roi)) ' ROIs available'];
                        if numel(store)==0
                        store=numel(tmp.roi);
                        end
                        cc=cc+1;
                        end
                    end
                end
                for i=1:numel(s.Classifier)
                    
                    clas=s.Classifier{i};
                    
                    tmp= evalin('base',clas);
                        
                    if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                        varstr{cc}=clas;
                        
                        displaystr{cc}=[clas ' - ' num2str(numel(tmp.roi)) ' ROIs available'];
                        
                        if numel(store)==0
                           store=numel(tmp.roi);
                        end
                       
                        cc=cc+1;
                     end
                        
                end
                
                app.ChoosePositionClassiferDropDown.Items=displaystr';
                app.Data.varstr=varstr; % list of classifier variables in the workspace
                app.Data.displaystr=displaystr;
                
                app.TypeROIindicestoimportEditField.Value=['1:' num2str(store)];
                
                ChoosePositionClassiferDropDownValueChanged(app)
              % displayChannels(app)


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
           uiresume(app.Data.mainApp.UIFigure)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           uiresume(app.Data.mainApp.UIFigure)
            delete(app)
            
        end

        % Value changed function: ChoosePositionClassiferDropDown
        function ChoosePositionClassiferDropDownValueChanged(app, event)
            value = app.ChoosePositionClassiferDropDown.Value;
                pix=contains(app.Data.displaystr,value);
                va=app.Data.varstr{pix};
                tmp=evalin('base',va);
                app.TypeROIindicestoimportEditField.Value=['1:' num2str(numel(tmp.roi))];
                
                if isa(tmp,'classi')
                    app.Preserveannotations.Enable='on';
                else
                    app.Preserveannotations.Enable='off';
                end
                
                app.Data.target=tmp;
              %  app.Data.targetChannel={};
                 app.Data.targetChannel=cell(1,numel(app.Data.mainApp.Data.classiObj.channelName));
                displayChannels(app)
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            value = app.ChoosePositionClassiferDropDown.Value;
            
            classiObj=app.Data.mainApp.Data.classiObj;
            
            pix=contains(app.Data.displaystr,value);
            va=app.Data.varstr{pix};
            tmp=evalin('base',va);
            
            rois=str2num(app.TypeROIindicestoimportEditField.Value);
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                    'Message','Importing ROIs from one classifier to another...');
            d.Value=0.33;
            
         %   rois
            adjustChannelName=app.Data.targetChannel;
           % return;
            
            if numel(rois)>0
       
                classiObj.addROI(tmp,'rois',rois,'convert',app.Data.convert,'adjustName',adjustChannelName)
                
            end
            
            d.Value=0.90;
            d.Message='Transfer is complete!';
            pause(1);
            
        end

        % Value changed function: Preserveannotations
        function PreserveannotationsValueChanged(app, event)
            value = app.Preserveannotations.Value;
            
             dat = app.ChoosePositionClassiferDropDown.Value;
             pix=contains(app.Data.displaystr,dat);
             va=app.Data.varstr{pix};
             tmp=evalin('base',va);
            
             cla=false;
             
             if isa(tmp,'classi')
             aa=tmp.classes;
             
             if numel(aa)
                 cla=true;
             end
                
             end
             
            if value && cla % preserve training
            app.ClassesinthedestinationROIEditField.Enable='on';
            app.ClassesinthesourceROIEditField.Enable='on';
            
            str=[];
            for i=1:numel(tmp.classes)
                str=[str ' ' tmp.classes{i}];
            end
            
            str2=[];
            for i=1:numel(app.Data.mainApp.Data.classiObj.classes)
                str2=[str2 ' ' app.Data.mainApp.Data.classiObj.classes{i}];
            end
            
            app.ClassesinthesourceROIEditField.Value=str;
            app.ClassesinthedestinationROIEditField.Value=str2;
            app.Data.convert={str,str2};

            else
             app.ClassesinthedestinationROIEditField.Enable='off';
             app.ClassesinthesourceROIEditField.Enable='off';  
             app.Data.convert={};
            end


        end

        % Value changed function: sourceChannelList
        function sourceChannelListValueChanged(app, event)
            value = app.sourceChannelList.Value;
            
            displayChannels(app)
        end

        % Value changed function: sourceChannelList_2
        function sourceChannelList_2ValueChanged(app, event)
            value = app.sourceChannelList_2.Value;
            
            valueSource = app.sourceChannelList.Value;
            itemSource=app.sourceChannelList.Items;
            
            pix=find(matches(itemSource,valueSource));
            
            app.Data.targetChannel{pix}=value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChoosePositionClassiferDropDownLabel = uilabel(app.UIFigure);
            app.ChoosePositionClassiferDropDownLabel.Text = 'Choose Position/Classifer:';
            app.ChoosePositionClassiferDropDown = uidropdown(app.UIFigure);
            app.ChoosePositionClassiferDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePositionClassiferDropDownValueChanged, true);
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TypeROIindicestoimportEditFieldLabel = uilabel(app.UIFigure);
            app.TypeROIindicestoimportEditFieldLabel.Text = 'Type ROI indices to import:';
            app.TypeROIindicestoimportEditField = uieditfield(app.UIFigure, 'text');
            app.Preserveannotations = uicheckbox(app.UIFigure);
            app.Preserveannotations.ValueChangedFcn = createCallbackFcn(app, @PreserveannotationsValueChanged, true);
            app.Preserveannotations.Text = 'Preserve annotations from the original ROI if avaiable';
            app.ClassesinthesourceROIEditFieldLabel = uilabel(app.UIFigure);
            app.ClassesinthesourceROIEditFieldLabel.Enable = 'off';
            app.ClassesinthesourceROIEditFieldLabel.Text = 'Classes in the source ROI: ';
            app.ClassesinthesourceROIEditField = uieditfield(app.UIFigure, 'text');
            app.ClassesinthesourceROIEditField.Editable = 'off';
            app.ClassesinthedestinationROIEditFieldLabel = uilabel(app.UIFigure);
            app.ClassesinthedestinationROIEditFieldLabel.Enable = 'off';
            app.ClassesinthedestinationROIEditFieldLabel.Text = 'Classes in the destination ROI:';
            app.ClassesinthedestinationROIEditField = uieditfield(app.UIFigure, 'text');
            app.ClassesinthedestinationROIEditField.Enable = 'off';
            app.ClassesinthedestinationROIEditField.Tooltip = {'Enter space-separated classes names; Type "0"  if you do not want to use a corresponding class; thenumber of items in both text fields must be  identical'; ''};
            app.ClickselectchannelsusedasinputoftheclassifierLabel = uilabel(app.UIFigure);
            app.ClickselectchannelsusedasinputoftheclassifierLabel.Text = 'Click / select channels used as input of the classifier:';
            app.sourceChannelList = uilistbox(app.UIFigure);
            app.sourceChannelList.ValueChangedFcn = createCallbackFcn(app, @sourceChannelListValueChanged, true);
            app.ChannelsavailableinROIstobeimportedLabel = uilabel(app.UIFigure);
            app.ChannelsavailableinROIstobeimportedLabel.Text = 'Select corresponding channel to be used for classification in the target ROIs:';
            app.sourceChannelList_2 = uilistbox(app.UIFigure);
            app.sourceChannelList_2.ValueChangedFcn = createCallbackFcn(app, @sourceChannelList_2ValueChanged, true);
            app.sourceChannelList_2.Tooltip = {'WARNING : Channel name will be changed upon import !!!'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>>>>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roiImporterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end