classdef score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScoreAppUIFigure              matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        deleteselectedROIMenu         matlab.ui.container.Menu
        SaveselectedroiMenu           matlab.ui.container.Menu
        DisplayMenu                   matlab.ui.container.Menu
        ROIsMenu                      matlab.ui.container.Menu
        DisplaysettingsMenu           matlab.ui.container.Menu
        IntensityquantificationMenu   matlab.ui.container.Menu
        AnnotationMenu                matlab.ui.container.Menu
        DatasettingsMenu              matlab.ui.container.Menu
        CopypresetsMenu               matlab.ui.container.Menu
        PastepresetsMenu              matlab.ui.container.Menu
        AnalysisMenu                  matlab.ui.container.Menu
        ComputemetricsinobjectMenu    matlab.ui.container.Menu
        IntensityQuantificationPanel  matlab.ui.container.Panel
        ShapeButton                   matlab.ui.control.StateButton
        LineIntensityprofileButton    matlab.ui.control.StateButton
        UIProfileAxes                 matlab.ui.control.UIAxes
        AnnotationPanel               matlab.ui.container.Panel
        isthedefautcolorCheckBox      matlab.ui.control.CheckBox
        MaskColorPicker               matlab.ui.control.ColorPicker
        AnnoatationcolorLabel         matlab.ui.control.Label
        DeleteclassButton             matlab.ui.control.Button
        NewclassButton                matlab.ui.control.Button
        DeleteAnnnotationButton       matlab.ui.control.Button
        NewAnnotationButton           matlab.ui.control.Button
        ObjectspanelPanel             matlab.ui.container.Panel
        ComputemetricsButton          matlab.ui.control.Button
        SelectedLabel                 matlab.ui.control.Label
        SelectedobjectindexEditField  matlab.ui.control.NumericEditField
        SelectedobjectindexEditFieldLabel  matlab.ui.control.Label
        PaintButton                   matlab.ui.control.StateButton
        IndexChannelLabel             matlab.ui.control.Label
        Transparency                  matlab.ui.control.Slider
        TransparencyLabel             matlab.ui.control.Label
        UIAnnotationTable             matlab.ui.control.Table
        DataSettingsPanel             matlab.ui.container.Panel
        DeselectallsubdataButton      matlab.ui.control.Button
        DeselectalldataButton         matlab.ui.control.Button
        ClassDropDown                 matlab.ui.control.DropDown
        ClassDropDownLabel            matlab.ui.control.Label
        FrameLabel                    matlab.ui.control.Label
        AssignvalueEditField          matlab.ui.control.EditField
        AssignvalueLabel              matlab.ui.control.Label
        SelecteddataLabel             matlab.ui.control.Label
        UISubDataTable                matlab.ui.control.Table
        UIDataTable                   matlab.ui.control.Table
        ROisPanel                     matlab.ui.container.Panel
        UIROITable                    matlab.ui.control.Table
        DisplaysettingsPanel          matlab.ui.container.Panel
        PanButton                     matlab.ui.control.StateButton
        ResetButton                   matlab.ui.control.Button
        ChannelPanel                  matlab.ui.container.Panel
        LowHighDisplaySlider          matlab.ui.control.RangeSlider
        LevelsLabel                   matlab.ui.control.Label
        OverlayCheckBox               matlab.ui.control.CheckBox
        SelectedChannelLabel          matlab.ui.control.Label
        ChannelColorPicker            matlab.ui.control.ColorPicker
        WeightSlider                  matlab.ui.control.Slider
        WeightSliderLabel             matlab.ui.control.Label
        FrameEditField                matlab.ui.control.NumericEditField
        FrameSlider                   matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        ZoomSlider                    matlab.ui.control.Slider
        ZoomLabel                     matlab.ui.control.Label
        UIChannelTable                matlab.ui.control.Table
        UIDisplayAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        content = struct('ROIList',[]); % Description
        SliderTickLabels % Description
        HistogramEdges % Stocke les bords des bins de l'histogramme
        HistogramData  % Stocke les valeurs de l'histogramme pour chaque canal affiché
        HistogramChannels % Stocke les indices des channels affichés dans l'histogramme
        HistogramLines  % Stocke les handles des courbes pour éviter de les redessiner
        HistogramLimits % Stocke les handles des lignes de niveaux (low/high)
        LineIntensityProfileLine   % handle de l'objet imline (initialement [])
        LineProfilePosition        % position mémorisée de la ligne (par exemple, [x1 y1; x2 y2])
        EllipseIntensityProfileObj   % Handle de l'ellipse (initialement [])
        EllipseProfilePosition       % Position mémorisée (rectangle [x y width height])
        OriginalXLim
        OriginalYLim
        BaselineXLim   % Baseline X-limits à 100%
        BaselineYLim   % Baseline Y-limits à 100%
        PreviousZoomFactor
        DisplaySettings % Description
        ImageFigure
        ImageAxes
        OverlayAxes
        SelectedObjectRectangle
        SelectedObjectLabel
        DataFigure % Description
        specialkeys % Description
        keys
    end

    properties (Access = private)

    end

    methods (Access = private)

        % Toggle the visibility of a panel and update menu status
        function newVisibility = toggleVisibility(~, menuItem)
            if strcmp(menuItem.Checked, 'on')
                menuItem.Checked = 'off';
                newVisibility = 'off';
            else
                menuItem.Checked = 'on';
                newVisibility = 'on';
            end
        end


        function displayROIs(app)
            % Vérifie si des ROIs sont présentes
            if isempty(app.content.ROIList)
                app.UIROITable.Data = {}; % Vide la table si aucune ROI
                return;
            end

            % Préparer les colonnes : Display, Name, Size
            numROIs = numel(app.content.ROIList);
            tableData = cell(numROIs, 3);

            for i = 1:numROIs
                roi = app.content.ROIList{i}; % Récupérer l'instance de la ROI

                if i==numROIs
                    tableData{i, 1} = true; % Désactiver l'affichage par défaut
                else
                    tableData{i, 1} = false; % Désactiver l'affichage par défaut
                end

                tableData{i, 2} = roi.id; % Nom de la ROI
                tableData{i, 3} = sprintf('%dx%d', size(roi.image, 1), size(roi.image, 2)); % Taille de l'image
            end

            % Mettre à jour la table
            app.UIROITable.Data = tableData;
            app.UIROITable.ColumnName = {'Display', 'Name', 'Size'};

            % Ajuster les largeurs des colonnes
            app.UIROITable.ColumnWidth = {50, 'auto', 60};

            % Définir un callback pour gérer les sélections
            app.UIROITable.CellEditCallback = @(src, event) app.handleROITableEdit(event);

            % Vérifier si des ROIs sont présentes dans l'application
            if isempty(app.content.ROIList)
                app.UIChannelTable.Data = {}; % Vide la table si aucune ROI
                return;
            end

            % Trouver l'index de la ROI actuellement sélectionnée
            selectedIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1); % Cherche la première ROI cochée
            if isempty(selectedIndex)
                app.UIChannelTable.Data = {}; % Vide la table si aucune ROI n'est sélectionnée
                return;
            end

            % Récupérer la ROI sélectionnée
            selectedROI = app.content.ROIList{selectedIndex};

            numFrames = size(selectedROI.image, 4);

            % Mettre à jour les limites du slider
            app.FrameSlider.Limits = [1 numFrames];

            % Réinitialiser la valeur du slider (optionnel)
            app.FrameSlider.Value = selectedROI.display.frame;

            displayData(app);
            displayROIChannels(app);
            score_display(app, 'slow');

        end

        function handleROITableEdit(app, event)
            % Callback pour s'assurer qu'une seule ROI est sélectionnée
            if event.Indices(2) == 1 % Si la colonne "Display" est modifiée
                % Désactiver toutes les cases sauf celle cliquée
                numROIs = size(app.UIROITable.Data, 1);
                for i = 1:numROIs
                    app.UIROITable.Data{i, 1} = false;
                end

                % Activer uniquement la ligne sélectionnée
                app.UIROITable.Data{event.Indices(1), 1} = true;

                % Mettre à jour la table
                app.UIROITable.Data = app.UIROITable.Data;

                % Optionnel : effectuer des actions basées sur la sélection
                selectedROI = app.content.ROIList{event.Indices(1)};
                disp(['Selected ROI: ', selectedROI.id]);

              
                displayROIChannels(app);

                numFrames = size(selectedROI.image, 4);

                % Mettre à jour les limites du slider
                app.FrameSlider.Limits = [1 numFrames];

                % Réinitialiser la valeur du slider (optionnel)
                app.FrameSlider.Value = selectedROI.display.frame;

                score_display(app, 'slow');
            end

        end

        function displayROIChannels(app)


            % Vérifier si des ROIs sont présentes dans l'application
            if isempty(app.content.ROIList)
                app.UIChannelTable.Data = {}; % Vide la table si aucune ROI
                return;
            end

            % Trouver l'index de la ROI actuellement sélectionnée
            selectedIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1); % Cherche la première ROI cochée
            if isempty(selectedIndex)
                app.UIChannelTable.Data = {}; % Vide la table si aucune ROI n'est sélectionnée
                return;
            end


            % Récupérer la ROI sélectionnée
            selectedROI = app.content.ROIList{selectedIndex};

            % Vérifier si la ROI a des canaux disponibles
            if isempty(selectedROI.display.channel)
                app.UIChannelTable.Data = {}; % Vide la table si aucun canal
                return;
            end

            % Préparer la liste des canaux affichables :
            % On inclut les canaux dont l'intensité n'est pas nulle et aussi ceux qui correspondent à des images RGB (3 canaux)
            numChannels = numel(selectedROI.display.channel);
            colorChannels = [];
            indexedChannels=[];

            for i = 1:numChannels
                % Vérifier si le canal correspond à une image RGB
                pix = selectedROI.findChannelID(selectedROI.display.channel{i});
                if numel(pix)==3
                    % Canal RGB détecté, on l'inclut
                    colorChannels = [colorChannels i];
                else
                    % Pour les autres canaux, on ne les inclut que si l'intensité n'est pas nulle
                    if sum(selectedROI.display.intensity(i, :)) == 0
                        indexedChannels=[ indexedChannels i];
                        % continue;

                    else
                        colorChannels = [colorChannels i];
                    end
                end
            end

            % Préparer les colonnes pour la table : Display, Name, Levels, RGB, Weight, Auto
            tableData = cell(numel(colorChannels), 6);
            for i = 1:numel(colorChannels)
                chIndex = colorChannels(i);
                % Colonne "Display" : checkbox indiquant si le canal doit être affiché
                tableData{i, 1} = logical(selectedROI.display.selectedchannel(chIndex));
                % Colonne "Name" : nom du canal
                tableData{i, 2} = selectedROI.display.channel{chIndex};
                % Colonne "Levels" : niveaux d'affichage (convertis en échelle 0-65535)

                if ~isfield(selectedROI.display,'displaylim') | numel(selectedROI.display.displaylim)==0
                    selectedROI.computeDisplaylim;
                end

                tableData{i, 3} = sprintf('%.0f %.0f', round(65535 * selectedROI.display.displaylim(1, chIndex)), round(65535 * selectedROI.display.displaylim(2, chIndex)));
                % Colonne "RGB" : affichage de la couleur (pour les images RGB, vous pouvez afficher par exemple 'RGB' ou la valeur si définie)
                if numel(selectedROI.findChannelID(selectedROI.display.channel{chIndex}))==3
                    % Optionnel : vous pouvez afficher "RGB" ou une valeur par défaut
                    tableData{i, 4} = 'RGB';
                else
                    tableData{i, 4} = sprintf('%.1f %.1f %.1f', selectedROI.display.rgb(chIndex, :));
                end
                % Colonne "Weight" : poids du canal
                tableData{i, 5} = sprintf('%.1f', selectedROI.display.intensity(chIndex, 1));
                % Colonne "Auto" : par défaut, "Auto" est désactivé
                tableData{i, 6} = false;
            end

            % Mettre à jour la table et ses propriétés
            app.UIChannelTable.Data = tableData;
            app.UIChannelTable.ColumnName = {'Display', 'Name', 'Levels', 'RGB', 'Weight', 'Auto'};
            app.UIChannelTable.ColumnWidth = {40, 100, 80, 80, 60, 45};
            % Rendre certaines colonnes éditables (ici Display, Levels, RGB, Weight, Auto)
            app.UIChannelTable.ColumnEditable = [true, false, true, true, true, true];

            % Définir le callback pour gérer les modifications
            app.UIChannelTable.CellEditCallback = @(src, event) app.handleChannelTableEdit(event, selectedROI, colorChannels);


            nIndexed = numel(indexedChannels);
            tableDataIndex = cell(nIndexed, 3);  % colonnes: Display, Annotation, Class
            for i = 1:nIndexed
                chIndex = indexedChannels(i);
                % Colonne "Display": case à cocher (selon la sélection actuelle)
                tableDataIndex{i, 1} = logical(selectedROI.display.selectedchannel(chIndex));

                % Récupérer le nom complet du canal
                channelName = selectedROI.display.channel{chIndex};
                pos = strfind(channelName, '_');
                if isempty(pos)
                    % Aucun underscore trouvé : toute la chaîne est l'annotation, classe vide
                    annotationName = channelName;
                    className = '';
                else
                    % Utiliser le premier underscore pour séparer annotation et classe
                    firstUnderscore = pos(1);
                    annotationName = channelName(1:firstUnderscore-1);
                    className = channelName(firstUnderscore+1:end);
                end

                tableDataIndex{i, 2} = annotationName;
                tableDataIndex{i, 3} = className;
            end

            app.UIAnnotationTable.Data = tableDataIndex;
            app.UIAnnotationTable.ColumnName = {'Display', 'Annotation', 'Class'};
            app.UIAnnotationTable.ColumnWidth = {50, 'auto', 'auto'};
            app.UIAnnotationTable.ColumnEditable = [true, true, true];


            app.UIAnnotationTable.CellEditCallback = @(src, event) app.handleIndexedChannelTableEdit(event, selectedROI, indexedChannels);


            displayData(app);
            % Mettre à jour l'affichage des features du channel et redessiner l'image si besoin
            displayChannelFeatures(app);

                         
          %  score_display(app, 'slow'); % unchecked because crashed ellips
          %  mode 
          

        end


        function handleChannelTableEdit(app, event, selectedROI, colorChannels)
            % Callback pour gérer l'édition de la table des canaux
            rowIndex = event.Indices(1); % Index de la ligne modifiée
            colIndex = event.Indices(2); % Index de la colonne modifiée
            chIndex = colorChannels(rowIndex); % Trouver le bon index du channel

            switch colIndex
                case 1 % Modification dans la colonne "Display" (checkbox)
                    selectedROI.display.selectedchannel(chIndex) = event.NewData;

                case 3
                    tmp=str2num(event.NewData);
                    selectedROI.display.displaylim(:, chIndex) = double(tmp/65535);


                case 4 % Modification dans la colonne "RGB"
                    % Valider l'entrée comme un triplet RGB
                    rgbValues = str2double(strsplit(event.NewData, {' ', ','}));

                    if numel(rgbValues) == 3 && all(~isnan(rgbValues))
                        disp('RGB values parsed successfully');
                    else
                        disp('Invalid RGB input format');
                    end

                    if numel(rgbValues) == 3 && all(rgbValues >= 0 & rgbValues <= 1)
                        selectedROI.display.rgb(chIndex, :) = rgbValues';
                    else
                        uialert(app.ScoreAppUIFigure, 'RGB values must be three numbers between 0 and 1.', 'Invalid Input');
                        app.displayROIChannels(); % Réinitialiser la table
                    end

                case 5 % Modification dans la colonne "Weight"
                    % Valider l'entrée comme un nombre positif
                    weightValue = str2double(event.NewData);
                    if ~isnan(weightValue) && weightValue >= 0
                        selectedROI.display.intensity(chIndex) = min(1,max(0.01,weightValue));

                    else
                        uialert(app.ScoreAppUIFigure, 'Weight must be a positive number.', 'Invalid Input');
                        app.displayROIChannels(); % Réinitialiser la table
                    end

                case 6 % Modification dans la colonne "Auto" (checkbox pour auto-adjust)
                    if event.NewData
                                   
                        % Appliquer un auto-ajustement des niveaux
                        selectedROI.computeDisplaylim;
                        app.displayROIChannels(); % Mettre à jour la table
                    end
            end

            displayChannelFeatures(app);
            displayData(app);
            score_display(app, 'refresh');
            % event=[];
            % event.Value= log10(selectedROI.display.displaylim(1, chIndex));
            % LowDisplaySliderValueChanging(app, event);
            % event.Value= log10(selectedROI.display.displaylim(2, chIndex));
            % HighDisplaySliderValueChanging(app, event);
        end


        function displayChannelFeatures(app)

            % Vérifier qu'une ROI est sélectionnée
            if isempty(app.content.ROIList)
                return;
            end

            % Trouver la ROI actuellement sélectionnée via la table des ROIs
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Vérifier qu'un canal est sélectionné dans la table des canaux
            selectedChannelIndex = app.UIChannelTable.Selection;
            if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
                return;
            end

            % Récupérer le nom du channel sélectionné dans la table
            selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2};

            % Tenter de trouver ce channel dans la liste actuelle de la ROI
            channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);
            if isempty(channelIndex)
                % Si non trouvé, utiliser le premier channel disponible
                if ~isempty(selectedROI.display.channel)
                    channelIndex = 1;
                    % Mettez à jour la sélection dans la table pour refléter ce choix par défaut
                    app.UIChannelTable.Selection = [1, 2];  % On suppose que la colonne 2 contient le nom
                else
                    return;
                end
            end

            % Vérifier que displaylim est défini pour ce channel sinon affecter des valeurs par défaut
            if isempty(selectedROI.display.displaylim) || size(selectedROI.display.displaylim,2) < channelIndex
                selectedROI.display.displaylim(:, channelIndex) = [0; 1];
            end

            % Définir les limites du slider en échelle logarithmique
            minVal = 1;    % On évite 0 (log(0) est indéfini)
            maxVal = 65535;
            logMin = log10(minVal);
            logMax = log10(maxVal);
            app.LowHighDisplaySlider.Limits = [logMin, logMax];

            % Extraire les limites d'affichage pour le channel sélectionné
            dlim = selectedROI.display.displaylim(:, channelIndex);
            if isempty(dlim) || numel(dlim) ~= 2
                dlim = [0, 1];
            end

            % S'assurer que la première valeur est bien inférieure à la seconde
            if dlim(1) > dlim(2)
                dlim = sort(dlim);
            end

            lowLevel = max(65535 * dlim(1), 1);
            highLevel = min(65535 * dlim(2), 65535);

            % Calculer les valeurs logarithmiques correspondantes
            valLow = log10(lowLevel);
            valHigh = log10(highLevel);
            if valLow > valHigh
                [valLow, valHigh] = deal(valHigh, valLow);
            end

            % Mettre à jour le slider avec une paire non décroissante
            app.LowHighDisplaySlider.Value = [valLow, valHigh];

            % Mettre à jour le color picker avec la couleur du channel
            rgbValues = selectedROI.display.rgb(channelIndex, :);
            app.ChannelColorPicker.Value = rgbValues;

            % Mettre à jour le slider de poids
            weightValue = min(1, max(0, selectedROI.display.intensity(channelIndex, 1)));
            app.WeightSlider.Value = weightValue;

        end

        function updateChannelTable(app, selectedROI)
            % Vérifier si la table est vide ou si aucun canal n'est affiché
            if isempty(app.UIChannelTable.Data)
                return;
            end

            % Trouver les canaux à afficher (colorChannels)
            numChannels = size(selectedROI.display.rgb, 1);
            colorChannels = [];

            for i = 1:numChannels
                if sum(selectedROI.display.intensity(i, :)) == 0
                    continue; % Skip les canaux indexés (intensity == 0)
                end
                colorChannels = [colorChannels, i]; % Stocke les indices des canaux affichables
            end

            % Vérifier qu'on a bien des canaux valides
            if isempty(colorChannels)
                app.UIChannelTable.Data = {}; % Vide la table si aucun canal valide
                return;
            end

            % Récupérer les données actuelles de la table
            tableData = app.UIChannelTable.Data;

            for i = 1:numel(colorChannels)
                chanIndex = colorChannels(i); % Indice réel du canal dans `selectedROI.display`

                % Mettre à jour la colonne "Levels" (conversion en 65535)
                tableData{i, 3} = sprintf('%.2f %.2f', ...
                    round(65535 * selectedROI.display.displaylim(1, chanIndex)), ...
                    round(65535 * selectedROI.display.displaylim(2, chanIndex)));

                % Mettre à jour la colonne "RGB" (sans crochets)
                tableData{i, 4} = sprintf('%.2f %.2f %.2f', selectedROI.display.rgb(chanIndex, :));

                % Mettre à jour la colonne "Weight"
                tableData{i, 5} = sprintf('%.2f', selectedROI.display.intensity(chanIndex, 1));
            end

            % Mettre à jour les données de la table
            app.UIChannelTable.Data = tableData;
            score_display(app, 'refresh');
        end

        function handleIndexedChannelTableEdit(app, event, selectedROI, indexedChannels)
            rowIndex = event.Indices(1); % ligne modifiée
            colIndex = event.Indices(2); % colonne modifiée
            chIndex = indexedChannels(rowIndex);

            % Récupérer le nom de canal existant et découper en utilisant le premier '_'
            oldChannelName = selectedROI.display.channel{chIndex};
            pos = strfind(oldChannelName, '_');
            if isempty(pos)
                currentAnnotation = oldChannelName;
                currentClass = '';
            else
                firstUnderscore = pos(1);
                currentAnnotation = oldChannelName(1:firstUnderscore-1);
                currentClass = oldChannelName(firstUnderscore+1:end);
            end

            switch colIndex
                case 1
                    % Modification de la case "Display"
                    selectedROI.display.selectedchannel(chIndex) = event.NewData;
                case 2
                    % Modification de la colonne "Annotation"
                    newAnnotation = event.NewData;
                    selectedROI.display.channel{chIndex} = [newAnnotation, '_', currentClass];
                case 3
                    % Modification de la colonne "Class"
                    newClass = event.NewData;
                    selectedROI.display.channel{chIndex} = [currentAnnotation, '_', newClass];
            end

            % Rafraîchir l'affichage pour refléter les modifications
            displayChannelFeatures(app);
            score_display(app, 'refresh');
        end
    end

    methods (Access = public)
        function addROI(app,roiobj)
            % Vérifier si ROI existe déjà dans app.content.ROIList
            if isempty(app.content.ROIList)
                isPresent = false;
            else
                % Vérifier si une ROI avec le même ID existe
                isPresent = any(cellfun(@(roi) strcmp(roi.id, roiobj.id), app.content.ROIList));
            end

            if isPresent
                disp(['ROI with ID "', roiobj.id, '" is already present. Skipping addition.']);
            else
                % Ajouter la ROI à la liste
                if numel(roiobj.image)==0
                    roiobj.load;
                end

                app.content.ROIList{end+1} = roiobj;
                disp(['Adding ROI: ', roiobj.id]);

                   % desactivate data rendering 
            dataStruct = roiobj.data ; % tableau de structures
            nData = numel(dataStruct);
            for i = 1:nData
               dataStruct(i).show=false;
            end

                % Mettre à jour l'affichage de la table
                app.displayROIs();
            end
        end


        function displayData(app)
            % Vérifier qu'une ROI est sélectionnée via la table des ROI
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                app.UIDataTable.Data = {};
                return;
            end

            roi = app.content.ROIList{selectedROIIndex};

            % S'assurer que la ROI possède des données (data)
            if ~isprop(roi, 'data') || isempty(roi.data)
                app.UIDataTable.Data = {};
                return;
            end

            dataStruct = roi.data ; % tableau de structures
            nData = numel(dataStruct);
            % Préparer une cellule t avec 5 colonnes :
            % 1: sélection (booléen), 2: groupid, 3: parentid, 4: class, 5: type
            t = cell(nData, 3);
            for i = 1:nData
                if isprop(dataStruct(i), 'show') && dataStruct(i).show
                    t{i,1} = true;
                else
                    t{i,1} = false;
                end
                if isprop(dataStruct(i), 'groupid')
                    t{i,2} = dataStruct(i).groupid;
                else
                    t{i,2} = '';
                end
                if isprop(dataStruct(i), 'type')
                    t{i,3} = dataStruct(i).type;
                else
                    t{i,3} = '';
                end
                % if isprop(dataStruct(i), 'class')
                %     t{i,4} = char(dataStruct(i).class);
                % else
                %     t{i,4} = '';
                % end
                % if isprop(dataStruct(i), 'type')
                %     t{i,5} = char(dataStruct(i).type);
                % else
                %     t{i,5} = '';
                % end
            end
            t(:,3) = cellfun(@char, t(:,3), 'UniformOutput', false);
            app.UIDataTable.Data = t;

            displaySubData(app);
            
            selectedTableIndex = find(cell2mat(app.UIDataTable.Data(:,1)));
           
          %  if numel(selectedTableIndex)
            for i=1:numel(roi.data) %selectedTableIndex'
                h=findobj('Tag',roi.data(i).id);

                if numel(h) % plot is present 
                    if numel(find( selectedTableIndex==i))==0 % table is not checked, delete plot
                        delete(h);
                    end
                end

            
            if numel(find( selectedTableIndex==i))~=0
                    app.DataFigure(i)= roi.data(i).plot();
            end
            end

        end


        function displaySubData(app)
            % Récupérer la sélection dans UIDataTable
            selection = app.UIDataTable.Selection;
            if isempty(selection)
                app.UISubDataTable.Data = {};
                return;
            end

            dsIndex = selection(1);

            % Sélectionner la ROI courante
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                app.UISubDataTable.Data = {};
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            if ~isprop(roi, 'data') || numel(roi.data) < dsIndex
                app.UISubDataTable.Data = {};
                return;
            end

            selectedData = roi.data(dsIndex);

            % Vérifier que le dataset contient des données (tableau ou table)
            if isempty(selectedData.data)
                app.UISubDataTable.Data = {};
                return;
            end

            % Récupérer les noms de variables du tableau (si c'est une table)
            if istable(selectedData.data)
                varnames = selectedData.data.Properties.VariableNames;
            else
                % sinon, on suppose que selectedData.data est structuré
                varnames = fieldnames(selectedData.data);
            end


            % Si plotProperties n'existe pas, on crée des valeurs par défaut
            if ~isprop(selectedData, 'plotProperties') || isempty(selectedData.plotProperties)
                nVars = numel(varnames);
                t = cell(nVars, 6);

                columnformat={[] [] [] [] [] {'id' 'prob' 'labels'}};
                for i = 1:nVars
                    t{i,1} = false;           % Plot flag
                    t{i,2} = varnames{i};       % Nom de la variable
                    t{i,3} = class(selectedData.data.(varnames{i})); % Type
                    t{i,4} = 'k';             % Couleur par défaut
                    t{i,5} = 2;               % Largeur par défaut
                    % Définir un groupe par défaut en fonction du nom
                    if ~isempty(regexpi(varnames{i},'id'))
                        t{i,6} = 'id';
                    elseif ~isempty(regexpi(varnames{i},'prob'))
                        t{i,6} = 'prob';
                    elseif ~isempty(regexpi(varnames{i},'labels'))
                        t{i,6} = 'labels';
                    else
                        t{i,6} = '';
                    end
                end
            else
                t = selectedData.plotProperties;
                columnformat= selectedData.plotGroup;
            end


            app.UISubDataTable.ColumnFormat=columnformat;

            selectedData.plotGroup=columnformat;

            app.UISubDataTable.Data = t;

            % Sauvegarder la configuration dans le dataset
            selectedData.plotProperties = t;
            roi.data(dsIndex) = selectedData;
        end


        function ImageFigureKeyPress(app, event)
            % Callback pour app.ImageFigure.KeyPressFcn : navigation entre frames
            % via les touches fléchées gauche et droite.

            % Vérifier la touche pressée
            switch event.Key
                case 'rightarrow'
                    increment = 1;
                case 'leftarrow'
                    increment = -1;
                otherwise
                    return; % Autres touches ignorées
            end

            % Récupérer la ROI actuellement sélectionnée via la table des ROIs
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Mettre à jour le numéro de frame
            currentFrame = selectedROI.display.frame;
            numFrames = size(selectedROI.image, 4);
            newFrame = max(1, min(currentFrame + increment, numFrames));
            selectedROI.display.frame = newFrame;

            % Mettre à jour le slider et le champ d'édition correspondants
            app.FrameSlider.Value = newFrame;
            app.FrameEditField.Value = newFrame;

            % Rafraîchir l'affichage (image et histogramme)
            score_display(app, 'refresh');
        end

        function checkOrCreateImageFigure(app)
            % Vérifier si app.ImageFigure n'existe pas ou n'est plus valide
            if ~isprop(app, 'ImageFigure') || isempty(app.ImageFigure) || ~ishandle(app.ImageFigure) || ~isvalid(app.ImageFigure)
                % Récupérer la position de la fenêtre principale
                mainPos = app.ScoreAppUIFigure.Position;  % [x, y, width, height]
                imageFigWidth = 800;
                imageFigHeight = 800;
                imageFigX = mainPos(1) + mainPos(3); % À droite de la fenêtre principale
                imageFigY = mainPos(2);

                % Créer la figure d'affichage sans menu ni toolbar
                app.ImageFigure = figure('Name', 'Affichage des images', ...
                    'MenuBar', 'none', 'ToolBar', 'none', ...
                    'Position', [imageFigX, imageFigY, imageFigWidth, imageFigHeight]);

                %   app.ImageFigure.WindowButtonDownFcn = @(src, event) score_paintOverlay(src, event, app);

                app.ImageFigure.KeyPressFcn = @(src, event) ImageFigureKeyPress(app, event);

                % Créer les axes pour occuper toute la figure
                app.ImageAxes = axes('Parent', app.ImageFigure, 'Units', 'normalized', 'Position', [0 0 1 1], 'HitTest', 'off');

                % (Si nécessaire, initialiser également un overlay synchronisé)
                app.OverlayAxes = axes(app.ImageFigure,'Units', 'normalized', 'Position', app.ImageAxes.Position, ...
                    'Color', 'none', 'XColor', 'none', 'YColor', 'none','HitTest', 'on');

                uistack(app.OverlayAxes, 'top');

                % Dans votre startupFcn, après avoir créé app.OverlayAxes :
                app.OverlayAxes.ButtonDownFcn = @(src, event) score_paintOverlay(src, event, app);

                % Synchroniser la position et les limites entre les axes d'image et d'overlay
                app.OverlayAxes.Position = app.ImageAxes.Position;
                app.OverlayAxes.Color = 'none';
                app.OverlayAxes.XColor = 'none';
                app.OverlayAxes.YColor = 'none';
                app.OverlayAxes.HitTest = 'off';
                %app.OverlayAxes.Units = 'pixels';
                linkaxes([app.ImageAxes, app.OverlayAxes], 'xy');
                set(app.OverlayAxes, 'Visible', 'on');

            end
        end

        function updateAssignValueControls(app)
            % Récupère la ROI sélectionnée


            if isempty(app.UIROITable.Data) || size(app.UIROITable.Data,1) < 1
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            % Vérifier que les sélections existent dans UIDataTable et UISubDataTable
            if isempty(app.UIDataTable.Selection) || isempty(app.UISubDataTable.Selection)
                return;
            end
            dsIndex = app.UIDataTable.Selection(1);
            if dsIndex > size(app.UIDataTable.Data,1)
                warning('La sélection de UIDataTable dépasse le nombre de lignes.');
                return;
            end
            subSel = app.UISubDataTable.Selection(1);
            if subSel > size(app.UISubDataTable.Data,1)
                warning('La sélection de UISubDataTable dépasse le nombre de lignes.');
                return;
            end

            % On suppose que le nom de la variable est en colonne 2 de UISubDataTable
            varName = app.UISubDataTable.Data{subSel,2};

            % Frame courante
            currentFrame = roi.display.frame;

            % Récupère le dataset sélectionné (on suppose qu'il s'agit d'une table ou d'un cell array)
            selectedData = roi.data(dsIndex);

            % Vérifier que le dataset contient au moins une frame
            if isempty(selectedData.data) || size(selectedData.data,1) == 0
                warning('Aucun frame disponible dans le dataset.');
                 app.AssignvalueEditField.Enable = 'off';
                         app.ClassDropDown.Enable = 'off';
                return;
            end

            % Vérifier que le frame courant ne dépasse pas le nombre de lignes du dataset
            if currentFrame > size(selectedData.data, 1)
                warning('Le frame courant (%d) dépasse le nombre de frames disponibles (%d).', ...
                    currentFrame, size(selectedData.data, 1));
                 app.AssignvalueEditField.Enable = 'off';
                         app.ClassDropDown.Enable = 'off';
                return;
            end

            % Extraction de la valeur en fonction du type de selectedData.data
            try
                if istable(selectedData.data)
                    % Vérifier que la variable existe
                    if ~any(strcmp(varName, selectedData.data.Properties.VariableNames))
                        warning('La variable %s n''existe pas dans le dataset.', varName);
                         app.AssignvalueEditField.Enable = 'off';
                         app.ClassDropDown.Enable = 'off';
                        return;
                    end
                    val = selectedData.data{currentFrame, varName};
                elseif iscell(selectedData.data)
                    % On suppose ici que le cell array contient directement les données (sans en-tête)
                    if currentFrame > size(selectedData.data, 1)
                        warning('Le frame courant dépasse le nombre de frames dans le dataset (cell array).');
                         app.AssignvalueEditField.Enable = 'off';
                         app.ClassDropDown.Enable = 'off';
                        return;
                    end
                    % Si varName est une chaîne, il faut trouver l'indice de colonne
                    header = selectedData.data(1,:);
                    colIdx = find(strcmp(header, varName), 1);
                    if isempty(colIdx)
                        warning('La variable %s n''existe pas dans le dataset (cell array).', varName);
                         app.AssignvalueEditField.Enable = 'off';
                         app.ClassDropDown.Enable = 'off';
                        return;
                    end
                    % Si la première ligne est un header, les données commencent à la deuxième ligne
                    dataRow = currentFrame + 1;
                    if dataRow > size(selectedData.data,1)
                        warning('Le frame courant dépasse le nombre de frames (cell array avec header).');
                         app.AssignvalueEditField.Enable = 'off';
                         app.ClassDropDown.Enable = 'off';
                        return;
                    end
                    val = selectedData.data{dataRow, colIdx};
                else
                    error('Type de selectedData.data non supporté.');
                end
            catch ME
                warning('Erreur lors de l''extraction de la valeur : %s', ME.message);
                return;
            end


            % Selon le type, active l'editField ou le dropdown
            if isnumeric(val)
                app.AssignvalueEditField.Value = num2str(val);
                app.AssignvalueEditField.Enable = 'on';
                app.ClassDropDown.Enable = 'off';
            else
                if iscategorical(val)
                    cats = categories(val);
                    app.ClassDropDown.Items = cats;
                    app.ClassDropDown.Value = char(val);
                elseif iscell(val) && all(cellfun(@ischar, val))
                    app.ClassDropDown.Items = val;
                    app.ClassDropDown.Value = val{1};
                elseif ischar(val)
                    app.ClassDropDown.Items = {val};
                    app.ClassDropDown.Value = val;
                else
                    app.ClassDropDown.Items = {};
                end
                app.ClassDropDown.Enable = 'on';
                app.AssignvalueEditField.Enable = 'off';
            end
        end


        function updatePanelsLayout(app)
            % Liste des panels potentiellement affichables
            panels = {app.ROisPanel, app.DisplaysettingsPanel, app.DataSettingsPanel, app.AnnotationPanel, app.IntensityQuantificationPanel};

            % Filtrer les panels visibles
            visiblePanels = panels(cellfun(@(p) strcmp(p.Visible, 'on'), panels));

            if isempty(visiblePanels)
                return;
            end

            % Récupérer les positions initiales des panels (définies lors de createComponents)
            panelSizes = cellfun(@(p) p.Position, visiblePanels, 'UniformOutput', false);

            % Paramètres de mise en page
            margin = 10;           % marge entre panels
            maxColumnHeight = 800; % hauteur maximum d'une colonne

            currentColumn = 1;
            currentY = maxColumnHeight; % départ en haut de la colonne
            newPositions = cell(size(visiblePanels));

            for i = 1:numel(visiblePanels)
                pos = panelSizes{i};
                panelWidth = pos(3);
                panelHeight = pos(4);

                % Passage à une nouvelle colonne si nécessaire
                if currentY - panelHeight - margin < 0
                    currentColumn = currentColumn + 1;
                    currentY = maxColumnHeight;
                end

                % Calcul de la nouvelle position pour le panel courant
                newX = (currentColumn - 1) * (panelWidth + margin) + margin;
                newY = currentY - panelHeight;
                newPositions{i} = [newX, newY, panelWidth, panelHeight];

                % Mettre à jour la position verticale pour le panel suivant
                currentY = newY - margin;
            end

            % Appliquer les nouvelles positions aux panels visibles
            for i = 1:numel(visiblePanels)
                visiblePanels{i}.Position = newPositions{i};
            end

            % Calculer la largeur totale nécessaire pour la fenêtre principale
            totalWidth = currentColumn * (max(cellfun(@(p) p.Position(3), visiblePanels)) + margin) + margin;
            newMainHeight = maxColumnHeight + 2*margin;

            % Ajuster la taille de la fenêtre principale
            app.ScoreAppUIFigure.Position(3:4) = [totalWidth, newMainHeight];

            % Si l'image figure existe, l'ajuster pour qu'elle se positionne à droite
            if  isprop(app, 'ImageFigure') && ~isempty(app.ImageFigure) && ishandle(app.ImageFigure)
                % Récupérer la position de la fenêtre principale
                mainPos = app.ScoreAppUIFigure.Position;
                % Conserver la largeur et hauteur actuelles de l'image figure
                imageFigWidth = app.ImageFigure.Position(3);
                imageFigHeight = app.ImageFigure.Position(4);
                % Placer l'image figure à droite de la fenêtre principale, en conservant la même coordonnée Y
                newX = mainPos(1) + mainPos(3);
                newY = mainPos(2);
                app.ImageFigure.Position = [newX, newY, imageFigWidth, imageFigHeight];
            end
        end


        function updateDisplaySettings(app)
            % Met à jour la structure en fonction de l'état actuel des panels
            app.DisplaySettings.panels.ROisPanel = app.ROisPanel.Visible;
            app.DisplaySettings.panels.DisplaysettingsPanel = app.DisplaysettingsPanel.Visible;
            app.DisplaySettings.panels.DataSettingsPanel = app.DataSettingsPanel.Visible;
            app.DisplaySettings.panels.AnnotationPanel = app.AnnotationPanel.Visible;
            app.DisplaySettings.panels.IntensityQuantificationPanel = app.IntensityQuantificationPanel.Visible;

            % Stocke la structure dans le workspace de base

          %  assignin('base', 'DisplaySettings', app.DisplaySettings);
        end

        function updateDisplayMenu(app)
            % Met à jour le menu "Display" pour refléter l'état de visibilité des panels

            % ROIs panel
            if isprop(app, 'ROisPanel') && strcmp(app.ROisPanel.Visible, 'on')
                app.ROIsMenu.Checked = 'on';
            else
                app.ROIsMenu.Checked = 'off';
            end

            % Display settings panel
            if isprop(app, 'DisplaysettingsPanel') && strcmp(app.DisplaysettingsPanel.Visible, 'on')
                app.DisplaysettingsMenu.Checked = 'on';
            else
                app.DisplaysettingsMenu.Checked = 'off';
            end

            % Intensity quantification panel
            if isprop(app, 'IntensityQuantificationPanel') && strcmp(app.IntensityQuantificationPanel.Visible, 'on')
                app.IntensityquantificationMenu.Checked = 'on';
            else
                app.IntensityquantificationMenu.Checked = 'off';
            end

            % Annotation panel
            if isprop(app, 'AnnotationPanel') && strcmp(app.AnnotationPanel.Visible, 'on')
                app.AnnotationMenu.Checked = 'on';
            else
                app.AnnotationMenu.Checked = 'off';
            end

            % Data settings panel
            if isprop(app, 'DataSettingsPanel') && strcmp(app.DataSettingsPanel.Visible, 'on')
                app.DatasettingsMenu.Checked = 'on';
            else
                app.DatasettingsMenu.Checked = 'off';
            end

        end

   function quantifyChannelFluo(app)
    %% Récupérer la ROI sélectionnée et le projet parent 
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};

    fovtmp=selectedROI.parent;

    ok = 0;
    if isa(fovtmp, 'fov')
        f = fovtmp.parent;
        if isa(f, 'shallow')
            shallowObj = f;
            ok = 1;
        end
    end
    if ok == 0
        uialert(app.ScoreAppUIFigure, 'Could not find parent projet; quitting....', 'Error');
        return;
    end

    %% Création de la progress dialog
    d = uiprogressdlg(app.ScoreAppUIFigure, 'Title', 'Please Wait...', ...
        'Message', 'Starting compute All metrics...');
    d.Value = 0.01;


    % Étape 1 : ROI et projet parent trouvés
    d.Message = 'ROI and parent project found';
    d.Value = 0.05;


    %% Ajouter un channel correspondant à l'ellipse (mask)
    annotationName = 'ellipsoid_shape_mask';
    pix = selectedROI.findChannelID(annotationName);
    
    % Charger l'image si nécessaire
    if numel(selectedROI.image) == 0
        selectedROI.load;
    end
    
    d.Message = 'Loading image and checking mask channel';
    d.Value = 0.10;

    
    if ~isempty(pix)
        % Si le channel existe déjà, récupérer le mask existant
      %  mask = selectedROI.image(:,:,pix,:);
    else
        % Créer un nouveau mask en reprenant la taille de l'image
        sz=size(selectedROI.image);
        mask = uint16(zeros(sz(1),sz(2),1,sz(4)));
        newColor = [1 1 1];
        newIntensity = [0, 0, 0];
        newChannelName = annotationName;
        newClassName = newChannelName;
        
        % Ajouter le nouveau channel via la méthode addChannel
        selectedROI.addChannel(mask, newChannelName, newColor, newIntensity);
        
        % Consigner la création dans le log
      %  selectedROI.log(['Added new class "' newClassName '" for annotation "' annotationName '"'], 'Processing');
        
        % Après ajout, récupérer l'indice du channel créé
        pix = selectedROI.findChannelID(annotationName);
    end
    
    d.Message = 'Mask channel updated';
    d.Value = 0.20;


    %% Récupérer le masque associé à l'ellipse
    if isempty(app.EllipseIntensityProfileObj) || ~isvalid(app.EllipseIntensityProfileObj)
        uialert(app.ScoreAppUIFigure, 'Could not find the mask of the ellipse....', 'Error');
        close(d);
        return;
    end
    I = selectedROI.image(:,:,1,selectedROI.display.frame);
    mask_with_ellipsoid_pixels = createMask(app.EllipseIntensityProfileObj, I);

    
    d.Message = 'Ellipse mask computed';
    d.Value = 0.35;
    
    % Mettre à jour l'image de la ROI avec le masque (en répliquant sur 3 canaux et pour toutes les frames)
    frames = size(selectedROI.image, 4);  % Nombre de frames
% Répliquer le masque dans la 3ème dimension par 1 (monochrome) et dans la 4ème dimension par frames
selectedROI.image(:,:,pix,:) = repmat(mask_with_ellipsoid_pixels, [1 1 1 frames]);
    
    d.Message = 'ROI image updated with ellipse mask';
    d.Value = 0.45;

    
    %% Identifier ou définir l'objet processObj
    processObj = app.getProcessorByName(shallowObj,'ComputeAllMetrics');
    if isempty(processObj)
        processObj = lifespan_setupPreProcessing(shallowObj, 'ComputeAllMetrics', 12);
    end
    
    d.Message = 'Processor object configured';
    d.Value = 0.60;
 
    
    % Définir le nombre de frames à traiter
    frames = size(selectedROI.image, 4);
    
    % Construire les arguments pour le processing
    arg = {'Progress', d, 'Frames', frames};
    
    % Configurer le processObj avec le mask à utiliser pour la quantification
    processObj.processArg.mask1_name = {'ellipsoid_shape_mask', 'ellipsoid_shape_mask'};
    processObj.processArg.mask1_class=1;
    processObj.processArg.mask1_stat = true;
    processObj.processArg.mask2_stat = false;
    
    % Sélectionner les channels cochés dans la table des channels
    selectedChannelIndex = find(cell2mat(app.UIChannelTable.Data(:, 1))==1);
    cc = 1;
    for i = selectedChannelIndex
        name = app.UIChannelTable.Data{i, 2};
        processObj.processArg.(['channel' num2str(cc) '_name']) = {name name};
        cc = cc + 1;
        if cc > 4  % only up to 4 channels are considered
            break;
        end
    end
    
    d.Message = 'Starting processing...';
    d.Value = 0.80;

    selectedROI.save;
    processData(processObj, selectedROI, arg{:});
    
    
    d.Message = 'Processing complete';
    d.Value = 0.95;
    
    % draw the new datatable 
    displayData(app);
    displaySubData(app);

    % reload roi because process data clears out the data. 
    selectedROI.load;

    % Finalisation de la progress dialog
    d.Value = 1;
    pause(0.2);
    close(d);

end


        % must define a channel for elliptical mask
        % setparameters for processor : channels, segmentation mask etc...


    function procObj = getProcessorByName(app, shallowObj, targetName)
        % getProcessorByName recherche dans app.shallowObj.processing.processor
        % l'instance dont le champ strid, dénué du suffixe "_XX", correspond à targetName.
        %
        % INPUT :
        %   app        - l'objet app contenant app.shallowObj.processing.processor
        %   targetName - le nom de base à rechercher (exemple : 'myProcessor')
        %
        % OUTPUT :
        %   procObj    - l'instance trouvée, ou [] si aucune correspondance n'est trouvée

        procObj = [];
        % Vérifier que la propriété processor existe et n'est pas vide
        if ~isfield(shallowObj.processing, 'processor') || isempty(shallowObj.processing.processor)
            return;
        end

        allProc = shallowObj.processing.processor;
        % Construire un pattern regex pour accepter targetName seul ou suivi de _ et de chiffres
        pattern = ['^', regexptranslate('escape', targetName), '(_\d+)?$'];

        for k = 1:numel(allProc)
            if isprop(allProc(k), 'strid')
                if ~isempty(regexp(allProc(k).strid, pattern, 'once'))
                    procObj = allProc(k);
                    return;
                end
            end
        end
    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roiobj)
            % Rendre visibles les panels souhaités


            % Récupérer les réglages de visibilité depuis le workspace de base, s'ils existent
            try
                app.DisplaySettings = evalin('base', 'DisplaySettings');
            catch
                % Sinon, définir des réglages par défaut
                app.DisplaySettings.panels.ROisPanel = 'on';
                app.DisplaySettings.panels.DisplaysettingsPanel = 'on';
                app.DisplaySettings.panels.DataSettingsPanel = 'off';
                app.DisplaySettings.panels.AnnotationPanel = 'off';
                app.DisplaySettings.panels.IntensityQuantificationPanel = 'off';
                % Stocker dans le workspace de base
                assignin('base', 'DisplaySettings', app.DisplaySettings);
            end

            % Appliquer ces réglages dans l'interface
            app.ROisPanel.Visible = app.DisplaySettings.panels.ROisPanel;
            app.DisplaysettingsPanel.Visible = app.DisplaySettings.panels.DisplaysettingsPanel;
            app.DataSettingsPanel.Visible = app.DisplaySettings.panels.DataSettingsPanel;

            app.AnnotationPanel.Visible = app.DisplaySettings.panels.AnnotationPanel;
            app.IntensityQuantificationPanel.Visible = app.DisplaySettings.panels.IntensityQuantificationPanel;

            app.isthedefautcolorCheckBox.Value=true;

            app.updatePanelsLayout();
            % Mettre à jour le DisplayMenu
            updateDisplayMenu(app);
            checkOrCreateImageFigure(app);

            % load shortcut keys 
            pth=userpath;
if ispc
fle= fullfile(pth,'Detecdiv/userprefs.mat');
else
tmpfile=getenv("HOME");
fle=fullfile(strcat(tmpfile,'/Detecdiv'),'userprefs.mat');
end

if exist(fle)
    load(fle) % loads userprefs variable
    keys=textscan(userprefs.roi_view_shortcut_keys,'%s');
    keys=keys{1};
    keys=keys';


    specialkeys={};
    tmp=userprefs.roi_view_corr_shortcut_keys;  tmp=textscan(tmp,'%s');   tmp=tmp{1}; tmp=tmp'; specialkeys{1}=tmp;
    tmp=userprefs.roi_view_bounds_shortcut_keys;  tmp=textscan(tmp,'%s');   tmp=tmp{1}; tmp=tmp'; specialkeys{2}=tmp;
    tmp=userprefs.roi_view_frames_jump_size;  tmp=textscan(tmp,'%s');   tmp=tmp{1}; tmp=tmp'; specialkeys{3}=tmp;
    tmp=userprefs.painting_fill_holes_shortcut;  tmp=textscan(tmp,'%s');   tmp=tmp{1}; tmp=tmp'; specialkeys{4}=tmp;
    tmp=userprefs.painting_transparency_shortcut;  tmp=textscan(tmp,'%s');   tmp=tmp{1}; tmp=tmp'; specialkeys{5}=tmp;

    app.specialkeys=specialkeys;
    app.keys=keys;

else % structure must me created
    errordlg('Could not file the shortcut preferences; Please reset user preferences before launching this window again!,Error');
    close
    return;
end


            app.addROI(roiobj);


        end

        % Callback function
        function DisplaysettingsMenuSelected(app, event)

        end

        % Callback function
        function ROIsMenuSelected(app, event)

        end

        % Callback function
        function DatasettingsMenuSelected(app, event)

        end

        % Callback function
        function DataMenuSelected(app, event)

        end

        % Callback function
        function DisplaysettingsMenuSelected2(app, event)

        end

        % Menu selected function: ROIsMenu
        function ROIsMenuSelected2(app, event)
            app.ROisPanel.Visible = app.toggleVisibility(app.ROIsMenu);
            % app.resizeUIImageAxes();  % Ajuster UIImageAxes
            app.updatePanelsLayout();  % Recalcule la disposition des panel
            app.updateDisplaySettings();
        end

        % Menu selected function: DatasettingsMenu
        function DatasettingsMenuSelected2(app, event)
            app.DataSettingsPanel.Visible = app.toggleVisibility(app.DatasettingsMenu);
            %app.resizeUIImageAxes();  % Ajuster UIImageAxes
            app.updatePanelsLayout();
            app.updateDisplaySettings();
        end

        % Menu selected function: DisplaysettingsMenu
        function DisplaysettingsMenuSelected3(app, event)
            app.DisplaysettingsPanel.Visible = app.toggleVisibility(app.DisplaysettingsMenu);
            %   app.resizeUIImageAxes();  % Ajuster UIImageAxes
            app.updatePanelsLayout();
            updateDisplaySettings(app);
        end

        % Selection changed function: UIChannelTable
        function UIChannelTableSelectionChanged(app, event)
            selection = app.UIChannelTable.Selection;
            % Vérifier si une ROI est sélectionnée
            if isempty(app.content.ROIList)
                app.SelectedChannelLabel.Text = 'Selected Channel: None';
                return;
            end

            % Trouver la ROI actuellement sélectionnée
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                app.SelectedChannelLabel.Text = 'Selected Channel: None';
                return;
            end

            % Vérifier si un canal est sélectionné dans la table
            selectedChannelIndex = app.UIChannelTable.Selection;
            if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
                app.SelectedChannelLabel.Text = 'Selected Channel: None';
                return;
            end

            % Récupérer le nom du canal sélectionné
            selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2};

            % Mettre à jour le label avec le nom du canal sélectionné
            app.SelectedChannelLabel.Text = sprintf('Selected Channel: %s', selectedChannelName);

            % Mettre à jour les sliders et le color picker pour refléter les paramètres du canal
            displayChannelFeatures(app);
            displayChannelFeatures(app);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)

            % Récupérer la valeur actuelle du slider depuis l'événement (en pourcentage : 100 à 500)
            zoomPercent = event.Value;
            zoomFactor = zoomPercent / 100;  % 1 pour 100%, 5 pour 500%
            app.PreviousZoomFactor=zoomFactor;

            % Si le slider est exactement à 100, on réinitialise la baseline
            if abs(zoomPercent - 100) < 1e-3
                app.OriginalXLim = app.ImageAxes.XLim;
                app.OriginalYLim = app.ImageAxes.YLim;
            end

            % Si la baseline n'est pas encore définie, l'initialiser
            if isempty(app.OriginalXLim) || isempty(app.OriginalYLim)
                app.OriginalXLim = app.ImageAxes.XLim;
                app.OriginalYLim = app.ImageAxes.YLim;
            end

            % Toujours utiliser le centre actuel de l'axe pour le zoom "sur place"
            center = [mean(app.ImageAxes.XLim), mean(app.ImageAxes.YLim)];

            % Définir la dimension de référence (baseline) en se basant sur la vue à 100%
            baselineWidth  = app.OriginalXLim(2) - app.OriginalXLim(1);
            baselineHeight = app.OriginalYLim(2) - app.OriginalYLim(1);

            % Calculer la nouvelle largeur et hauteur selon le facteur de zoom
            newWidth  = baselineWidth / zoomFactor;
            newHeight = baselineHeight / zoomFactor;

            % Calculer les nouvelles limites en centrant la vue autour du centre actuel
            newXLim = [center(1) - newWidth/2, center(1) + newWidth/2];
            newYLim = [center(2) - newHeight/2, center(2) + newHeight/2];

            % Mettre à jour les limites de l'axe de l'image
            set(app.ImageAxes, 'XLim', newXLim, 'YLim', newYLim);

            % Gérer le mode Pan : désactiver si zoomFactor est 1, sinon activer
            if zoomFactor < 1.1
                pan(app.ImageFigure, 'off');
                app.PanButton.Value=0;
            else
                pan(app.ImageFigure, 'on');
                app.PanButton.Value=1;
            end

        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;

            % Récupérer la nouvelle valeur du slider et l'arrondir
            newFrame = round(event.Value);

            % Vérifier qu'une ROI est présente
            if isempty(app.content.ROIList)
                return;
            end

            % Trouver l'index de la ROI actuellement sélectionnée (celle cochée dans la table)
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Obtenir le nombre total de frames pour cette ROI
            numFrames = size(selectedROI.image, 4);
            % S'assurer que newFrame est dans l'intervalle valide
            if newFrame < 1
                newFrame = 1;
            elseif newFrame > numFrames
                newFrame = numFrames;
            end

            % Mettre à jour le numéro de frame dans la ROI
            selectedROI.display.frame = newFrame;
            app.FrameEditField.Value=selectedROI.display.frame;

            % Mettre à jour l'affichage (image et histogramme) en mode "refresh"
            score_display(app, 'refresh');

        end

        % Value changing function: WeightSlider
        function WeightSliderValueChanging(app, event)
            changingValue = event.Value;

            % Vérifier si une ROI est sélectionnée
            if isempty(app.content.ROIList)
                return;
            end

            % Trouver l'index de la ROI actuellement sélectionnée
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                return;
            end

            % Récupérer la ROI sélectionnée
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Vérifier si un canal est sélectionné
            selectedChannelIndex = app.UIChannelTable.Selection;
            if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
                return;
            end

            % Trouver l'index réel du canal dans colorChannels
            channelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2}; % Nom du canal
            channelIndex = find(strcmp(selectedROI.display.channel, channelName), 1);

            if isempty(channelIndex)
                return;
            end

            % Mettre à jour la valeur du poids dans selectedROI
            newWeight = max(0.01,event.Value); % Récupérer la nouvelle valeur du slider
            selectedROI.display.intensity(channelIndex, 1) = newWeight;

            % Mettre à jour la colonne "Weight" dans la table des channels
            updateChannelTable(app, selectedROI);
            % app.UIChannelTable.Data{selectedChannelIndex(1), 5} = sprintf('%.2f', newWeight);

        end

        % Callback function: ChannelColorPicker
        function ChannelColorPickerValueChanged(app, event)
            value = app.ChannelColorPicker.Value;
            % Vérifier si une ROI est sélectionnée
            if isempty(app.content.ROIList)
                return;
            end

            % Trouver l'index de la ROI actuellement sélectionnée
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                return;
            end

            % Récupérer la ROI sélectionnée
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Vérifier si un canal est sélectionné dans la table des canaux
            selectedChannelIndex = app.UIChannelTable.Selection;
            if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
                return;
            end

            % Trouver l'index réel du canal dans colorChannels
            channelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2}; % Nom du canal
            channelIndex = find(strcmp(selectedROI.display.channel, channelName), 1);

            if isempty(channelIndex)
                return;
            end

            % Récupérer la nouvelle couleur du color picker
            newRGB = event.Value;

            % Mettre à jour la couleur RGB dans selectedROI
            selectedROI.display.rgb(channelIndex, :) = newRGB;

            % Mettre à jour la colonne "RGB" dans la table des canaux
            updateChannelTable(app, selectedROI);
            %app.UIChannelTable.Data{selectedChannelIndex(1), 4} = sprintf('%.2f %.2f %.2f', newRGB);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)

            % Récupérer la nouvelle valeur entrée par l'utilisateur et l'arrondir
            newFrame = round(app.FrameEditField.Value);

            % Vérifier qu'une ROI est présente
            if isempty(app.content.ROIList)
                return;
            end

            % Récupérer la ROI sélectionnée
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Déterminer le nombre de frames disponibles
            numFrames = size(selectedROI.image, 4);

            % Contraindre newFrame à l'intervalle [1, numFrames]
            if newFrame < 1
                newFrame = 1;
            elseif newFrame > numFrames
                newFrame = numFrames;
            end

            % Mettre à jour le slider et la propriété de la ROI
            app.FrameSlider.Value = newFrame;
            selectedROI.display.frame = newFrame;

            % Mettre à jour le champ d'édition (au cas où il ait été ajusté)
            app.FrameEditField.Value = newFrame;

            % Rafraîchir l'affichage (image et histogramme)
            score_display(app, 'refresh');
        end

        % Key press function: ScoreAppUIFigure
        function ScoreAppUIFigureKeyPress(app, event)
            % Gestion de la touche Suppr pour supprimer la ROI en cours

            if strcmp(event.Key, 'delete')

                % Récupérer l'indice de la ROI actuellement sélectionnée
                roiData = app.UIROITable.Data;
                if isempty(roiData)
                    return;
                end
                selectedIndex = find(cell2mat(roiData(:,1)), 1);
                if ~isempty(selectedIndex)
                    % Supprimer la ROI sélectionnée de la liste
                    app.content.ROIList(selectedIndex) = [];

                    % Mettre à jour le tableau des ROI
                    app.displayROIs();

                    % Sélectionner la ROI précédente si elle existe
                    if selectedIndex > 1 && ~isempty(app.UIROITable.Data)
                        newIndex = selectedIndex - 1;
                        % Remettre toutes les cases à false
                        tableData = app.UIROITable.Data;
                        for i = 1:size(tableData,1)
                            tableData{i,1} = false;
                        end
                        tableData{newIndex,1} = true;
                        app.UIROITable.Data = tableData;
                    end
                end

                % Si aucune ROI ne reste, fermer la fenêtre principale (et l'app)
                if isempty(app.content.ROIList)
                    delete(app.ScoreAppUIFigure);
                else
                    % Sinon, rafraîchir l'affichage
                    score_display(app, 'refresh');
                end
                return;  % Ne pas traiter d'autres touches
            end

            specialkeys=app.specialkeys;
            keys=app.keys;


            % Gestion des autres touches (exemple pour les flèches)
            specialevent=false;

            switch event.Key
                case 'rightarrow'
                    increment = 1;
                    specialevent=true;
                case 'leftarrow'
                    increment = -1;
                    specialevent=true;
                case specialkeys{3}{2}
                     increment = 10;
                     specialevent=true;
                case specialkeys{3}{1}
                     increment = -10;
                     specialevent=true;
                otherwise
                  %  return;
            end


           selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};
            currentFrame = selectedROI.display.frame;

            if specialevent % frame events
            numFrames = size(selectedROI.image, 4);
            newFrame = max(1, min(currentFrame + increment, numFrames));
            selectedROI.display.frame = newFrame;
            app.FrameSlider.Value = newFrame;
            app.FrameEditField.Value = newFrame;
            score_display(app, 'refresh'); % also refreshes the display of the data
            return;
            else % class allocation key

                selectedClassIndex=[];
                 for i=1:numel(app.keys) % display the selected class for the current image
                       if strcmp(event.Key,keys{i})
                           selectedClassIndex=i;
                           break;
                       end
              end

              if numel(selectedClassIndex)==0
                  return;
              end


            % keyboard shortcut to edit data 
            if isempty(app.UIDataTable.Selection) || isempty(app.UISubDataTable.Selection)
                warning('no data are selected in the table');
                return;
            end
            dsIndex = app.UIDataTable.Selection(1);
            subSel = app.UISubDataTable.Selection(1);
            varName = app.UISubDataTable.Data{subSel,2};

            % Mettre à jour le dataset
            selectedData =  selectedROI.data(dsIndex);

             % class allocation events 
            classList = app.ClassDropDown.Items

              for i=1:numel(app.keys) % display the selected class for the current image
                       if strcmp(event.Key,keys{i})
                           selectedClassIndex=i;
                           break;
                       end
              end

              if selectedClassIndex> numel(classList)
                  warning('selected key does not correspond to a class')
                    return;
              else
             newValue=classList{selectedClassIndex};
              end

            if iscategorical(selectedData.data{currentFrame, varName})
                if ischar(newValue)
                    newValue = cellstr(newValue);
                end
                selectedData.data{currentFrame, varName} = categorical(newValue);
            else
                % Sinon, affecter directement (par exemple pour un cell array de chaînes)
                selectedData.data{currentFrame, varName} = newValue;
            end
           selectedROI.data(dsIndex) = selectedData;
            end

           score_display(app, 'refresh'); % also refreshes the display of the data


        end

        % Value changed function: OverlayCheckBox
        function OverlayCheckBoxValueChanged(app, event)
            value = app.OverlayCheckBox.Value;
            app.LineIntensityprofileButton.Value=false;
            app.ShapeButton.Value=false;
            LineIntensityprofileButtonValueChanged2(app, event);
            ShapeButtonValueChanged(app,event);
            score_display(app, 'slow');
        end

        % Callback function
        function LineIntensityprofileButtonValueChanged(app, event)


        end

        % Value changed function: LineIntensityprofileButton
        function LineIntensityprofileButtonValueChanged2(app, event)
            value = app.LineIntensityprofileButton.Value;

            if app.LineIntensityprofileButton.Value
                % Désactiver le mode ellipse
                app.ShapeButton.Value = false;
                ShapeButtonValueChanged(app, []);

                % Bouton activé : afficher la ligne de profil
                if ~isempty(app.LineIntensityProfileLine) && isvalid(app.LineIntensityProfileLine)
                    % La ligne existe déjà ; vous pouvez éventuellement la laisser telle quelle
                    % ou la mettre au premier plan (par exemple, en la recréant si nécessaire)
                else
                    % Déterminer la position initiale de la ligne
                    if ~isempty(app.LineProfilePosition)
                        pos = app.LineProfilePosition;  % Utiliser la position mémorisée
                    else


                        % Position par défaut : ligne horizontale au milieu de l'axe de l'image
                        xLimits = app.OverlayAxes.XLim;
                        yLimits = app.OverlayAxes.YLim;
                        pos = [xLimits(1)+10, mean(yLimits); xLimits(2)-10, mean(yLimits)];
                    end
                    % Créer la ligne sur l'axe de l'image
                    app.LineIntensityProfileLine = imline(app.OverlayAxes, pos);
                    % Ajouter un callback pour mettre à jour le profil quand la ligne est déplacée
                    addNewPositionCallback(app.LineIntensityProfileLine, @(newPos) score_updateIntensityProfile(app, newPos));
                end
                % Mise à jour immédiate du profil d'intensité avec la position actuelle de la ligne
                score_updateIntensityProfile(app, getPosition(app.LineIntensityProfileLine));
            else
                % Bouton désactivé : mémoriser la position et supprimer la ligne
                if ~isempty(app.LineIntensityProfileLine) && isvalid(app.LineIntensityProfileLine)
                    app.LineProfilePosition = getPosition(app.LineIntensityProfileLine);
                    delete(app.LineIntensityProfileLine);  % Supprime la ligne
                    app.LineIntensityProfileLine = [];
                end
                % Effacer le plot du profil d'intensité
                cla(app.UIProfileAxes);
            end


        end

        % Value changed function: ShapeButton
        function ShapeButtonValueChanged(app, event)
            value = app.ShapeButton.Value;

            if value
                app.LineIntensityprofileButton.Value = false;
                LineIntensityprofileButtonValueChanged2(app, []);

                % Bouton activé : afficher ou créer l'ellipse de profil
                if isempty(app.EllipseIntensityProfileObj) || ~isvalid(app.EllipseIntensityProfileObj)
                    % Déterminer la position initiale de l'ellipse
                    if ~isempty(app.EllipseProfilePosition)
                        % Utiliser la position mémorisée (format [x y width height])
                        pos = app.EllipseProfilePosition;
                        center = [pos(1) + pos(3)/2, pos(2) + pos(4)/2];
                        semiAxes = [pos(3)/2, pos(4)/2];
                    else
                        % Position par défaut : ellipse centrée dans l'axe de l'image
                        xLimits = app.ImageAxes.XLim;
                        yLimits = app.ImageAxes.YLim;
                        width  = (xLimits(2)-xLimits(1)) * 0.5;
                        height = (yLimits(2)-yLimits(1)) * 0.5;
                        center = [mean(xLimits), mean(yLimits)];
                        semiAxes = [width/4, height/4];
                    end
                    % Créer l'ellipse en spécifiant 'Center' et 'SemiAxes'
                    app.EllipseIntensityProfileObj = drawellipse(app.OverlayAxes, ...
                        'Center', center, 'SemiAxes', semiAxes);
                    % Ajouter des listeners pour mettre à jour le profil en temps réel
                    addlistener(app.EllipseIntensityProfileObj, 'MovingROI', @(src,evt) score_updateEllipticalProfile(app, src));
                    addlistener(app.EllipseIntensityProfileObj, 'ROIMoved', @(src,evt) score_updateEllipticalProfile(app, src));

                    cm = app.EllipseIntensityProfileObj.ContextMenu;

                    % Supprimer les items par défaut "Fix Aspect Ratio" et "Delete Ellipse"
                    delete(findall(cm, 'Label', 'Delete Ellipse'));

                    % Ajouter un nouvel item au menu contextuel avec une callback personnalisée
                    uimenu(cm, 'Label', 'Quantify channel fluorescence over time', ...
                        'Callback', @(src, event) quantifyChannelFluo(app));

                end
                % Mise à jour immédiate du profil avec l'ellipse actuelle
                score_updateEllipticalProfile(app, app.EllipseIntensityProfileObj);
            else
                % Bouton désactivé : mémoriser la position et supprimer l'ellipse
                if ~isempty(app.EllipseIntensityProfileObj) && isvalid(app.EllipseIntensityProfileObj)
                    % Pour récupérer la position, on utilise les propriétés 'Center' et 'SemiAxes'
                    center = app.EllipseIntensityProfileObj.Center;
                    semiAxes = app.EllipseIntensityProfileObj.SemiAxes;
                    app.EllipseProfilePosition = [center(1)-semiAxes(1), center(2)-semiAxes(2), 2*semiAxes(1), 2*semiAxes(2)];
                    delete(app.EllipseIntensityProfileObj);
                    app.EllipseIntensityProfileObj = [];
                end
                % Effacer le plot du profil
                cla(app.UIProfileAxes);

            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Récupérer la ROI actuellement sélectionnée via la table des ROIs
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Récupérer la taille de l'image brute
            imgSize = size(selectedROI.image);  % [height, width, channels, frames]
            imgHeight = imgSize(1);
            imgWidth  = imgSize(2);

            % Vérifier si l'overlay est actif
            if app.OverlayCheckBox.Value
                % En mode overlay, on affiche l'image composite qui occupe la taille de l'image
                newOrigXLim = [1, imgWidth];
                newOrigYLim = [1, imgHeight];
            else
                % En mode non-overlay, l'image de chaque canal est affichée empilée verticalement.
                % Déterminer le nombre de canaux affichés via la table (colonne 1)
                if isempty(app.UIChannelTable.Data)
                    newOrigXLim = [1, imgWidth];
                    newOrigYLim = [1, imgHeight];
                else
                    checkboxValues = cell2mat(app.UIChannelTable.Data(:,1));
                    numDisplayed = sum(checkboxValues);
                    if numDisplayed < 1
                        numDisplayed = 1;
                    end
                    newOrigXLim = [1, imgWidth];
                    newOrigYLim = [1, numDisplayed * imgHeight];
                end
            end

            % Réinitialiser l'axe UIImageAxes aux nouveaux paramètres
            set(app.ImageAxes, 'XLim', newOrigXLim, 'YLim', newOrigYLim);

            % Mettre à jour les propriétés de référence du zoom
            app.OriginalXLim = newOrigXLim;
            app.OriginalYLim = newOrigYLim;
            app.PreviousZoomFactor = 1;

            % Réinitialiser le ZoomSlider à 100%
            app.ZoomSlider.Value = 100;

            % Désactiver le mode Pan
            pan(app.ImageFigure, 'off'); % pour activer

            % Optionnel : si vous utilisez un bouton "Restore View" intégré à l'axe,
            % vous pouvez également réinitialiser son état ici.

        end

        % Callback function
        function PanOFFButtonValueChanged(app, event)

        end

        % Value changing function: LowHighDisplaySlider
        function LowHighDisplaySliderValueChanging(app, event)
            changingValue = event.Value;
            % event.Value est un vecteur à 2 éléments : [lowVal, highVal] (en échelle logarithmique)
            lowLogVal = event.Value(1);
            highLogVal = event.Value(2);

            % Convertir en valeurs linéaires en tenant compte que l'échelle est basée sur 65535
            newLow = 10^(lowLogVal) / 65535;
            newHigh = 10^(highLogVal) / 65535;

            % Récupérer la ROI et le canal sélectionné
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Vérifier que l'utilisateur a sélectionné un canal dans la table des channels
            selectedChannelIndex = app.UIChannelTable.Selection;
            if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
                return;
            end
            selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2};
            channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);
            if isempty(channelIndex)
                return;
            end

            % Mettre à jour la propriété displaylim pour ce canal avec les nouvelles valeurs
            selectedROI.display.displaylim(:, channelIndex) = [newLow; newHigh];

            % Mettre à jour la table des canaux (pour refléter les nouvelles valeurs)
            updateChannelTable(app, selectedROI);

            % Forcer la mise à jour de l'affichage (image et histogramme)
            score_display(app, 'refresh');
        end

        % Selection changed function: UIAnnotationTable
        function UIAnnotationTableSelectionChanged(app, event)
            % Récupérer la sélection de la table d'annotation
            selection = app.UIAnnotationTable.Selection;

            % Vérifier qu'une ROI est sélectionnée via la table des ROIs
            if isempty(app.content.ROIList)
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Vérifier qu'une ligne est sélectionnée dans la table d'annotation
            selectedRow = app.UIAnnotationTable.Selection;
            if isempty(selectedRow) || isempty(selectedRow(1))
                return;
            end

            % Récupérer les parties "Annotation" (colonne 2) et "Class" (colonne 3)
            annotationPart = app.UIAnnotationTable.Data{selectedRow(1), 2};
            classPart = app.UIAnnotationTable.Data{selectedRow(1), 3};
            % Concaténer avec un underscore pour reconstruire le nom complet du canal
            fullChannelName = [annotationPart, '_', classPart];

            % Trouver l'indice réel du canal dans selectedROI.display.channel
            channelIndex = find(strcmp(selectedROI.display.channel, fullChannelName), 1);
            if isempty(channelIndex)
                return;
            end

            % Mettre à jour le MaskColorPicker avec la couleur associée dans selectedROI.display.rgb
            app.MaskColorPicker.Value = selectedROI.display.rgb(channelIndex, :);

            % Mettre à jour le label avec le nom complet du canal sélectionné
            app.IndexChannelLabel.Text = sprintf(' %s', fullChannelName);

            score_display(app, 'refresh');

        end

        % Callback function: MaskColorPicker
        function MaskColorPickerValueChanged(app, event)
            newColor = event.Value;  % La nouvelle couleur sélectionnée, par exemple [R G B]

            % Vérifier qu'une ROI est sélectionnée
            if isempty(app.content.ROIList)
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            selectedROI = app.content.ROIList{selectedROIIndex};

            % Vérifier qu'une ligne est sélectionnée dans la table d'annotation
            selectedRow = app.UIAnnotationTable.Selection;
            if isempty(selectedRow) || isempty(selectedRow(1))
                return;
            end

            % Récupérer les parties "Annotation" et "Class" depuis les colonnes 2 et 3
            annotationPart = app.UIAnnotationTable.Data{selectedRow(1), 2};
            classPart = app.UIAnnotationTable.Data{selectedRow(1), 3};

            % Reconstruire le nom complet du canal
            fullChannelName = [annotationPart, '_', classPart];

            % Trouver l'indice réel du canal dans selectedROI.display.channel
            channelIndex = find(strcmp(selectedROI.display.channel, fullChannelName), 1);
            if isempty(channelIndex)
                return;
            end

            % Mettre à jour la couleur du canal dans selectedROI.display.rgb
            selectedROI.display.rgb(channelIndex, :) = newColor;

            % Rafraîchir l'affichage général (image, histogrammes, etc.)
            score_display(app, 'refresh');

        end

        % Value changed function: Transparency
        function TransparencyValueChanged(app, event)
            value = app.Transparency.Value;
            score_display(app,'refresh');
        end

        % Value changing function: Transparency
        function TransparencyValueChanging(app, event)
            changingValue = event.Value;
            app.Transparency.Value=changingValue ;
            score_display(app,'refresh');
        end

        % Menu selected function: IntensityquantificationMenu
        function IntensityquantificationMenuSelected(app, event)
            app.IntensityQuantificationPanel.Visible = app.toggleVisibility(app.IntensityquantificationMenu);
            % app.resizeUIImageAxes();  % Ajuster UIImageAxes
            app.updatePanelsLayout();  % Recalcule la disposition des panel
            updateDisplaySettings(app)
        end

        % Menu selected function: AnnotationMenu
        function AnnotationMenuSelected(app, event)
            app.AnnotationPanel.Visible = app.toggleVisibility(app.AnnotationMenu);
            % app.resizeUIImageAxes();  % Ajuster UIImageAxes
            app.updatePanelsLayout();  % Recalcule la disposition des panel
            updateDisplaySettings(app)
        end

        % Close request function: ScoreAppUIFigure
        function ScoreAppUIFigureCloseRequest(app, event)
            % Supprimer la figure annexe si elle existe et est valide
            if isprop(app, 'ImageFigure') && isvalid(app.ImageFigure)

                delete(app.ImageFigure);
            end

            % Supprimer l'application
            delete(app);

        end

        % Value changed function: PanButton
        function PanButtonValueChanged(app, event)
            value = app.PanButton.Value;

            if value
                pan(app.ImageFigure, 'on'); % pour activer
            else
                pan(app.ImageFigure, 'off'); % pour désactiver
            end
        end

        % Menu selected function: deleteselectedROIMenu
        function deleteselectedROIMenuSelected(app, event)
            % Récupérer l'indice de la ROI actuellement sélectionnée
            roiData = app.UIROITable.Data;

            if isempty(roiData)
                return;
            end

            selectedIndex = find(cell2mat(roiData(:,1)), 1);


            if ~isempty(selectedIndex)
                % Supprimer la ROI sélectionnée de la liste
                app.content.ROIList(selectedIndex) = [];

                % Mettre à jour le tableau des ROI
                app.displayROIs();

                % Sélectionner la ROI précédente si elle existe
                if selectedIndex > 1 && ~isempty(app.UIROITable.Data)
                    newIndex = selectedIndex - 1;
                    % Remettre toutes les cases à false
                    tableData = app.UIROITable.Data;
                    for i = 1:size(tableData,1)
                        tableData{i,1} = false;
                    end
                    tableData{newIndex,1} = true;
                    app.UIROITable.Data = tableData;
                end
            end

            % Si aucune ROI ne reste, fermer la fenêtre principale (et l'app)
            if isempty(app.content.ROIList)
                delete(app.ScoreAppUIFigure);
            else
                % Sinon, rafraîchir l'affichage
                score_display(app, 'refresh');
            end
            return;  % Ne pas traiter d'autres touches
        end

        % Value changed function: PaintButton
        function PaintButtonValueChanged(app, event)
            value = app.PaintButton.Value;

            if value
                %           app.OverlayAxes.ButtonDownFcn = @(src, event) score_paintOverlay(src, event, app);
                app.ImageFigure.WindowButtonDownFcn = @(src, event) score_paintOverlay(src, event, app);
                app.SelectedobjectindexEditField.Enable="on";
            else
                app.ImageFigure.WindowButtonDownFcn=[];
                app.SelectedobjectindexEditField.Enable="off";
                if isprop(app, 'SelectedObjectRectangle') && ~isempty(app.SelectedObjectRectangle) && isgraphics(app.SelectedObjectRectangle)
                    delete(app.SelectedObjectRectangle);
                end
            end

            score_display(app, 'refresh');
        end

        % Value changed function: SelectedobjectindexEditField
        function SelectedobjectindexEditFieldValueChanged(app, event)
            newVal = app.SelectedobjectindexEditField.Value;

            % Récupérer la nouvelle valeur saisie (en chaîne) et la convertir en nombre

            if isnan(newVal)
                uialert(app.ScoreAppUIFigure, 'Veuillez entrer une valeur numérique valide.', 'Erreur');
                return;
            end

            % Vérifier qu'une ROI est présente et récupère la ROI sélectionnée
            if isempty(app.content.ROIList)
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            % Récupérer le canal d'annotation sélectionné via la table UIAnnotationTable
            selectedRow = app.UIAnnotationTable.Selection;
            if isempty(selectedRow) || isempty(selectedRow(1))
                uialert(app.ScoreAppUIFigure, 'Aucun canal d''annotation sélectionné.', 'Erreur');
                return;
            end
            selectedChannelName = app.UIAnnotationTable.Data{selectedRow(1), 2};
            channelIdx = find(strcmp(roi.display.channel, selectedChannelName), 1);
            if isempty(channelIdx)
                uialert(app.ScoreAppUIFigure, 'Canal d''annotation invalide.', 'Erreur');
                return;
            end
            % Obtenir l'indice du canal dans l'image
            pix = roi.findChannelID(roi.display.channel{channelIdx});

            % Récupérer la valeur actuelle (l'ancien label) de l'objet sélectionné
            if ~isprop(app, 'SelectedObjectLabel') || isempty(app.SelectedObjectLabel)
                uialert(app.ScoreAppUIFigure, 'Aucun objet n''est actuellement sélectionné.', 'Erreur');
                return;
            end
            oldLabel = app.SelectedObjectLabel;

            % Pour le frame courant, remplacer dans le masque toutes les valeurs égales à oldLabel par newVal
            currentFrame = roi.display.frame;
            temp = roi.image(:, :, pix, currentFrame); % masque actuel (par exemple, de type uint16)
            temp(temp == oldLabel) = newVal;
            roi.image(:, :, pix, currentFrame) = temp;

            % Mettre à jour l'affichage (ce qui actualisera l'overlay et le composite)
            score_display(app, 'refresh');

            % Stocker le nouveau label dans la propriété de l'application
            app.SelectedObjectLabel = newVal;
        end

        % Button pushed function: NewAnnotationButton
        function NewAnnotationButtonPushed(app, event)
            % Vérifier qu'une ROI est sélectionnée
            if isempty(app.content.ROIList)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            % Créer une nouvelle matrice d'annotation vide
            % Dimensions : même hauteur, largeur et nombre de frames que l'image de la ROI, et 1 canal
            [imgH, imgW, ~, numFrames] = size(roi.image);
            newMatrix = zeros(imgH, imgW, 1, numFrames, 'uint16');

            % Déterminer le numéro par défaut pour le nouveau channel d'annotation
            count = 0;
            for i = 1:numel(roi.display.channel)
                % Ici, on considère qu'un channel est une annotation si la somme des intensités vaut 0
                if sum(roi.display.intensity(i, :)) == 0
                    count = count + 1;
                end
            end
            % Le nouveau channel portera un nom comme "NewAnnotation1", "NewAnnotation2", etc.
            defaultAnnotationName = ['NewAnnotation', num2str(count+1)];
            defaultClass = 'class1';
            % Concaténer pour obtenir le nom complet du channel. Pour respecter le parsing existant (basé sur le premier '_'),
            % il est préférable que le nom d'annotation n'inclue pas d'underscore.
            fullChannelName = [defaultAnnotationName, '_', defaultClass];

            % Définir la couleur par défaut et l'intensité à 0 pour un channel d'annotation
            defaultRgb = [1 1 1];         % Couleur par défaut (blanc)
            defaultIntensity = [0 0 0];     % Intensité nulle pour indiquer qu'il s'agit d'une annotation

            % Ajouter le nouveau channel via la méthode dédiée
            roi.addChannel(newMatrix, fullChannelName, defaultRgb, defaultIntensity);

            % Mettre à jour la table d'annotations (elle sera reconstruite dans displayROIChannels)
            displayROIChannels(app);

        end

        % Button pushed function: DeleteAnnnotationButton
        function DeleteAnnnotationButtonPushed(app, event)

            % Vérifier qu'une ROI est sélectionnée
            if isempty(app.content.ROIList)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            % Vérifier qu'une ligne est sélectionnée dans la table d'annotation
            selectedRow = app.UIAnnotationTable.Selection;
            if isempty(selectedRow) || isempty(selectedRow(1))
                uialert(app.ScoreAppUIFigure, 'No annotation selected', 'Error');
                return;
            end

            % Récupérer l'annotation sélectionnée (colonne 2)
            selectedAnnotation = app.UIAnnotationTable.Data{selectedRow(1), 2};
            if isempty(selectedAnnotation)
                uialert(app.ScoreAppUIFigure, 'Invalid annotation name', 'Error');
                return;
            end

            % Confirmer la suppression
            choice = questdlg(['Are you sure you want to delete annotation "' selectedAnnotation '" and all its channels?'], ...
                'Delete Annotation', 'Yes', 'No', 'No');
            if ~strcmp(choice, 'Yes')
                return;
            end

            % Identifier tous les channels dont la partie "annotation" (avant le premier underscore)
            % correspond à l'annotation sélectionnée.
            channelsToRemove = {};
            for i = 1:numel(roi.display.channel)
                channelName = roi.display.channel{i};
                pos = strfind(channelName, '_');
                if isempty(pos)
                    annotationPart = channelName;
                else
                    annotationPart = channelName(1:pos(1)-1);
                end
                if strcmp(annotationPart, selectedAnnotation)
                    channelsToRemove{end+1} = channelName;
                end
            end

            if isempty(channelsToRemove)
                uialert(app.ScoreAppUIFigure, 'No annotation channels found for deletion', 'Info');
                return;
            end

            % Supprimer chaque channel en utilisant la méthode removeChannel de roi
            for i = 1:numel(channelsToRemove)
                roi.removeChannel(channelsToRemove{i});
            end

            roi.log(['Deleted annotation "' selectedAnnotation '" with ' num2str(numel(channelsToRemove)) ' channels'], 'Processing');

            % Mettre à jour l'affichage et la table des annotations
            displayROIChannels(app);
            score_display(app, 'slow');

        end

        % Button pushed function: NewclassButton
        function NewclassButtonPushed(app, event)
            % Vérifier qu'une ROI est sélectionnée
            if isempty(app.content.ROIList)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            % Vérifier qu'une ligne est sélectionnée dans la table d'annotation
            selectedRow = app.UIAnnotationTable.Selection;
            if isempty(selectedRow) || isempty(selectedRow(1))
                uialert(app.ScoreAppUIFigure, 'Please select an annotation in the table', 'Error');
                return;
            end

            % Récupérer la partie "annotation" depuis la colonne 2
            annotationName = app.UIAnnotationTable.Data{selectedRow(1), 2};
            if isempty(annotationName)
                uialert(app.ScoreAppUIFigure, 'Invalid annotation name', 'Error');
                return;
            end

            % Parcourir les channels existants pour cette annotation afin de lister les classes déjà utilisées
            existingClasses = {};
            for i = 1:numel(roi.display.channel)
                channelName = roi.display.channel{i};
                pos = strfind(channelName, '_');
                if isempty(pos)
                    annPart = channelName;
                    classPart = '';
                else
                    annPart = channelName(1:pos(1)-1);
                    classPart = channelName(pos(1)+1:end);
                end
                if strcmp(annPart, annotationName)
                    existingClasses{end+1} = classPart; %#ok<AGROW>
                end
            end

            % Trouver le plus petit entier X tel que "classX" n'existe pas déjà
            newClassIndex = 1;
            while any(strcmp(existingClasses, ['class', num2str(newClassIndex)]))
                newClassIndex = newClassIndex + 1;
            end
            newClassName = ['class', num2str(newClassIndex)];

            % Construire le nom complet du nouveau channel
            newChannelName = [annotationName, '_', newClassName];

            % Créer une nouvelle matrice d'annotation vide
            [imgH, imgW, ~, numFrames] = size(roi.image);
            newMatrix = zeros(imgH, imgW, 1, numFrames, 'uint16');

            % Choisir une nouvelle couleur de display différente.
            % On utilise la palette "lines" avec (N+1) couleurs, N étant le nombre de classes existantes.
            cmap = lines(numel(existingClasses)+1);
            newColor = cmap(end, :);

            % Pour une annotation, l'intensité est mise à zéro (indiquant qu'il s'agit d'un channel indexé)
            newIntensity = [0, 0, 0];

            % Appeler la méthode addChannel de la ROI pour ajouter le nouveau channel
            roi.addChannel(newMatrix, newChannelName, newColor, newIntensity);

            % Consigner la création dans le log
            roi.log(['Added new class "' newClassName '" for annotation "' annotationName '"'], 'Processing');

            % Mettre à jour la table d'annotation et rafraîchir l'affichage
            displayROIChannels(app);
            score_display(app, 'slow');
        end

        % Button pushed function: DeleteclassButton
        function DeleteclassButtonPushed(app, event)
            % Vérifier qu'une ROI est sélectionnée
            if isempty(app.content.ROIList)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                uialert(app.ScoreAppUIFigure, 'No ROI selected', 'Error');
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            % Vérifier qu'une ligne est sélectionnée dans la table d'annotation
            selectedRow = app.UIAnnotationTable.Selection;
            if isempty(selectedRow) || isempty(selectedRow(1))
                uialert(app.ScoreAppUIFigure, 'No class selected', 'Error');
                return;
            end

            % Récupérer la partie "Annotation" (colonne 2) et "Class" (colonne 3)
            annotationPart = app.UIAnnotationTable.Data{selectedRow(1), 2};
            classPart = app.UIAnnotationTable.Data{selectedRow(1), 3};
            if isempty(annotationPart) || isempty(classPart)
                uialert(app.ScoreAppUIFigure, 'Invalid class selection', 'Error');
                return;
            end

            % Construire le nom complet du channel à supprimer
            fullChannelName = [annotationPart, '_', classPart];

            % Confirmer la suppression
            choice = questdlg(['Are you sure you want to delete class "' classPart '" for annotation "' annotationPart '"?'], ...
                'Delete Class', 'Yes', 'No', 'No');
            if ~strcmp(choice, 'Yes')
                return;
            end

            % Supprimer le channel en appelant la méthode removeChannel de la ROI
            roi.removeChannel(fullChannelName);

            % Consigner la suppression dans le log
            roi.log(['Deleted class "' classPart '" from annotation "' annotationPart '"'], 'Processing');

            % Mettre à jour l'affichage et la table des annotations
            displayROIChannels(app);
            score_display(app, 'slow');

            %   uialert(app.ScoreAppUIFigure, ['Class "' classPart '" deleted.'], 'Info');
        end

        % Menu selected function: SaveselectedroiMenu
        function SaveselectedroiMenuSelected(app, event)
            % Vérifier qu'il existe au moins une ROI
            if isempty(app.content.ROIList)
                uialert(app.ScoreAppUIFigure, 'No ROI available to save.', 'Error');
                return;
            end

            % Trouver l'indice de la ROI cochée dans la table des ROI
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                uialert(app.ScoreAppUIFigure, 'Please select a ROI to save.', 'Error');
                return;
            end

            % Récupérer la ROI sélectionnée
            roiObj = app.content.ROIList{selectedROIIndex};

            % Sauvegarder la ROI via la méthode dédiée
            roiObj.save();

        end

        % Cell edit callback: UIDataTable
        function UIDataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;


            % Récupérer l'index du dataset modifié
            dsIndex = indices(1);
            col = indices(2);

            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};
            if ~isprop(roi, 'data') || numel(roi.data) < dsIndex
                return;
            end

            
            % Mettre à jour le dataset en fonction de la colonne éditée
            switch col
                case 1  % Champ de sélection (show)
                    roi.data(dsIndex).show = logical(newData);

                    if newData
                        app.UIDataTable.Selection=[dsIndex 1];
                    else
                        app.UIDataTable.Selection=[];
                    end
                % case 2
                %     roi.data(dsIndex).groupid = newData;
                % case 3
                %     roi.data(dsIndex).parentid = newData;
                % case 4
                %     roi.data(dsIndex).class = newData;
                % case 5
                %     roi.data(dsIndex).type = newData;
            end

            % Réactualiser l'affichage
            displayData(app);

        end

        % Selection changed function: UIDataTable
        function UIDataTableSelectionChanged(app, event)
            selection = app.UIDataTable.Selection;
            displaySubData(app);
        end

        % Cell edit callback: UISubDataTable
        function UISubDataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            % Récupérer la sélection dans UIDataTable pour identifier le dataset courant
            selection = app.UIDataTable.Selection;
            if isempty(selection)
                return;
            end
            dsIndex = selection(1);

            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end

            roi = app.content.ROIList{selectedROIIndex};
            if ~isprop(roi, 'data') || numel(roi.data) < dsIndex
                return;
            end

            % Mettre à jour les plotProperties dans le dataset correspondant
            roi.data(dsIndex).plotProperties = app.UISubDataTable.Data;

            % Optionnel : actualiser le plot si une méthode plot est définie
            %   if isprop(roi.data(dsIndex), 'plot') && ~isempty(roi.data(dsIndex).plot)

            %  end

            displayData(app);

        end

        % Selection changed function: UISubDataTable
        function UISubDataTableSelectionChanged(app, event)
            selection = app.UISubDataTable.Selection;

            % Récupérer la sélection dans la table de sous-données
            subSel = app.UISubDataTable.Selection;
            % Récupérer la sélection dans la table principale des datasets
            mainSel = app.UIDataTable.Selection;

            % Si aucune série de données n'est sélectionnée, on affiche un message
            if isempty(mainSel)
                app.SelecteddataLabel.Text = 'No dataset selected';
                return;
            end

            % Récupérer le nom de la série de données (colonne 2 dans UIDataTable)
            mainDataName = app.UIDataTable.Data{mainSel(1), 2};

            % Si aucune sous-donnée n'est sélectionnée, afficher un message approprié
            if isempty(subSel)
                app.SelecteddataLabel.Text = sprintf('Dataset: %s | No sub-data selected', mainDataName);
            else
                % Récupérer le nom de la sous-donnée (colonne 2 dans UISubDataTable)
                subDataName = app.UISubDataTable.Data{subSel(1), 2};
                app.SelecteddataLabel.Text = sprintf('Dataset: %s | Variable: %s', mainDataName, subDataName);
            end

            updateAssignValueControls(app);

        end

        % Value changed function: AssignvalueEditField
        function AssignvalueEditFieldValueChanged(app, event)
            newStr = app.AssignvalueEditField.Value;

            % Récupérer la ROI, le dataset et la variable sélectionnée
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            if isempty(app.UIDataTable.Selection) || isempty(app.UISubDataTable.Selection)
                return;
            end
            dsIndex = app.UIDataTable.Selection(1);
            subSel = app.UISubDataTable.Selection(1);
            varName = app.UISubDataTable.Data{subSel,2};
            currentFrame = roi.display.frame;

            % Conversion de la chaîne en valeur numérique
            newVal = str2double(newStr);
            if isnan(newVal)
                uialert(app.ScoreAppUIFigure, 'Please enter a valid numeric value', 'Error');
                return;
            end

            % Mettre à jour le dataset
            selectedData = roi.data(dsIndex);
            try
                selectedData.data{currentFrame, varName} = newVal;
            catch ME
                warning('Could not update the value: %s', ME.message);
                return;
            end
            roi.data(dsIndex) = selectedData;
            score_display(app, 'refresh');
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
            newValue = app.ClassDropDown.Value;

            % Récupérer la ROI, le dataset et la variable sélectionnée
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};

            if isempty(app.UIDataTable.Selection) || isempty(app.UISubDataTable.Selection)
                return;
            end
            dsIndex = app.UIDataTable.Selection(1);
            subSel = app.UISubDataTable.Selection(1);
            varName = app.UISubDataTable.Data{subSel,2};
            currentFrame = roi.display.frame;

            % Mettre à jour le dataset
            selectedData = roi.data(dsIndex);
            % Si la donnée est catégorique, convertir newValue en cell array si nécessaire
            if iscategorical(selectedData.data{currentFrame, varName})
                if ischar(newValue)
                    newValue = cellstr(newValue);
                end
                selectedData.data{currentFrame, varName} = categorical(newValue);
            else
                % Sinon, affecter directement (par exemple pour un cell array de chaînes)
                selectedData.data{currentFrame, varName} = newValue;
            end
            roi.data(dsIndex) = selectedData;
            score_display(app, 'refresh');
        end

        % Menu selected function: CopypresetsMenu
        function CopypresetsMenuSelected(app, event)
            % Préparer une structure de presets
            presets = struct();

            % 1) Copier la visibilité des panels
            presets.panels = struct(...
                'ROisPanel', app.ROisPanel.Visible, ...
                'DisplaysettingsPanel', app.DisplaysettingsPanel.Visible, ...
                'DataSettingsPanel', app.DataSettingsPanel.Visible, ...
                'AnnotationPanel', app.AnnotationPanel.Visible, ...
                'IntensityQuantificationPanel', app.IntensityQuantificationPanel.Visible);

            % 2) Copier les réglages des channels
            % On copie le champ display de la ROI sélectionnée (s'il existe)
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if ~isempty(selectedROIIndex)
                roi = app.content.ROIList{selectedROIIndex};
                if isprop(roi, 'display')
                    
                    presets.channels = roi.display;
                else
                    presets.channels = [];
                end
            else
                presets.channels = [];
            end

            % 3) Copier le contenu des tables de données
            presets.dataTable = app.UIDataTable.Data;
            presets.subDataTable = app.UISubDataTable.Data;

            % 4) Copier d'autres réglages (par exemple, la transparence et éventuellement la table des canaux)
            presets.transparency = app.Transparency.Value;
            presets.channelTable = app.UIChannelTable.Data;


            % Sauvegarder la structure dans le workspace de base
            assignin('base', 'DisplaySettings', presets);
            % uialert(app.ScoreAppUIFigure, 'Display presets copied to workspace (DisplaySettings).', 'Copy Presets');

        end

        % Menu selected function: PastepresetsMenu
        function PastepresetsMenuSelected(app, event)
            % Essayer de récupérer la structure DisplaySettings depuis le workspace
            try
                presets = evalin('base', 'DisplaySettings');
            catch
                uialert(app.ScoreAppUIFigure, 'No DisplaySettings found in workspace.', 'Error');
                return;
            end

            % 1) Mise à jour de la visibilité des panels
            if isfield(presets, 'panels')
                app.ROisPanel.Visible = presets.panels.ROisPanel;
                app.DisplaysettingsPanel.Visible = presets.panels.DisplaysettingsPanel;
                app.DataSettingsPanel.Visible = presets.panels.DataSettingsPanel;
                app.AnnotationPanel.Visible = presets.panels.AnnotationPanel;
                app.IntensityQuantificationPanel.Visible = presets.panels.IntensityQuantificationPanel;
            end

            % 2) Mise à jour des réglages des channels uniquement si les noms correspondent exactement
            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if ~isempty(selectedROIIndex) && isfield(presets, 'channels') && ~isempty(presets.channels)
                roi = app.content.ROIList{selectedROIIndex};
                for i = 1:numel(presets.channels.channel)
                    presetName = presets.channels.channel{i}; % ex: 'Channel0' ou 'results_cellsegClassifier_3'
                    % Recherche par correspondance exacte dans roi.display.channel
                    idx = find(strcmp(roi.display.channel, presetName), 1);
                    if ~isempty(idx)
                        % Appliquer les réglages du preset sur le channel correspondant
                        if isfield(presets.channels, 'intensity') && size(presets.channels.intensity,1) >= i
                            roi.display.intensity(idx,:) = presets.channels.intensity(i,:);
                        end
                        if isfield(presets.channels, 'rgb') && size(presets.channels.rgb,1) >= i
                            roi.display.rgb(idx,:) = presets.channels.rgb(i,:);
                        end
                        if isfield(presets.channels, 'displaylim') && size(presets.channels.displaylim,2) >= i
                            roi.display.displaylim(:,idx) = presets.channels.displaylim(:,i);
                        end
                        if isfield(presets.channels, 'selectedchannel') && numel(presets.channels.selectedchannel) >= i
                            roi.display.selectedchannel(idx) = presets.channels.selectedchannel(i);
                        end
                    else
                        % Aucun channel correspondant exact trouvé : on ignore ce preset
                        disp(['Skipping preset for channel "' presetName '" because no exact match was found.']);
                    end
                end
                % Sauvegarder les mises à jour dans la ROI et rafraîchir la table des channels
                app.content.ROIList{selectedROIIndex} = roi;
                displayROIChannels(app);
            end

            % 3) Mise à jour des réglages de Data settings
            if isfield(presets, 'dataTable') && ~isempty(presets.dataTable) && ~isempty(app.UIDataTable.Data)
                dt = app.UIDataTable.Data;
                presetDT = presets.dataTable;
                n = min(size(dt,1), size(presetDT,1));
                for i = 1:n
                    if strcmp(dt{i,2}, presetDT{i,2})
                        dt{i,1} = presetDT{i,1}; % Mise à jour du flag de sélection
                    end
                end
                app.UIDataTable.Data = dt;
            end
            if isfield(presets, 'subDataTable') && ~isempty(presets.subDataTable) && ~isempty(app.UISubDataTable.Data)
                sd = app.UISubDataTable.Data;
                presetSD = presets.subDataTable;
                n = min(size(sd,1), size(presetSD,1));
                for i = 1:n
                    if strcmp(sd{i,2}, presetSD{i,2})
                        sd{i,1} = presetSD{i,1}; % Mise à jour du flag "Plot"
                    end
                end
                app.UISubDataTable.Data = sd;
            end

            % 4) Mise à jour des autres réglages
            if isfield(presets, 'transparency')
                app.Transparency.Value = presets.transparency;
            end
            % IMPORTANT : on n'actualise pas la table des channels à partir de presets.channelTable,
            % car cela écraserait la liste filtrée construite par displayROIChannels.

            % Actualiser les réglages (menus, panels, disposition, etc.)
            updateDisplaySettings(app);
            updateDisplayMenu(app);
            updatePanelsLayout(app);

            % Rafraîchir l'affichage général
            score_display(app, 'slow');

        end

        % Value changed function: isthedefautcolorCheckBox
        function isthedefautcolorCheckBoxValueChanged(app, event)
            value = app.isthedefautcolorCheckBox.Value;
            score_display(app, 'refresh');
        end

        % Button pushed function: DeselectalldataButton
        function DeselectalldataButtonPushed(app, event)
                     selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end
            roi = app.content.ROIList{selectedROIIndex};
            if ~isprop(roi, 'data')
                return;
            end

          for i=1:numel(roi.data)
                    roi.data(i).show = logical(false);
          end
  
          app.UIDataTable.Selection=[];
          
           displayData(app);

        end

        % Button pushed function: DeselectallsubdataButton
        function DeselectallsubdataButtonPushed(app, event)


            % Récupérer la sélection dans UIDataTable pour identifier le dataset courant
            selection = app.UIDataTable.Selection;
            if isempty(selection)
                return;
            end
            dsIndex = selection(1);

            selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
            if isempty(selectedROIIndex)
                return;
            end

            roi = app.content.ROIList{selectedROIIndex};
            if ~isprop(roi, 'data')
                return;
            end

            % Mettre à jour les plotProperties dans le dataset correspondant

            data = app.UISubDataTable.Data;
            data(:,1) = repmat({false}, size(data,1), 1);
            app.UISubDataTable.Data = data;

            roi.data(dsIndex).plotProperties = app.UISubDataTable.Data;

            % Optionnel : actualiser le plot si une méthode plot est définie
            %   if isprop(roi.data(dsIndex), 'plot') && ~isempty(roi.data(dsIndex).plot)

            %  end

            displayData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScoreAppUIFigure = uifigure('Visible', 'off');
            app.ScoreAppUIFigure.Name = 'ScoreApp';
            app.ScoreAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScoreAppUIFigureCloseRequest, true);
            app.ScoreAppUIFigure.KeyPressFcn = createCallbackFcn(app, @ScoreAppUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.ScoreAppUIFigure);
            app.FileMenu.Text = 'File';
            app.deleteselectedROIMenu = uimenu(app.FileMenu);
            app.deleteselectedROIMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteselectedROIMenuSelected, true);
            app.deleteselectedROIMenu.Text = 'delete selected ROI';
            app.SaveselectedroiMenu = uimenu(app.FileMenu);
            app.SaveselectedroiMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveselectedroiMenuSelected, true);
            app.SaveselectedroiMenu.Text = 'Save selected roi';
            app.DisplayMenu = uimenu(app.ScoreAppUIFigure);
            app.DisplayMenu.Text = 'Display';
            app.ROIsMenu = uimenu(app.DisplayMenu);
            app.ROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIsMenuSelected2, true);
            app.ROIsMenu.Checked = 'on';
            app.ROIsMenu.Text = 'ROIs';
            app.DisplaysettingsMenu = uimenu(app.DisplayMenu);
            app.DisplaysettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaysettingsMenuSelected3, true);
            app.DisplaysettingsMenu.Text = 'Display settings';
            app.IntensityquantificationMenu = uimenu(app.DisplayMenu);
            app.IntensityquantificationMenu.MenuSelectedFcn = createCallbackFcn(app, @IntensityquantificationMenuSelected, true);
            app.IntensityquantificationMenu.Text = 'Intensity quantification';
            app.AnnotationMenu = uimenu(app.DisplayMenu);
            app.AnnotationMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotationMenuSelected, true);
            app.AnnotationMenu.Text = 'Annotation';
            app.DatasettingsMenu = uimenu(app.DisplayMenu);
            app.DatasettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasettingsMenuSelected2, true);
            app.DatasettingsMenu.Text = 'Data settings';
            app.CopypresetsMenu = uimenu(app.DisplayMenu);
            app.CopypresetsMenu.MenuSelectedFcn = createCallbackFcn(app, @CopypresetsMenuSelected, true);
            app.CopypresetsMenu.Separator = 'on';
            app.CopypresetsMenu.Text = 'Copy presets';
            app.PastepresetsMenu = uimenu(app.DisplayMenu);
            app.PastepresetsMenu.MenuSelectedFcn = createCallbackFcn(app, @PastepresetsMenuSelected, true);
            app.PastepresetsMenu.Text = 'Paste presets';
            app.AnalysisMenu = uimenu(app.ScoreAppUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.ComputemetricsinobjectMenu = uimenu(app.AnalysisMenu);
            app.ComputemetricsinobjectMenu.Text = 'Compute metrics in object';
            app.DisplaysettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.UIDisplayAxes = uiaxes(app.DisplaysettingsPanel);
            xlabel(app.UIDisplayAxes, 'X')
            ylabel(app.UIDisplayAxes, 'Y')
            zlabel(app.UIDisplayAxes, 'Z')
            app.UIChannelTable = uitable(app.DisplaysettingsPanel);
            app.UIChannelTable.ColumnName = {'Display'; 'Name'; 'Levels'; 'RGB'; 'Weight'; 'Auto'};
            app.UIChannelTable.RowName = {};
            app.UIChannelTable.SelectionChangedFcn = createCallbackFcn(app, @UIChannelTableSelectionChanged, true);
            app.ZoomLabel = uilabel(app.DisplaysettingsPanel);
            app.ZoomLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.DisplaysettingsPanel);
            app.ZoomSlider.Limits = [100 500];
            app.ZoomSlider.MajorTicks = [100 500];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.Value = 100;
            app.FrameSliderLabel = uilabel(app.DisplaysettingsPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.DisplaysettingsPanel);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.ChannelPanel = uipanel(app.DisplaysettingsPanel);
            app.ChannelPanel.Title = 'Channel';
            app.WeightSliderLabel = uilabel(app.ChannelPanel);
            app.WeightSliderLabel.Text = 'Weight';
            app.WeightSlider = uislider(app.ChannelPanel);
            app.WeightSlider.Limits = [0 1];
            app.WeightSlider.MajorTicks = [0 0.5 1];
            app.WeightSlider.ValueChangingFcn = createCallbackFcn(app, @WeightSliderValueChanging, true);
            app.WeightSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.WeightSlider.Value = 1;
            app.ChannelColorPicker = uicolorpicker(app.ChannelPanel);
            app.ChannelColorPicker.ValueChangedFcn = createCallbackFcn(app, @ChannelColorPickerValueChanged, true);
            app.SelectedChannelLabel = uilabel(app.ChannelPanel);
            app.OverlayCheckBox = uicheckbox(app.ChannelPanel);
            app.OverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCheckBoxValueChanged, true);
            app.OverlayCheckBox.Text = 'Overlay Channels';
            app.OverlayCheckBox.Value = true;
            app.LevelsLabel = uilabel(app.ChannelPanel);
            app.LevelsLabel.Text = 'Levels';
            app.LowHighDisplaySlider = uislider(app.ChannelPanel, 'range');
            app.LowHighDisplaySlider.Limits = [0 4.8];
            app.LowHighDisplaySlider.MajorTicks = [0 1 2 3 4];
            app.LowHighDisplaySlider.MajorTickLabels = {'1', '10', '100', '1000', '10000'};
            app.LowHighDisplaySlider.ValueChangingFcn = createCallbackFcn(app, @LowHighDisplaySliderValueChanging, true);
            app.LowHighDisplaySlider.Value = [0 4.8];
            app.ResetButton = uibutton(app.DisplaysettingsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PanButton = uibutton(app.DisplaysettingsPanel, 'state');
            app.PanButton.ValueChangedFcn = createCallbackFcn(app, @PanButtonValueChanged, true);
            app.PanButton.Text = 'Pan';
            app.ROisPanel = uipanel(app.ScoreAppUIFigure);
            app.ROisPanel.Title = 'ROis';
            app.UIROITable = uitable(app.ROisPanel);
            app.UIROITable.ColumnName = {'Display'; 'Name'; 'Size'};
            app.UIROITable.RowName = {};
            app.UIROITable.ColumnEditable = [true false false];
            app.DataSettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DataSettingsPanel.Title = 'Data';
            app.UIDataTable = uitable(app.DataSettingsPanel);
            app.UIDataTable.ColumnName = {'Sel'; 'Name'; 'Type'};
            app.UIDataTable.RowName = {};
            app.UIDataTable.ColumnEditable = [true false false];
            app.UIDataTable.CellEditCallback = createCallbackFcn(app, @UIDataTableCellEdit, true);
            app.UIDataTable.SelectionChangedFcn = createCallbackFcn(app, @UIDataTableSelectionChanged, true);
            app.UISubDataTable = uitable(app.DataSettingsPanel);
            app.UISubDataTable.ColumnName = {'Plot'; 'Name'; 'Type'; 'Color'; 'Width'; 'Plot group'};
            app.UISubDataTable.RowName = {};
            app.UISubDataTable.ColumnEditable = [true false false true true true];
            app.UISubDataTable.CellEditCallback = createCallbackFcn(app, @UISubDataTableCellEdit, true);
            app.UISubDataTable.SelectionChangedFcn = createCallbackFcn(app, @UISubDataTableSelectionChanged, true);
            app.SelecteddataLabel = uilabel(app.DataSettingsPanel);
            app.SelecteddataLabel.Text = 'Selected data';
            app.AssignvalueLabel = uilabel(app.DataSettingsPanel);
            app.AssignvalueLabel.Text = 'Assign value:';
            app.AssignvalueEditField = uieditfield(app.DataSettingsPanel, 'text');
            app.AssignvalueEditField.ValueChangedFcn = createCallbackFcn(app, @AssignvalueEditFieldValueChanged, true);
            app.FrameLabel = uilabel(app.DataSettingsPanel);
            app.FrameLabel.Text = 'FrameLabel';
            app.ClassDropDownLabel = uilabel(app.DataSettingsPanel);
            app.ClassDropDownLabel.Text = 'Class';
            app.ClassDropDown = uidropdown(app.DataSettingsPanel);
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.DeselectalldataButton = uibutton(app.DataSettingsPanel, 'push');
            app.DeselectalldataButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectalldataButtonPushed, true);
            app.DeselectalldataButton.Text = 'Deselect all data';
            app.DeselectallsubdataButton = uibutton(app.DataSettingsPanel, 'push');
            app.DeselectallsubdataButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallsubdataButtonPushed, true);
            app.DeselectallsubdataButton.Text = 'Deselect all subdata';
            app.AnnotationPanel = uipanel(app.ScoreAppUIFigure);
            app.AnnotationPanel.Title = 'Annotation Panel';
            app.UIAnnotationTable = uitable(app.AnnotationPanel);
            app.UIAnnotationTable.ColumnName = {'Display'; 'Name'; 'Class'};
            app.UIAnnotationTable.RowName = {};
            app.UIAnnotationTable.ColumnEditable = [true true true];
            app.UIAnnotationTable.SelectionChangedFcn = createCallbackFcn(app, @UIAnnotationTableSelectionChanged, true);
            app.TransparencyLabel = uilabel(app.AnnotationPanel);
            app.TransparencyLabel.Text = 'Transparency';
            app.Transparency = uislider(app.AnnotationPanel);
            app.Transparency.Limits = [0 1];
            app.Transparency.ValueChangedFcn = createCallbackFcn(app, @TransparencyValueChanged, true);
            app.Transparency.ValueChangingFcn = createCallbackFcn(app, @TransparencyValueChanging, true);
            app.Transparency.Value = 0.5;
            app.ObjectspanelPanel = uipanel(app.AnnotationPanel);
            app.ObjectspanelPanel.Title = 'Objects panel';
            app.IndexChannelLabel = uilabel(app.ObjectspanelPanel);
            app.IndexChannelLabel.Text = 'IndexChannelLabel';
            app.PaintButton = uibutton(app.ObjectspanelPanel, 'state');
            app.PaintButton.ValueChangedFcn = createCallbackFcn(app, @PaintButtonValueChanged, true);
            app.PaintButton.Text = 'Paint';
            app.SelectedobjectindexEditFieldLabel = uilabel(app.ObjectspanelPanel);
            app.SelectedobjectindexEditFieldLabel.Text = 'Selected object index';
            app.SelectedobjectindexEditField = uieditfield(app.ObjectspanelPanel, 'numeric');
            app.SelectedobjectindexEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedobjectindexEditFieldValueChanged, true);
            app.SelectedLabel = uilabel(app.ObjectspanelPanel);
            app.SelectedLabel.Text = 'Selected:';
            app.ComputemetricsButton = uibutton(app.ObjectspanelPanel, 'push');
            app.ComputemetricsButton.Text = 'Compute metrics';
            app.NewAnnotationButton = uibutton(app.AnnotationPanel, 'push');
            app.NewAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @NewAnnotationButtonPushed, true);
            app.NewAnnotationButton.Text = 'New Annotation';
            app.DeleteAnnnotationButton = uibutton(app.AnnotationPanel, 'push');
            app.DeleteAnnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAnnnotationButtonPushed, true);
            app.DeleteAnnnotationButton.Text = 'Delete Annnotation';
            app.NewclassButton = uibutton(app.AnnotationPanel, 'push');
            app.NewclassButton.ButtonPushedFcn = createCallbackFcn(app, @NewclassButtonPushed, true);
            app.NewclassButton.Text = 'New class';
            app.DeleteclassButton = uibutton(app.AnnotationPanel, 'push');
            app.DeleteclassButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteclassButtonPushed, true);
            app.DeleteclassButton.Text = 'Delete class';
            app.AnnoatationcolorLabel = uilabel(app.AnnotationPanel);
            app.AnnoatationcolorLabel.Text = 'Annoatation color';
            app.MaskColorPicker = uicolorpicker(app.AnnotationPanel);
            app.MaskColorPicker.ValueChangedFcn = createCallbackFcn(app, @MaskColorPickerValueChanged, true);
            app.isthedefautcolorCheckBox = uicheckbox(app.AnnotationPanel);
            app.isthedefautcolorCheckBox.ValueChangedFcn = createCallbackFcn(app, @isthedefautcolorCheckBoxValueChanged, true);
            app.isthedefautcolorCheckBox.Text = '''1'' is the defaut color';
            app.IntensityQuantificationPanel = uipanel(app.ScoreAppUIFigure);
            app.IntensityQuantificationPanel.Title = 'Intensity Quantification';
            app.UIProfileAxes = uiaxes(app.IntensityQuantificationPanel);
            app.LineIntensityprofileButton = uibutton(app.IntensityQuantificationPanel, 'state');
            app.LineIntensityprofileButton.ValueChangedFcn = createCallbackFcn(app, @LineIntensityprofileButtonValueChanged2, true);
            app.LineIntensityprofileButton.Text = 'Line Intensity profile';
            app.ShapeButton = uibutton(app.IntensityQuantificationPanel, 'state');
            app.ShapeButton.ValueChangedFcn = createCallbackFcn(app, @ShapeButtonValueChanged, true);
            app.ShapeButton.Text = 'Shape intensity';
            % Show the figure after all components are created
            app.ScoreAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ScoreAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ScoreAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScoreAppUIFigure)
        end
    end
end