classdef preferencesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UserpreferencesUIFigure  matlab.ui.Figure
        CloseButton              matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        StatusSaved              matlab.ui.control.Lamp
        SavingstatusLampLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
        
 % Description
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
             if numel(userpath)==0
                 userpath('reset')
             end

            pth=userpath;
 
          % fle= fullfile(pth,'Detecdiv/userprefs.mat')
          % pth=userpath;
if ispc
fle= fullfile(pth,'Detecdiv/userprefs.mat');
targetpth=userpath;
else
tmpfile=getenv("HOME");
fle=fullfile(strcat(tmpfile,'/Detecdiv'),'userprefs.mat');
targetpth=tmpfile;
end

            if exist(fle)
                load(fle)
            else % structure must me created

               if ~exist(fullfile(targetpth,'Detecdiv'))
                    mkdir(fullfile(targetpth,'Detecdiv'))
               end


            tip={'Keyboard shortcuts used to assign a class to an image; Please enter space-separated letters; Please restart the ROI viewer after modification',...
                'Keyboard shortcuts used to correct GT vs prediction discrepancies; Please enter space-separated letters; Please restart the ROI viewer after modification',...
                'Keyboard shortcuts used to set frames bounds: only frames within the bounds will be considered for training; Please enter space-separated letters; Please restart the ROI viewer after modification',...
                'Keyboard shortcuts used to quickly jump between frames',....
                 'Keyboard shortcuts used to fill holes when painting',...
                 'Keyboard shortcuts used to change painting transparency',...
                 'Size in pixels of the brush size used when using the left mouse button;  Please restart the ROI viewer after modification',...
        'Size in pixels of the brush size used when using the right mouse button;  Please restart the ROI viewer after modification',...
        'Size in pixels of the brush size used when using the mouse wheel button;  Please restart the ROI viewer after modification'};
    
    userprefs=struct('roi_view_shortcut_keys','a z e r t y u i',...
        'roi_view_corr_shortcut_keys','j k',...
        'roi_view_bounds_shortcut_keys','w x',...
        'roi_view_frames_jump_size','l m',...
        'painting_fill_holes_shortcut','k',...
        'painting_transparency_shortcut','g h',...
        'painting_large_brush_size', 9,... 
        'painting_small_brush_size', 1,... 
        'painting_huge_brush_size', 49,...
        'tip',{tip});

          save(fle,'userprefs');
            end

            % create structure; 
% 
               trainingParam=userprefs;
               tip=trainingParam.tip;
               trainingParam=rmfield(trainingParam,'tip');
               app.UserpreferencesUIFigure.UserData=[];
               si=app.UserpreferencesUIFigure.Position(4);
              struct2GUI(trainingParam,[10 si-25],'Handle',app.UserpreferencesUIFigure,'Flag',app.StatusSaved,'Tip',tip,'Width',200);
              app.Data.tip=tip;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                   delete(app.UserpreferencesUIFigure)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            userprefs=app.UserpreferencesUIFigure.UserData;
            userprefs.tip=app.Data.tip;
            
                         if numel(userpath)==0
                 userpath('reset')
             end

            pth=userpath;
 
          % fle= fullfile(pth,'Detecdiv/userprefs.mat')
          % pth=userpath;
if ispc
fle= fullfile(pth,'Detecdiv/userprefs.mat');
targetpth=userpath;
else
tmpfile=getenv("HOME");
fle=fullfile(strcat(tmpfile,'/Detecdiv'),'userprefs.mat');
targetpth=tmpfile;
end

            if exist(fle)
                load(fle)
            else % structure must me created

               if ~exist(fullfile(targetpth,'Detecdiv'))
                    mkdir(fullfile(targetpth,'Detecdiv'))
               end
            end
            
            
        save(fle,'userprefs');
            app.StatusSaved.Color=[0 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UserpreferencesUIFigure = uifigure('Visible', 'off');
            app.UserpreferencesUIFigure.Name = 'User preferences';
            app.SavingstatusLampLabel = uilabel(app.UserpreferencesUIFigure);
            app.SavingstatusLampLabel.Text = 'Saving status:';
            app.StatusSaved = uilamp(app.UserpreferencesUIFigure);
            app.SaveButton = uibutton(app.UserpreferencesUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.UserpreferencesUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UserpreferencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preferencesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UserpreferencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UserpreferencesUIFigure)
        end
    end
end